classdef Haendische_Nachbearbeitung < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StreckungsfaktorxfacheSlider   matlab.ui.control.Slider
        StreckungsfaktorxfacheSliderLabel  matlab.ui.control.Label
        StauchungsfaktorxfacheSlider   matlab.ui.control.Slider
        StauchungsfaktorxfacheSliderLabel  matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        AngleSpinner                   matlab.ui.control.Spinner
        AngleSpinnerLabel              matlab.ui.control.Label
        TimeSpinner                    matlab.ui.control.Spinner
        TimeSpinnerLabel               matlab.ui.control.Label
        DataPointwiththeindexLabel     matlab.ui.control.Label
        NoAxisselectedLabel            matlab.ui.control.Label
        GenerateButton                 matlab.ui.control.Button
        UndoButton                     matlab.ui.control.Button
        RedoButton                     matlab.ui.control.Button
        Ruck_sechste_Achse             matlab.ui.control.UIAxes
        Beschleuinigung_sechste_Achse  matlab.ui.control.UIAxes
        Geschwindigkeit_sechste_Achse  matlab.ui.control.UIAxes
        Ruck_fuenfte_Achse             matlab.ui.control.UIAxes
        Beschleuinigung_fuenfte_Achse  matlab.ui.control.UIAxes
        Geschwindigkeit_fuenfte_Achse  matlab.ui.control.UIAxes
        Geschwindigkeit_vierte_Achse   matlab.ui.control.UIAxes
        Beschleuinigung_vierte_Achse   matlab.ui.control.UIAxes
        Ruck_vierte_Achse              matlab.ui.control.UIAxes
        Ruck_dritte_Achse              matlab.ui.control.UIAxes
        Beschleuinigung_dritte_Achse   matlab.ui.control.UIAxes
        Geschwindigkeit_dritte_Achse   matlab.ui.control.UIAxes
        Ruck_zweite_Achse              matlab.ui.control.UIAxes
        Beschleuinigung_zweite_Achse   matlab.ui.control.UIAxes
        Geschwindigkeit_zweite_Achse   matlab.ui.control.UIAxes
        Ruck_erste_Achse               matlab.ui.control.UIAxes
        Beschleuinigung_erste_Achse    matlab.ui.control.UIAxes
        Geschwindigkeit_erste_Achse    matlab.ui.control.UIAxes
        sechste_Achse                  matlab.ui.control.UIAxes
        fuenfte_Achse                  matlab.ui.control.UIAxes
        vierte_Achse                   matlab.ui.control.UIAxes
        dritte_Achse                   matlab.ui.control.UIAxes
        zweite_Achse                   matlab.ui.control.UIAxes
        erste_Achse                    matlab.ui.control.UIAxes
    end

    %%TODO: MAKE CODE CLEANER BY CAPSULATING A LOT OF REOCCURING CODE INTO
    %%FUNCTIONS (Important)


    properties (Access = private)
        SelectedIndex (1,1) = nan; %Used to determine if a line of a graph has been clicked and which point has been clicked
        actualAxis = 1;
        firstAxis; %Contain the plot of the axis
        firstVelocity;
        firstVelocityMarker = [];
        firstVelocityLimits = [-1.39 1.39];
        firstAcceleration;
        firstAccelerationMarker = [];
        secondAxis;
        secondVelocity;
        secondVelocityMarker = [];
        secondVelocityLimits = [-1.31 1.31]
        secondAcceleration;
        secondAccelerationMarker = [];
        thirdAxis;
        thirdVelocity;
        thirdVelocityMarker = [];
        thirdVelocityLimits = [-1.22 1.22];
        thirdAcceleration;
        thirdAccelerationMarker = [];
        fourthAxis;
        fourthVelocity;
        fourthVelocityMarker = [];
        fourthVelocityLimits = [-1.22 1.22];
        fourthAcceleration;
        fourthAccelerationMarker = [];
        fifthAxis;
        fifthVelocity;
        fifthVelocityMarker = [];
        fifthVelocityLimits = [-1.22 1.22];
        fifthAcceleration
        fifthAccelerationMarker = [];
        sixthAxis;
        sixthVelocity;
        sixthVelocityMarker = [];
        sixthVelocityLimits = [-1.6 1.6];
        sixthAcceleration;
        sixthAccelerationMarker = [];
        AccelerationLimits = [-3.5 3.5];
        axisSelected = false;
        dataPoints; %the selected points
        basePoints; %Save for Redo
        timeintervals; %Save for Redo
        xPointsUndo = zeros(0,40); %Save each step for Undo
        yPointsUndo = zeros(0,40);
        axisSelections = []; %Order of last axis that has been changed (Used for Undo)
        timeAngleSelections = []; %Order of whether the x-axis or y-axis was changed
        firstAxisMarker = 0;
        secondAxisMarker = 0;
        thirdAxisMarker = 0;
        fourthAxisMarker = 0;
        fifthAxisMarker = 0;
        sixthAxisMarker = 0;
        matfileName;
    end
    
    methods (Access = public)


        function ChangeValueFirstAxis(app, event) % Callback: Graph was clicked and the index of the point is calculated. A panel appears and lets you change the value of the point
            %CLEAR app.dataPoints and Markers when accesing another axes    
            if(app.actualAxis ~= 1)
                app.dataPoints = [];
                if(app.secondAxisMarker ~= 0)
                    app.secondAxisMarker.XData = [];
                    app.secondAxisMarker.YData = [];
                end
                if(app.thirdAxisMarker  ~= 0)
                    app.thirdAxisMarker.XData = [];
                    app.thirdAxisMarker.YData = [];
                end
                if(app.fourthAxisMarker  ~= 0)
                    app.fourthAxisMarker.XData = [];
                    app.fourthAxisMarker.YData = [];
                end
                if(app.fifthAxisMarker  ~= 0)
                    app.fifthAxisMarker.XData = [];
                    app.fifthAxisMarker.YData = [];
                end
                if(app.sixthAxisMarker  ~= 0)
                    app.sixthAxisMarker.XData = [];
                    app.sixthAxisMarker.YData = [];
                end
            end

            %Update actualAxis
            app.actualAxis = 1;

            %Calculate index of selected point
            [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2);
            
            %Add points to either pointOne or pointTwo
            if(length(app.dataPoints) < 2)

                app.dataPoints(end + 1) = app.SelectedIndex;
            

                %Instantiate Marker
                if(app.firstAxisMarker == 0)
                    hold(app.erste_Achse,'on');
                    app.firstAxisMarker = plot(app.erste_Achse,app.firstAxis.XData(app.SelectedIndex),app.firstAxis.YData(app.SelectedIndex),"Marker","*");
                    hold(app.erste_Achse,"off");
                else
                    app.firstAxisMarker.XData(end + 1) = app.firstAxis.XData(app.SelectedIndex);
                    app.firstAxisMarker.YData(end + 1) = app.firstAxis.YData(app.SelectedIndex);
                end

            end


            %Activate Spinner and Label of the Spinner

            app.TimeSpinner.Value = app.firstAxis.XData(app.SelectedIndex);
            app.AngleSpinner.Value = app.firstAxis.YData(app.SelectedIndex);
            app.NoAxisselectedLabel.Text = "First Axis selected.";
            
            %Update text
            newText = "Data Point with the index: ";
            for point = app.dataPoints
                newText = newText + int2str(point) + " ";
            end
            
            app.DataPointwiththeindexLabel.Text = newText;


            app.axisSelected = true;
            
        end

        function ChangeValueSecondAxis(app, event) % Callback: Graph was clicked and the index of the point is calculated. A panel appears and lets you change the value of the point
            %CLEAR app.dataPoints and Markers when accesing another axes    
            if(app.actualAxis ~= 2)
                app.dataPoints = [];
                if(app.firstAxisMarker ~= 0)
                    app.firstAxisMarker.XData = [];
                    app.firstAxisMarker.YData = [];
                end
                if(app.thirdAxisMarker  ~= 0)
                    app.thirdAxisMarker.XData = [];
                    app.thirdAxisMarker.YData = [];
                end
                if(app.fourthAxisMarker  ~= 0)
                    app.fourthAxisMarker.XData = [];
                    app.fourthAxisMarker.YData = [];
                end
                if(app.fifthAxisMarker  ~= 0)
                    app.fifthAxisMarker.XData = [];
                    app.fifthAxisMarker.YData = [];
                end
                if(app.sixthAxisMarker  ~= 0)
                    app.sixthAxisMarker.XData = [];
                    app.sixthAxisMarker.YData = [];
                end
            end

            %Update actualAxis
            app.actualAxis = 2;
            
            %Calculate index of selected point
            [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2);

            
            %Add points to either pointOne or pointTwo
            if(length(app.dataPoints) < 2)
                app.dataPoints(end + 1) = app.SelectedIndex;

                %Instantiate Marker
                if(app.secondAxisMarker == 0)
                    hold(app.zweite_Achse,'on');
                    app.secondAxisMarker = plot(app.zweite_Achse,app.secondAxis.XData(app.SelectedIndex),app.secondAxis.YData(app.SelectedIndex),"Marker","*");
                    hold(app.zweite_Achse,"off");
                else
                    app.secondAxisMarker.XData(end + 1) = app.secondAxis.XData(app.SelectedIndex);
                    app.secondAxisMarker.YData(end + 1) = app.secondAxis.YData(app.SelectedIndex);
                end
            end


            %Activate Spinner and Label of the Spinner

            app.TimeSpinner.Value = app.secondAxis.XData(app.SelectedIndex);
            app.AngleSpinner.Value = app.secondAxis.YData(app.SelectedIndex);
            app.NoAxisselectedLabel.Text = "Second Axis selected.";
            
            %Update text
            newText = "Data Point with the index: ";
            for point = app.dataPoints
                newText = newText + int2str(point) + " ";
            end
            
            app.DataPointwiththeindexLabel.Text = newText;


            app.axisSelected = true;
        end

        function ChangeValueThirdAxis(app, event) % Callback: Graph was clicked and the index of the point is calculated. A panel appears and lets you change the value of the point
            %CLEAR app.dataPoints and Markers when accesing another axes    
            if(app.actualAxis ~= 3)
                app.dataPoints = [];
                if(app.firstAxisMarker ~= 0)
                    app.firstAxisMarker.XData = [];
                    app.firstAxisMarker.YData = [];
                end
                if(app.secondAxisMarker  ~= 0)
                    app.secondAxisMarker.XData = [];
                    app.secondAxisMarker.YData = [];
                end
                if(app.fourthAxisMarker  ~= 0)
                    app.fourthAxisMarker.XData = [];
                    app.fourthAxisMarker.YData = [];
                end
                if(app.fifthAxisMarker  ~= 0)
                    app.fifthAxisMarker.XData = [];
                    app.fifthAxisMarker.YData = [];
                end
                if(app.sixthAxisMarker  ~= 0)
                    app.sixthAxisMarker.XData = [];
                    app.sixthAxisMarker.YData = [];
                end
            end

            %Update actualAxis
            app.actualAxis = 3;

            %Calculate index of selected point
            [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2);


            %Add points to either pointOne or pointTwo
            if(length(app.dataPoints) < 2)
                app.dataPoints(end + 1) = app.SelectedIndex;

                %Instantiate Marker
                if(app.thirdAxisMarker == 0)
                    hold(app.dritte_Achse,'on');
                    app.thirdAxisMarker = plot(app.dritte_Achse,app.thirdAxis.XData(app.SelectedIndex),app.thirdAxis.YData(app.SelectedIndex),"Marker","*");
                    hold(app.dritte_Achse,"off");
                else
                    app.thirdAxisMarker.XData(end + 1) = app.thirdAxis.XData(app.SelectedIndex);
                    app.thirdAxisMarker.YData(end + 1) = app.thirdAxis.YData(app.SelectedIndex);
                end
            end


            %Activate Spinner and Label of the Spinner

            app.TimeSpinner.Value = app.thirdAxis.XData(app.SelectedIndex);
            app.AngleSpinner.Value = app.thirdAxis.YData(app.SelectedIndex);
            app.NoAxisselectedLabel.Text = "Third Axis selected.";
            
            %Update text
            newText = "Data Point with the index: ";
            for point = app.dataPoints
                newText = newText + int2str(point) + " ";
            end
            
            app.DataPointwiththeindexLabel.Text = newText;


            app.axisSelected = true;
        end

        function ChangeValueFourthAxis(app, event) % Callback: Graph was clicked and the index of the point is calculated. A panel appears and lets you change the value of the point
            %CLEAR app.dataPoints and Markers when accesing another axes    
            if(app.actualAxis ~= 4)
                app.dataPoints = [];
                if(app.firstAxisMarker ~= 0)
                    app.firstAxisMarker.XData = [];
                    app.firstAxisMarker.YData = [];
                end
                if(app.thirdAxisMarker  ~= 0)
                    app.thirdAxisMarker.XData = [];
                    app.thirdAxisMarker.YData = [];
                end
                if(app.secondAxisMarker  ~= 0)
                    app.secondAxisMarker.XData = [];
                    app.secondAxisMarker.YData = [];
                end
                if(app.fifthAxisMarker  ~= 0)
                    app.fifthAxisMarker.XData = [];
                    app.fifthAxisMarker.YData = [];
                end
                if(app.sixthAxisMarker  ~= 0)
                    app.sixthAxisMarker.XData = [];
                    app.sixthAxisMarker.YData = [];
                end
            end

            %Update actualAxis
            app.actualAxis = 4;

            %Calculate index of selected point
            [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2);


            %Add points to either pointOne or pointTwo
            if(length(app.dataPoints) < 2)
                app.dataPoints(end + 1) = app.SelectedIndex;

                %Instantiate Marker
                if(app.fourthAxisMarker == 0)
                    hold(app.vierte_Achse,'on');
                    app.fourthAxisMarker = plot(app.vierte_Achse,app.fourthAxis.XData(app.SelectedIndex),app.fourthAxis.YData(app.SelectedIndex),"Marker","*");
                    hold(app.vierte_Achse,"off");
                else
                    app.fourthAxisMarker.XData(end + 1) = app.fourthAxis.XData(app.SelectedIndex);
                    app.fourthAxisMarker.YData(end + 1) = app.fourthAxis.YData(app.SelectedIndex);
                end
            end


            %Activate Spinner and Label of the Spinner

            app.TimeSpinner.Value = app.fourthAxis.XData(app.SelectedIndex);
            app.AngleSpinner.Value = app.fourthAxis.YData(app.SelectedIndex);
            app.NoAxisselectedLabel.Text = "Fourth Axis selected.";
            
            %Update text
            newText = "Data Point with the index: ";
            for point = app.dataPoints
                newText = newText + int2str(point) + " ";
            end
            
            app.DataPointwiththeindexLabel.Text = newText;


            app.axisSelected = true;
        end

        function ChangeValueFifthAxis(app, event) % Callback: Graph was clicked and the index of the point is calculated. A panel appears and lets you change the value of the point
            %CLEAR app.dataPoints and Markers when accesing another axes    
            if(app.actualAxis ~= 5)
                app.dataPoints = [];
                if(app.firstAxisMarker ~= 0)
                    app.firstAxisMarker.XData = [];
                    app.firstAxisMarker.YData = [];
                end
                if(app.thirdAxisMarker  ~= 0)
                    app.thirdAxisMarker.XData = [];
                    app.thirdAxisMarker.YData = [];
                end
                if(app.fourthAxisMarker  ~= 0)
                    app.fourthAxisMarker.XData = [];
                    app.fourthAxisMarker.YData = [];
                end
                if(app.secondAxisMarker  ~= 0)
                    app.secondAxisMarker.XData = [];
                    app.secondAxisMarker.YData = [];
                end
                if(app.sixthAxisMarker  ~= 0)
                    app.sixthAxisMarker.XData = [];
                    app.sixthAxisMarker.YData = [];
                end
            end 

            %Update actualAxis
            app.actualAxis = 5;

            %Calculate index of selected point
            [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2);


            %Add points to either pointOne or pointTwo
            if(length(app.dataPoints) < 2)
                app.dataPoints(end + 1) = app.SelectedIndex;

                %Instantiate Marker
                if(app.fifthAxisMarker == 0)
                    hold(app.fuenfte_Achse,'on');
                    app.fifthAxisMarker = plot(app.fuenfte_Achse,app.fifthAxis.XData(app.SelectedIndex),app.fifthAxis.YData(app.SelectedIndex),"Marker","*");
                    hold(app.fuenfte_Achse,"off");
                else
                    app.fifthAxisMarker.XData(end + 1) = app.fifthAxis.XData(app.SelectedIndex);
                    app.fifthAxisMarker.YData(end + 1) = app.fifthAxis.YData(app.SelectedIndex);
                end
            end


            %Activate Spinner and Label of the Spinner

            app.TimeSpinner.Value = app.fifthAxis.XData(app.SelectedIndex);
            app.AngleSpinner.Value = app.fifthAxis.YData(app.SelectedIndex);
            app.NoAxisselectedLabel.Text = "Fifth Axis selected.";
            
            %Update text
            newText = "Data Point with the index: ";
            for point = app.dataPoints
                newText = newText + int2str(point) + " ";
            end
            
            app.DataPointwiththeindexLabel.Text = newText;


            app.axisSelected = true;
        end

        function ChangeValueSixthAxis(app, event) % Callback: Graph was clicked and the index of the point is calculated. A panel appears and lets you change the value of the point
           %CLEAR app.dataPoints and Markers when accesing another axes    
            if(app.actualAxis ~= 6)
                app.dataPoints = [];
                if(app.firstAxisMarker ~= 0)
                    app.firstAxisMarker.XData = [];
                    app.firstAxisMarker.YData = [];
                end
                if(app.thirdAxisMarker  ~= 0)
                    app.thirdAxisMarker.XData = [];
                    app.thirdAxisMarker.YData = [];
                end
                if(app.fourthAxisMarker  ~= 0)
                    app.fourthAxisMarker.XData = [];
                    app.fourthAxisMarker.YData = [];
                end
                if(app.fifthAxisMarker  ~= 0)
                    app.fifthAxisMarker.XData = [];
                    app.fifthAxisMarker.YData = [];
                end
                if(app.secondAxisMarker  ~= 0)
                    app.secondAxisMarker.XData = [];
                    app.secondAxisMarker.YData = [];
                end
            end 

            %Update actualAxis
            app.actualAxis = 6;

            %Calculate index of selected point
            [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2);
            
            %Add points to either pointOne or pointTwo
            if(length(app.dataPoints) < 2)
                app.dataPoints(end + 1) = app.SelectedIndex;

                %Instantiate Marker
                if(app.sixthAxisMarker == 0)
                    hold(app.sechste_Achse,'on');
                    app.sixthAxisMarker = plot(app.sechste_Achse,app.sixthAxis.XData(app.SelectedIndex),app.sixthAxis.YData(app.SelectedIndex),"Marker","*");
                    hold(app.sechste_Achse,"off");
                else
                    app.sixthAxisMarker.XData(end + 1) = app.sixthAxis.XData(app.SelectedIndex);
                    app.sixthAxisMarker.YData(end + 1) = app.sixthAxis.YData(app.SelectedIndex);
                end
            end


            %Activate Spinner and Label of the Spinner

            app.TimeSpinner.Value = app.sixthAxis.XData(app.SelectedIndex);
            app.AngleSpinner.Value = app.sixthAxis.YData(app.SelectedIndex);
            app.NoAxisselectedLabel.Text = "Sixth Axis selected.";
            
            %Update text
            newText = "Data Point with the index: ";
            for point = app.dataPoints
                newText = newText + int2str(point) + " ";
            end
            
            app.DataPointwiththeindexLabel.Text = newText;


            app.axisSelected = true;
        end

        function updateGraphs(app, basePoints,timeintervals, actualAxis)
            
            %Using splineOptimal to derivate the changed graphs
            [spline, velocity, acceleration, ruck , time , place ] =  splineOptimal2(basePoints,timeintervals,false);
               
            %Graphs that has been changed shall be updated
            switch actualAxis

                        case 1

                            if(isempty(velocity))
                                disp("stop");
                            end    

                        app.firstVelocity = plot(app.Geschwindigkeit_erste_Achse,time,velocity,'marker','o','Color','magenta');
                        app.firstAcceleration = plot(app.Beschleuinigung_erste_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_erste_Achse,time,ruck,'marker','o','Color','red');

                        line(app.Geschwindigkeit_erste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.39 1.39],'Color','black');
                        line(app.Geschwindigkeit_erste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.39 -1.39],'Color','black');
                        line(app.Beschleuinigung_erste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_erste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');

                        case 2
                        
                        app.secondVelocity = plot(app.Geschwindigkeit_zweite_Achse,time,velocity,'marker','o','Color','magenta');
                        app.secondAcceleration = plot(app.Beschleuinigung_zweite_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_zweite_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_zweite_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.31 1.31],'Color','black');
                        line(app.Geschwindigkeit_zweite_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.31 -1.31],'Color','black');
                        line(app.Beschleuinigung_zweite_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_zweite_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');

                        case 3
                        
                        app.thirdVelocity = plot(app.Geschwindigkeit_dritte_Achse,time,velocity,'marker','o','Color','magenta');
                        app.thirdAcceleration = plot(app.Beschleuinigung_dritte_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_dritte_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_dritte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.22 1.22],'Color','black');
                        line(app.Geschwindigkeit_dritte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.22 -1.22],'Color','black');
                        line(app.Beschleuinigung_dritte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_dritte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');

                        case 4
                        
                        app.fourthVelocity = plot(app.Geschwindigkeit_vierte_Achse,time,velocity,'marker','o','Color','magenta');
                        app.fourthAcceleration = plot(app.Beschleuinigung_vierte_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_vierte_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_vierte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.22 1.22],'Color','black');
                        line(app.Geschwindigkeit_vierte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.22 -1.22],'Color','black');
                        line(app.Beschleuinigung_vierte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_vierte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');


                        case 5
                        
                        app.fifthVelocity = plot(app.Geschwindigkeit_fuenfte_Achse,time,velocity,'marker','o','Color','magenta');
                        app.fifthAcceleration = plot(app.Beschleuinigung_fuenfte_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_fuenfte_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_fuenfte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.22 1.22],'Color','black');
                        line(app.Geschwindigkeit_fuenfte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.22 -1.22],'Color','black');
                        line(app.Beschleuinigung_fuenfte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_fuenfte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');

                        case 6
                       
                        app.sixthVelocity = plot(app.Geschwindigkeit_sechste_Achse,time,velocity,'marker','o','Color','magenta');
                        app.sixthAcceleration = plot(app.Beschleuinigung_sechste_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_sechste_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_sechste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.6 1.6],'Color','black');
                        line(app.Geschwindigkeit_sechste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.6 -1.6],'Color','black');
                        line(app.Beschleuinigung_sechste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_sechste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');
            end

            checkLimits(app);
            

        end

        function checkLimits(app)

            firstVelocityIndex = [];
            secondVelocityIndex = [];
            thirdVelocityIndex = [];
            fourthVelocityIndex = [];
            fifthVelocityIndex = [];
            sixthVelocityIndex = [];

            firstAccelerationIndex = [];
            secondAccelerationIndex = [];
            thirdAccelerationIndex = [];
            fourthAccelerationIndex = [];
            fifthAccelerationIndex = [];
            sixthAccelerationIndex = [];
            
          if(isvalid(app.firstVelocity))
            for i = 1: length(app.firstVelocity.XData)
                
                %CHECK VELOCITY LIMITS
                if( (app.firstVelocity.YData(i) < app.firstVelocityLimits(1)) || (app.firstVelocity.YData(i) > app.firstVelocityLimits(2)) )
                    firstVelocityIndex(end + 1) = i;
                end

                if( (app.firstAcceleration.YData(i) < app.AccelerationLimits(1)) || (app.firstAcceleration.YData(i) > app.AccelerationLimits(2)) )
                    firstAccelerationIndex(end + 1) = i;
                end
            end

            for i = 1:length(app.secondVelocity.XData)
                if( (app.secondVelocity.YData(i) < app.secondVelocityLimits(1)) || (app.secondVelocity.YData(i) > app.secondVelocityLimits(2)) )
                    secondVelocityIndex(end + 1) = i;
                end

                if( (app.secondAcceleration.YData(i) < app.AccelerationLimits(1)) || (app.secondAcceleration.YData(i) > app.AccelerationLimits(2)) )
                    test = app.secondAcceleration.YData(i);
                    secondAccelerationIndex(end + 1) = i;
                end 
            end    
            
            for i = 1:length(app.thirdVelocity.XData)
                if( (app.thirdVelocity.YData(i) < app.thirdVelocityLimits(1)) || (app.thirdVelocity.YData(i) > app.thirdVelocityLimits(2)) )
                    thirdVelocityIndex(end + 1) = i;
                end

                if( (app.thirdAcceleration.YData(i) < app.AccelerationLimits(1)) || (app.thirdAcceleration.YData(i) > app.AccelerationLimits(2)) )
                    thirdAccelerationIndex(end + 1) = i;
                end 
            end    
            
            for i = 1:length(app.fourthVelocity.XData)
                if( (app.fourthVelocity.YData(i) < app.fourthVelocityLimits(1)) || (app.fourthVelocity.YData(i) > app.fourthVelocityLimits(2)) )
                    fourthVelocityIndex(end + 1) = i;
                end

                if( (app.fourthAcceleration.YData(i) < app.AccelerationLimits(1)) || (app.fourthAcceleration.YData(i) > app.AccelerationLimits(2)) )
                    fourthAccelerationIndex(end + 1) = i;
                end 
            end    

            for i = 1:length(app.fifthVelocity.XData)
                if( (app.fifthVelocity.YData(i) < app.fifthVelocityLimits(1)) || (app.fifthVelocity.YData(i) > app.fifthVelocityLimits(2)) )
                    fifthVelocityIndex(end + 1) = i;
                end

                if( (app.fifthAcceleration.YData(i) < app.AccelerationLimits(1)) || (app.fifthAcceleration.YData(i) > app.AccelerationLimits(2)) )
                    fifthAccelerationIndex(end + 1) = i;
                end 
            end

            for i = 1:length(app.sixthVelocity.XData)

                if( (app.sixthVelocity.YData(i) < app.sixthVelocityLimits(1)) || (app.sixthVelocity.YData(i) > app.sixthVelocityLimits(2)) )
                    sixthVelocityIndex(end + 1) = i;
                end

                if( (app.sixthAcceleration.YData(i) < app.AccelerationLimits(1)) || (app.sixthAcceleration.YData(i) > app.AccelerationLimits(2)) )
                    sixthAccelerationIndex(end + 1) = i;
                end 

            end
          end 

           if(~isempty(firstVelocityIndex))
                delete(app.firstVelocityMarker);
                hold(app.Geschwindigkeit_erste_Achse,"on")
                app.firstVelocityMarker = plot(app.Geschwindigkeit_erste_Achse,app.firstVelocity.XData(firstVelocityIndex),app.firstVelocity.YData(firstVelocityIndex),"Marker","*",'LineStyle','none');
                hold(app.Geschwindigkeit_erste_Achse,"off")
           end

           if(~isempty(firstAccelerationIndex))
               delete(app.firstAccelerationMarker);
               hold(app.Beschleuinigung_erste_Achse,"on")
               app.firstAccelerationMarker = plot(app.Beschleuinigung_erste_Achse,app.firstAcceleration.XData(firstAccelerationIndex),app.firstAcceleration.YData(firstAccelerationIndex),"Marker","*",'LineStyle','none');
               hold(app.Beschleuinigung_erste_Achse,"off")
           end

           if(~isempty(secondVelocityIndex))
                delete(app.secondVelocityMarker);
                hold(app.Geschwindigkeit_zweite_Achse,"on")
                app.secondVelocityMarker = plot(app.Geschwindigkeit_zweite_Achse,app.secondVelocity.XData(secondVelocityIndex),app.secondVelocity.YData(secondVelocityIndex),"Marker","*",'LineStyle','none','Color','red');
                hold(app.Geschwindigkeit_zweite_Achse,"off")
           end

           if(~isempty(secondAccelerationIndex))
               delete(app.secondAccelerationMarker);
               hold(app.Beschleuinigung_zweite_Achse,"on")
               app.secondAccelerationMarker = plot(app.Beschleuinigung_zweite_Achse,app.secondAcceleration.XData(secondAccelerationIndex),app.secondAcceleration.YData(secondAccelerationIndex),"Marker","*",'LineStyle','none','Color','red');
               hold(app.Beschleuinigung_zweite_Achse,"off")
           end

           if(~isempty(thirdVelocityIndex))
                delete(app.thirdVelocityMarker);
                hold(app.Geschwindigkeit_dritte_Achse,"on")
                app.thirdVelocityMarker = plot(app.Geschwindigkeit_dritte_Achse,app.thirdVelocity.XData(thirdVelocityIndex),app.thirdVelocity.YData(thirdVelocityIndex),"Marker","*",'LineStyle','none','Color','red');
                hold(app.Geschwindigkeit_dritte_Achse,"off")
           end

           if(~isempty(thirdAccelerationIndex))
               delete(app.thirdAccelerationMarker);
               hold(app.Beschleuinigung_dritte_Achse,"on")
               app.thirdAccelerationMarker = plot(app.Beschleuinigung_dritte_Achse,app.thirdAcceleration.XData(thirdAccelerationIndex),app.thirdAcceleration.YData(thirdAccelerationIndex),"Marker","*",'LineStyle','none','Color','red');
               hold(app.Beschleuinigung_dritte_Achse,"off")
           end

           if(~isempty(fourthVelocityIndex))
                delete(app.fourthVelocityMarker);
                hold(app.Geschwindigkeit_vierte_Achse,"on")
                app.fourthVelocityMarker = plot(app.Geschwindigkeit_vierte_Achse,app.fourthVelocity.XData(fourthVelocityIndex),app.fourthVelocity.YData(fourthVelocityIndex),"Marker","*",'LineStyle','none','Color','red');
                hold(app.Geschwindigkeit_vierte_Achse,"off")
           end

           if(~isempty(fourthAccelerationIndex))
               delete(app.fourthAccelerationMarker);
               hold(app.Beschleuinigung_vierte_Achse,"on")
               app.fourthAccelerationMarker = plot(app.Beschleuinigung_vierte_Achse,app.fourthAcceleration.XData(fourthAccelerationIndex),app.fourthAcceleration.YData(fourthAccelerationIndex),"Marker","*",'LineStyle','none','Color','red');
               hold(app.Beschleuinigung_vierte_Achse,"off")
           end

           if(~isempty(fifthVelocityIndex))
                delete(app.fifthVelocityMarker);
                hold(app.Geschwindigkeit_fuenfte_Achse,"on")
                app.fifthVelocityMarker = plot(app.Geschwindigkeit_fuenfte_Achse,app.fifthVelocity.XData(fifthVelocityIndex),app.fifthVelocity.YData(fifthVelocityIndex),"Marker","*",'LineStyle','none','Color','red');
                hold(app.Geschwindigkeit_fuenfte_Achse,"off")
           end

           if(~isempty(fifthAccelerationIndex))
               delete(app.fifthAccelerationMarker);
               hold(app.Beschleuinigung_fuenfte_Achse,"on")
               app.fifthAccelerationMarker = plot(app.Beschleuinigung_fuenfte_Achse,app.fifthAcceleration.XData(fifthAccelerationIndex),app.fifthAcceleration.YData(fifthAccelerationIndex),"Marker","*",'LineStyle','none','Color','red');
               hold(app.Beschleuinigung_fuenfte_Achse,"off")
           end

           if(~isempty(sixthVelocityIndex))
                delete(app.sixthVelocityMarker);
                hold(app.Geschwindigkeit_sechste_Achse,"on")
                app.sixthVelocityMarker = plot(app.Geschwindigkeit_sechste_Achse,app.sixthVelocity.XData(sixthVelocityIndex),app.sixthVelocity.YData(sixthVelocityIndex),"Marker","*",'LineStyle','none','Color','red');
                hold(app.Geschwindigkeit_sechste_Achse,"off")
           end

           if(~isempty(sixthAccelerationIndex))
               delete(app.sixthAccelerationMarker);
               hold(app.Beschleuinigung_sechste_Achse,"on")
               app.sixthAccelerationMarker = plot(app.Beschleuinigung_sechste_Achse,app.sixthAcceleration.XData(sixthAccelerationIndex),app.sixthAcceleration.YData(sixthAccelerationIndex),"Marker","*",'LineStyle','none','Color','red');
               hold(app.Beschleuinigung_sechste_Achse,"off")
           end


        end    

        function makeAxisnotChange(app)

            axis(app.Geschwindigkeit_erste_Achse, 'manual');
            axis(app.Beschleuinigung_erste_Achse, 'manual');
            axis(app.Ruck_erste_Achse, 'manual');

            axis(app.Geschwindigkeit_zweite_Achse, 'manual');
            axis(app.Beschleuinigung_zweite_Achse, 'manual');
            axis(app.Ruck_zweite_Achse, 'manual');

            axis(app.Geschwindigkeit_dritte_Achse, 'manual');
            axis(app.Beschleuinigung_dritte_Achse, 'manual');
            axis(app.Ruck_dritte_Achse, 'manual');

            axis(app.Geschwindigkeit_vierte_Achse, 'manual');
            axis(app.Beschleuinigung_vierte_Achse, 'manual');
            axis(app.Ruck_vierte_Achse, 'manual');

            axis(app.Geschwindigkeit_fuenfte_Achse, 'manual');
            axis(app.Beschleuinigung_fuenfte_Achse, 'manual');
            axis(app.Ruck_fuenfte_Achse, 'manual');

            axis(app.Geschwindigkeit_sechste_Achse, 'manual');
            axis(app.Beschleuinigung_sechste_Achse, 'manual');
            axis(app.Ruck_sechste_Achse, 'manual');
        end    

        
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, matfileName)
            
            %LOAD SIMULATION RESULTS INTO A VARIABLE
            results = matfile(matfileName);
            optimized_translational_values_load = results.x;
            
            %LOAD SPECIFIC COLUMNS INTO THESE TWO VARIABLES (needed for
            %splineoptimal)
            timeintervals = optimized_translational_values_load(1:size(optimized_translational_values_load,1)-1,1);
            base_points = optimized_translational_values_load(:,2:size(optimized_translational_values_load,2));
            app.basePoints = base_points;
            app.matfileName = matfileName;
             

            %CAlCULATE VELOCITY, ACCELERATION AND RUCK
            for i=1:size(base_points,2)

                    [spline, velocity, acceleration, ruck , time , place ] = splineOptimal2(base_points(:,i)',timeintervals, false);

                    %Add last point to the spline since it gets lost by
                    %splineOptimal function
                     spline(end + 1) = base_points(end,i);
                     timeSpline = time(:);
                     timeSpline(end + 1) = time(end) + timeintervals(1);
                     app.timeintervals = timeSpline;
                    %PLOT THE GRAPHS
                    switch i

                        case 1
                        
                        app.firstAxis = plot(app.erste_Achse,timeSpline,spline,'marker','o','Color','green');
                        app.firstAxis.ButtonDownFcn = createCallbackFcn(app, @ChangeValueFirstAxis, true);
                        app.firstVelocity = plot(app.Geschwindigkeit_erste_Achse,time,velocity,'marker','o','Color','magenta');
                        app.firstAcceleration = plot(app.Beschleuinigung_erste_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_erste_Achse,time,ruck,'marker','o','Color','red');

                        line(app.Geschwindigkeit_erste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.39 1.39],'Color','black');
                        line(app.Geschwindigkeit_erste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.39 -1.39],'Color','black');
                        line(app.Beschleuinigung_erste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_erste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');

                        case 2
                        app.secondAxis = plot(app.zweite_Achse,timeSpline,spline,'marker','o','Color','green');
                        app.secondAxis.ButtonDownFcn = createCallbackFcn(app, @ChangeValueSecondAxis, true);
                        app.secondVelocity = plot(app.Geschwindigkeit_zweite_Achse,time,velocity,'marker','o','Color','magenta');
                        app.secondAcceleration = plot(app.Beschleuinigung_zweite_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_zweite_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_zweite_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.31 1.31],'Color','black');
                        line(app.Geschwindigkeit_zweite_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.31 -1.31],'Color','black');
                        line(app.Beschleuinigung_zweite_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_zweite_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');

                        case 3
                        app.thirdAxis = plot(app.dritte_Achse,timeSpline,spline,'marker','o','Color','green');
                        app.thirdAxis.ButtonDownFcn = createCallbackFcn(app, @ChangeValueThirdAxis, true);
                        app.thirdVelocity = plot(app.Geschwindigkeit_dritte_Achse,time,velocity,'marker','o','Color','magenta');
                        app.thirdAcceleration = plot(app.Beschleuinigung_dritte_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_dritte_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_dritte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.22 1.22],'Color','black');
                        line(app.Geschwindigkeit_dritte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.22 -1.22],'Color','black');
                        line(app.Beschleuinigung_dritte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_dritte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');


                        case 4
                        app.fourthAxis = plot(app.vierte_Achse,timeSpline,spline,'marker','o','Color','green');
                        app.fourthAxis.ButtonDownFcn = createCallbackFcn(app, @ChangeValueFourthAxis, true);
                        app.fourthVelocity = plot(app.Geschwindigkeit_vierte_Achse,time,velocity,'marker','o','Color','magenta');
                        app.fourthAcceleration = plot(app.Beschleuinigung_vierte_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_vierte_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_vierte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.22 1.22],'Color','black');
                        line(app.Geschwindigkeit_vierte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.22 -1.22],'Color','black');
                        line(app.Beschleuinigung_vierte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_vierte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');

                        case 5
                        app.fifthAxis = plot(app.fuenfte_Achse,timeSpline,spline,'marker','o','Color','green');
                        app.fifthAxis.ButtonDownFcn = createCallbackFcn(app, @ChangeValueFifthAxis, true);
                        app.fifthVelocity = plot(app.Geschwindigkeit_fuenfte_Achse,time,velocity,'marker','o','Color','magenta');
                        app.fifthAcceleration = plot(app.Beschleuinigung_fuenfte_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_fuenfte_Achse,time,ruck,'marker','o','Color','red')

                        line(app.Geschwindigkeit_fuenfte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.22 1.22],'Color','black');
                        line(app.Geschwindigkeit_fuenfte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.22 -1.22],'Color','black');
                        line(app.Beschleuinigung_fuenfte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_fuenfte_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');

                        case 6
                        app.sixthAxis = plot(app.sechste_Achse,timeSpline,spline,'marker','o','Color','green');
                        app.sixthAxis.ButtonDownFcn = createCallbackFcn(app, @ChangeValueSixthAxis, true);
                        app.sixthVelocity = plot(app.Geschwindigkeit_sechste_Achse,time,velocity,'marker','o','Color','magenta');
                        app.sixthAcceleration = plot(app.Beschleuinigung_sechste_Achse,time,acceleration,'marker','o','Color','blue');
                        plot(app.Ruck_sechste_Achse,time,ruck,'marker','o','Color','red')
                        
                        line(app.Geschwindigkeit_sechste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[1.6 1.6],'Color','black');
                        line(app.Geschwindigkeit_sechste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-1.6 -1.6],'Color','black');
                        line(app.Beschleuinigung_sechste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[3.5 3.5],'Color','black');
                        line(app.Beschleuinigung_sechste_Achse,[0 app.Geschwindigkeit_erste_Achse.XLim(2)],[-3.5 -3.5],'Color','black');
                    end
            end

             %MAKE AXIS MANUAL SO AXISES DO NOT CHANGE
             makeAxisnotChange(app);

             checkLimits(app);

             %Reshape Undo Arrays
             app.xPointsUndo = reshape(app.xPointsUndo,[0 length(app.firstAxis.XData)]);
             app.yPointsUndo = reshape(app.yPointsUndo,[0 length(app.firstAxis.XData)]);
        end

        % Value changed function: TimeSpinner
        function TimeSpinnerValueChanged(app, event)
            %For Undo
            app.timeAngleSelections(end + 1) = 0;
            app.axisSelections(end + 1) = app.actualAxis;
            
            % Change the values of the x coordinate and the y coordinate of
            % the selected point to the value of the Spinner
            % After changing the value we update the graphs by using the
            % spline function (Calculation of timeintervals
            % (distanceValues)) needed
            if(app.axisSelected)

                %Store Value into undo array
                app.xPointsUndo(end + 1, :) = app.firstAxis.XData(:); 

                %Change Points
                for point = app.dataPoints
                    %We need to calculate the value to add in this
                    %way since we do not know if we increased or
                    %decreased the value
                               

                    %Change Time
                    valueToAdd = app.TimeSpinner.Value - app.firstAxis.XData(app.dataPoints(length(app.dataPoints(:))));
                    app.firstAxis.XData(point) = app.firstAxis.XData(point) + valueToAdd;
                    app.secondAxis.XData(point) = app.secondAxis.XData(point) + valueToAdd;
                    app.thirdAxis.XData(point) = app.thirdAxis.XData(point) + valueToAdd;
                    app.fourthAxis.XData(point) = app.fourthAxis.XData(point) + valueToAdd;
                    app.fifthAxis.XData(point) = app.fifthAxis.XData(point) + valueToAdd;
                    app.sixthAxis.XData(point) = app.sixthAxis.XData(point) + valueToAdd;
                    

                end
                
                %Update Markers
                switch app.actualAxis

                    case 1          

                            app.firstAxisMarker.XData(1) = app.firstAxis.XData(app.dataPoints(1));

                            if(length(app.firstAxisMarker.XData) > 1)
                                app.firstAxisMarker.XData(2) = app.firstAxis.XData(app.dataPoints(2));
                            end
                    case 2
                         
                            app.secondAxisMarker.XData(1) = app.secondAxis.XData(app.dataPoints(1));

                            if(length(app.secondAxisMarker.XData) > 1)
                                app.secondAxisMarker.XData(2) = app.secondAxis.XData(app.dataPoints(2));
                            end
                        
                    case 3
                         
                            app.thirdAxisMarker.XData(1) = app.thirdAxis.XData(app.dataPoints(1));

                            if(length(app.thirdAxisMarker.XData) > 1)
                                app.thirdAxisMarker.XData(2) = app.thirdAxis.XData(app.dataPoints(2));
                            end
                       
                    case 4
                         
                            app.fourthAxisMarker.XData(1) = app.fourthAxis.XData(app.dataPoints(1));

                            if(length(app.fourthAxisMarker.XData) > 1)
                                app.fourthAxisMarker.XData(2) = app.fourthAxis.XData(app.dataPoints(2));
                            end
                        
                    case 5
                         
                            app.fifthAxisMarker.XData(1) = app.fifthAxis.XData(app.dataPoints(1));

                            if(length(app.fifthAxisMarker.XData) > 1)
                                app.fifthAxisMarker.XData(2) = app.fifthAxis.XData(app.dataPoints(2));
                            end
                        
                    case 6
                         
                            app.sixthAxisMarker.XData(1) = app.sixthAxis.XData(app.dataPoints(1));

                            if(length(app.sixthAxisMarker.XData) > 1)
                                app.sixthAxisMarker.XData(2) = app.sixthAxis.XData(app.dataPoints(2));
                            end
                        
                 end
                
                %Calculate timeintervals and update graphs
                distanceValues = zeros(1,size(app.firstAxis.XData,1));
                sizeofArray = length(app.firstAxis.XData(:));
                for i = 1:sizeofArray-1
                    distanceValues(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                end
                
                %Update graph
                updateGraphs(app, app.firstAxis.YData(:),distanceValues,1)
                updateGraphs(app, app.secondAxis.YData(:),distanceValues,2)
                updateGraphs(app, app.thirdAxis.YData(:),distanceValues,3)
                updateGraphs(app, app.fourthAxis.YData(:),distanceValues,4)
                updateGraphs(app, app.fifthAxis.YData(:),distanceValues,5)
                updateGraphs(app, app.sixthAxis.YData(:),distanceValues,6)


            end
            
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
             %For Undo
             app.timeAngleSelections(end + 1) = 1;
             app.axisSelections(end +1) = app.actualAxis;

             % Change the values of the x coordinate and the y coordinate of
             % the selected point to the value of the Spinner
             % After changing the value we update the graphs by using the
             % spline function (Calculation of timeintervals
             % (distanceValues)) needed
             if(app.axisSelected)

                distanceValues = zeros(1,size(app.firstAxis.XData,1));
                sizeofArray = length(app.firstAxis.XData(:));
                for i = 1:sizeofArray-1
                    distanceValues(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                end

                updateGraphs(app, app.firstAxis.YData(:),distanceValues,app.actualAxis)
                
                switch app.actualAxis

                    case 1
                        
                        %For Undo
                        app.yPointsUndo(end + 1, :) = app.firstAxis.YData(:);

                        for point = app.dataPoints
                            %We need to calculate the value to add in this
                            %way since we do not know if we increased or
                            %decreased the value

                            valueToAdd = app.AngleSpinner.Value - app.firstAxis.YData(app.dataPoints(length(app.dataPoints(:))));
                            app.firstAxis.YData(point) = app.firstAxis.YData(point) + valueToAdd;
                            
                        end
                        
                        app.firstAxisMarker.YData(1) = app.firstAxis.YData(app.dataPoints(1));

                        if(length(app.firstAxisMarker.XData) > 1)
                            app.firstAxisMarker.YData(2) = app.firstAxis.YData(app.dataPoints(2));
                        end

                        updateGraphs(app, app.firstAxis.YData(:),distanceValues,app.actualAxis)
                    case 2

                        %For Undo
                        app.yPointsUndo(end + 1, :) = app.secondAxis.YData(:);

                        for point = app.dataPoints
                            %We need to calculate the value to add in this
                            %way since we do not know if we increased or
                            %decreased the value

                            valueToAdd = app.AngleSpinner.Value - app.secondAxis.YData(app.dataPoints(length(app.dataPoints(:))));
                            app.secondAxis.YData(point) = app.secondAxis.YData(point) + valueToAdd;
                            
                        end

                        app.secondAxisMarker.YData(1) = app.secondAxis.YData(app.dataPoints(1));

                        if(length(app.secondAxisMarker.XData) > 1)
                            app.secondAxisMarker.YData(2) = app.secondAxis.YData(app.dataPoints(2));
                        end

                        updateGraphs(app, app.secondAxis.YData(:),distanceValues,app.actualAxis)
                    case 3

                        %For Undo
                        app.yPointsUndo(end + 1, :) = app.thirdAxis.YData(:);

                        for point = app.dataPoints
                            %We need to calculate the value to add in this
                            %way since we do not know if we increased or
                            %decreased the value

                            valueToAdd = app.AngleSpinner.Value - app.thirdAxis.YData(app.dataPoints(length(app.dataPoints(:))));
                            app.thirdAxis.YData(point) = app.thirdAxis.YData(point) + valueToAdd;
                            
                        end

                        app.thirdAxisMarker.YData(1) = app.thirdAxis.YData(app.dataPoints(1));

                        if(length(app.thirdAxisMarker.XData) > 1)
                            app.thirdAxisMarker.YData(2) = app.thirdAxis.YData(app.dataPoints(2));
                        end

                        updateGraphs(app, app.thirdAxis.YData(:),distanceValues,app.actualAxis)
                    case 4

                        %For Undo
                        app.yPointsUndo(end + 1, :) = app.fourthAxis.YData(:);

                        for point = app.dataPoints
                            %We need to calculate the value to add in this
                            %way since we do not know if we increased or
                            %decreased the value

                            valueToAdd = app.AngleSpinner.Value - app.fourthAxis.YData(app.dataPoints(length(app.dataPoints(:))));
                            app.fourthAxis.YData(point) = app.fourthAxis.YData(point) + valueToAdd;
                            
                        end

                        app.fourthAxisMarker.YData(1) = app.fourthAxis.YData(app.dataPoints(1));

                        if(length(app.fourthAxisMarker.XData) > 1)
                            app.fourthAxisMarker.YData(2) = app.fourthAxis.YData(app.dataPoints(2));
                        end

                        updateGraphs(app, app.fourthAxis.YData(:),distanceValues,app.actualAxis)
                    case 5

                        %For Undo
                        app.yPointsUndo(end + 1, :) = app.fifthAxis.YData(:);

                        for point = app.dataPoints
                            %We need to calculate the value to add in this
                            %way since we do not know if we increased or
                            %decreased the value

                          
                            valueToAdd = app.AngleSpinner.Value - app.fifthAxis.YData(app.dataPoints(length(app.dataPoints(:))));
                            app.fifthAxis.YData(point) = app.fifthAxis.YData(point) + valueToAdd;
                            
                        end

                        app.fifthAxisMarker.YData(1) = app.fifthAxis.YData(app.dataPoints(1));

                        if(length(app.fifthAxisMarker.XData) > 1)
                            app.fifthAxisMarker.YData(2) = app.fifthAxis.YData(app.dataPoints(2));
                        end

                        updateGraphs(app, app.fifthAxis.YData(:),distanceValues,app.actualAxis)
                    case 6

                        %For Undo
                        app.yPointsUndo(end + 1, :) = app.sixthAxis.YData(:);

                        for point = app.dataPoints
                            %We need to calculate the value to add in this
                            %way since we do not know if we increased or
                            %decreased the value

                            valueToAdd = app.AngleSpinner.Value - app.sixthAxis.YData(app.dataPoints(length(app.dataPoints(:))));
                            app.sixthAxis.YData(point) = app.sixthAxis.YData(point) + valueToAdd;
                            
                        end

                         app.sixthAxisMarker.YData(1) = app.sixthAxis.YData(app.dataPoints(1));

                        if(length(app.sixthAxisMarker.XData) > 1)
                            app.sixthAxisMarker.YData(2) = app.sixthAxis.YData(app.dataPoints(2));
                        end

                        updateGraphs(app, app.sixthAxis.YData(:),distanceValues,app.actualAxis)

                end
             end


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Enable the Spinner
            app.TimeSpinner.Enable = true;
            app.AngleSpinner.Enable = true;
            
            %Enable Slider
            if(length(app.dataPoints(:)) > 1)
                app.StauchungsfaktorxfacheSlider.Enable = true;
                app.StreckungsfaktorxfacheSlider.Enable = true;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            %Clear everything and disable Spinner and Slider
            app.TimeSpinner.Enable = false;
            app.AngleSpinner.Enable = false;
            
            app.TimeSpinner.Value = 0;
            app.AngleSpinner.Value = 0;

            app.StauchungsfaktorxfacheSlider.Enable = false;
            app.StreckungsfaktorxfacheSlider.Enable = false;

            app.StauchungsfaktorxfacheSlider.Value = 0;
            app.StreckungsfaktorxfacheSlider.Value = 0;

            app.dataPoints = [];

            app.NoAxisselectedLabel.Text = "No Axis selected";
            app.DataPointwiththeindexLabel.Text = "Data Point with the index: ";
            
            if(app.firstAxisMarker ~= 0)
                app.firstAxisMarker.XData = [];
                app.firstAxisMarker.YData = [];
            end
            if(app.secondAxisMarker ~= 0)
                app.secondAxisMarker.XData = [];
                app.secondAxisMarker.YData = [];
            end
            if(app.thirdAxisMarker ~= 0)
                app.thirdAxisMarker.XData = [];
                app.thirdAxisMarker.YData = [];
            end
            if(app.fourthAxisMarker ~= 0)
                app.fourthAxisMarker.XData = [];
                app.fourthAxisMarker.YData = [];
            end
            if(app.fifthAxisMarker ~= 0)
                app.fifthAxisMarker.XData = [];
                app.fifthAxisMarker.YData = [];
            end
            if(app.sixthAxisMarker ~= 0)
                app.sixthAxisMarker.XData = [];
                app.sixthAxisMarker.YData = [];
            end
            app.axisSelected = false;

            %%TODO: If you change XSpinner when app.selectedIndex is nan
            %%than it does not compile --> needs to be fixed or
            %%app.selectedIndex shall not be set nan
            app.SelectedIndex = nan;

            
        end

        % Value changed function: StreckungsfaktorxfacheSlider
        function StreckungsfaktorxfacheSliderValueChanged(app, event)
                %For Undo
                app.timeAngleSelections(end + 1) = 0;
                app.axisSelections(end + 1) = app.actualAxis;

                %%TODO: CAPSULE THIS INTO A FUNCTION TO MAKE CODE CLEANER 
                %Calculation of timeintervals
                timeintervals = zeros(1,size(app.firstAxis.XData,1));
                sizeofArray = length(app.firstAxis.XData(:));
                
                for i = 1:sizeofArray-1
                    timeintervals(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                end
                
                %%HIER FRAGEN
                timeintervals(1) = 0;
                timeintervals(end + 1) = 0.1421;
    
                dataPoints = sort(app.dataPoints);

                %Points that will be changed
                pointsToChange = [];
                for i = dataPoints(1):dataPoints(2)
                    pointsToChange(end + 1) = i;
                end

                %Calculate middle point
                middlePoint = round(median(pointsToChange));

                %Calculate the new timeintervals with
                %calculation of the factor by using normpdf
                standardDeviation = (pointsToChange(end) - pointsToChange(1))/6;
                
                timeintervals(dataPoints(1):dataPoints(2)) = timeintervals(dataPoints(1):dataPoints(2)) .* (1+(normpdf(pointsToChange,middlePoint,standardDeviation)*app.StreckungsfaktorxfacheSlider.Value));
                
                %Calculation of the new X-Points
                xData = [];
                newX = 0;
                for i = 1:length(timeintervals)
                    newX = sum(timeintervals(1:i));
                    xData(end + 1) = newX;
                    newX = 0;
                end
                
                %Store Value into undo array
                app.xPointsUndo(end + 1, :) = app.firstAxis.XData(:);

                app.firstAxis.XData(:) = xData(:);
                app.secondAxis.XData(:) = xData(:);
                app.thirdAxis.XData(:) = xData(:);
                app.fourthAxis.XData(:) = xData(:);
                app.fifthAxis.XData(:) = xData(:);
                app.sixthAxis.XData(:) = xData(:);

                %Update Markers
                switch app.actualAxis

                    case 1          

                            app.firstAxisMarker.XData(1) = app.firstAxis.XData(app.dataPoints(1));

                            if(length(app.firstAxisMarker.XData) > 1)
                                app.firstAxisMarker.XData(2) = app.firstAxis.XData(app.dataPoints(2));
                            end
                    case 2
                         
                            app.secondAxisMarker.XData(1) = app.secondAxis.XData(app.dataPoints(1));

                            if(length(app.secondAxisMarker.XData) > 1)
                                app.secondAxisMarker.XData(2) = app.secondAxis.XData(app.dataPoints(2));
                            end
                        
                    case 3
                         
                            app.thirdAxisMarker.XData(1) = app.thirdAxis.XData(app.dataPoints(1));

                            if(length(app.thirdAxisMarker.XData) > 1)
                                app.thirdAxisMarker.XData(2) = app.thirdAxis.XData(app.dataPoints(2));
                            end
                       
                    case 4
                         
                            app.fourthAxisMarker.XData(1) = app.fourthAxis.XData(app.dataPoints(1));

                            if(length(app.fourthAxisMarker.XData) > 1)
                                app.fourthAxisMarker.XData(2) = app.fourthAxis.XData(app.dataPoints(2));
                            end
                        
                    case 5
                         
                            app.fifthAxisMarker.XData(1) = app.fifthAxis.XData(app.dataPoints(1));

                            if(length(app.fifthAxisMarker.XData) > 1)
                                app.fifthAxisMarker.XData(2) = app.fifthAxis.XData(app.dataPoints(2));
                            end
                        
                    case 6
                         
                            app.sixthAxisMarker.XData(1) = app.sixthAxis.XData(app.dataPoints(1));

                            if(length(app.sixthAxisMarker.XData) > 1)
                                app.sixthAxisMarker.XData(2) = app.sixthAxis.XData(app.dataPoints(2));
                            end
                        
                 end
            
                %Calculation of timeintervals and update the graphs
                distanceValues = zeros(1,size(app.firstAxis.XData,1));
                sizeofArray = length(app.firstAxis.XData(:));
                for i = 1:sizeofArray-1
                    distanceValues(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                end

                updateGraphs(app, app.firstAxis.YData(:),distanceValues,1)
                updateGraphs(app, app.secondAxis.YData(:),distanceValues,2)
                updateGraphs(app, app.thirdAxis.YData(:),distanceValues,3)
                updateGraphs(app, app.fourthAxis.YData(:),distanceValues,4)
                updateGraphs(app, app.fifthAxis.YData(:),distanceValues,5)
                updateGraphs(app, app.sixthAxis.YData(:),distanceValues,6)

                %Update the Spinner
                app.TimeSpinner.Value = xData(app.dataPoints(2));
            
        end

        % Value changed function: StauchungsfaktorxfacheSlider
        function StauchungsfaktorxfacheSliderValueChanged(app, event)
                %For Undo
                app.timeAngleSelections(end + 1) = 0;
                app.axisSelections(end + 1) = app.actualAxis;

                %Calculation of timeintervals
                timeintervals = zeros(1,size(app.firstAxis.XData,1));
                sizeofArray = length(app.firstAxis.XData(:));
                
                for i = 1:sizeofArray-1
                    timeintervals(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                end
                timeintervals(1) = 0;
                timeintervals(end + 1) = 0.1421;

                dataPoints = sort(app.dataPoints);

                %Points that will be changed
                pointsToChange = [];
                for i = dataPoints(1):dataPoints(2)
                    pointsToChange(end + 1) = i;
                end

                %Calculate middle point
                middlePoint = round(median(pointsToChange));

                %Calculate the new timeintervals with
                %calculation of the factor by using normpdf
                standardDeviation = (pointsToChange(end) - pointsToChange(1))/6;
                
                timeintervals(dataPoints(1):dataPoints(2)) = timeintervals(dataPoints(1):dataPoints(2)) .* (1-(normpdf(pointsToChange,middlePoint,standardDeviation)*app.StauchungsfaktorxfacheSlider.Value));
                
                %Calculation of the new X-Points
                xData = [];
                newX = 0;
                for i = 1:length(timeintervals)
                    newX = sum(timeintervals(1:i));
                    xData(end + 1) = newX;
                    newX = 0;
                end
                
                %Store Value into undo array
                app.xPointsUndo(end + 1, :) = app.firstAxis.XData(:);

                app.firstAxis.XData(:) = xData(:);
                app.secondAxis.XData(:) = xData(:);
                app.thirdAxis.XData(:) = xData(:);
                app.fourthAxis.XData(:) = xData(:);
                app.fifthAxis.XData(:) = xData(:);
                app.sixthAxis.XData(:) = xData(:);

               %Update Markers
                switch app.actualAxis

                    case 1          

                            app.firstAxisMarker.XData(1) = app.firstAxis.XData(app.dataPoints(1));

                            if(length(app.firstAxisMarker.XData) > 1)
                                app.firstAxisMarker.XData(2) = app.firstAxis.XData(app.dataPoints(2));
                            end
                    case 2
                         
                            app.secondAxisMarker.XData(1) = app.secondAxis.XData(app.dataPoints(1));

                            if(length(app.secondAxisMarker.XData) > 1)
                                app.secondAxisMarker.XData(2) = app.secondAxis.XData(app.dataPoints(2));
                            end
                        
                    case 3
                         
                            app.thirdAxisMarker.XData(1) = app.thirdAxis.XData(app.dataPoints(1));

                            if(length(app.thirdAxisMarker.XData) > 1)
                                app.thirdAxisMarker.XData(2) = app.thirdAxis.XData(app.dataPoints(2));
                            end
                       
                    case 4
                         
                            app.fourthAxisMarker.XData(1) = app.fourthAxis.XData(app.dataPoints(1));

                            if(length(app.fourthAxisMarker.XData) > 1)
                                app.fourthAxisMarker.XData(2) = app.fourthAxis.XData(app.dataPoints(2));
                            end
                        
                    case 5
                         
                            app.fifthAxisMarker.XData(1) = app.fifthAxis.XData(app.dataPoints(1));

                            if(length(app.fifthAxisMarker.XData) > 1)
                                app.fifthAxisMarker.XData(2) = app.fifthAxis.XData(app.dataPoints(2));
                            end
                        
                    case 6
                         
                            app.sixthAxisMarker.XData(1) = app.sixthAxis.XData(app.dataPoints(1));

                            if(length(app.sixthAxisMarker.XData) > 1)
                                app.sixthAxisMarker.XData(2) = app.sixthAxis.XData(app.dataPoints(2));
                            end
                        
                 end
            
                %Calculation of timeintervals and update the graphs
                distanceValues = zeros(1,size(app.firstAxis.XData,1));
                sizeofArray = length(app.firstAxis.XData(:));
                for i = 1:sizeofArray-1
                    distanceValues(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                end

                updateGraphs(app, app.firstAxis.YData(:),distanceValues,1)
                updateGraphs(app, app.secondAxis.YData(:),distanceValues,2)
                updateGraphs(app, app.thirdAxis.YData(:),distanceValues,3)
                updateGraphs(app, app.fourthAxis.YData(:),distanceValues,4)
                updateGraphs(app, app.fifthAxis.YData(:),distanceValues,5)
                updateGraphs(app, app.sixthAxis.YData(:),distanceValues,6)

                %Update the Spinner
                app.TimeSpinner.Value = xData(app.dataPoints(2));
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
           if(~isempty(app.timeAngleSelections))
               test = app.timeAngleSelections;
               test2 = app.xPointsUndo;
               test3 = app.yPointsUndo;
               test4 = app.axisSelections;
            switch app.timeAngleSelections(end)
                %X-Value
                case 0
                    app.firstAxis.XData(:) = app.xPointsUndo(end,:);
                    app.secondAxis.XData(:) = app.xPointsUndo(end,:);
                    app.thirdAxis.XData(:) = app.xPointsUndo(end,:);
                    app.fourthAxis.XData(:) = app.xPointsUndo(end,:);
                    app.fifthAxis.XData(:) = app.xPointsUndo(end,:);
                    app.sixthAxis.XData(:) = app.xPointsUndo(end,:);

                    %Update Markers
                    if(app.axisSelected == true)

                        switch app.actualAxis
    
                        case 1          
    
                                app.firstAxisMarker.XData(1) = app.firstAxis.XData(app.dataPoints(1));
    
                                if(length(app.firstAxisMarker.XData) > 1)
                                    app.firstAxisMarker.XData(2) = app.firstAxis.XData(app.dataPoints(2));
                                end
                        case 2
                             
                                app.secondAxisMarker.XData(1) = app.secondAxis.XData(app.dataPoints(1));
    
                                if(length(app.secondAxisMarker.XData) > 1)
                                    app.secondAxisMarker.XData(2) = app.secondAxis.XData(app.dataPoints(2));
                                end
                            
                        case 3
                             
                                app.thirdAxisMarker.XData(1) = app.thirdAxis.XData(app.dataPoints(1));
    
                                if(length(app.thirdAxisMarker.XData) > 1)
                                    app.thirdAxisMarker.XData(2) = app.thirdAxis.XData(app.dataPoints(2));
                                end
                           
                        case 4
                             
                                app.fourthAxisMarker.XData(1) = app.fourthAxis.XData(app.dataPoints(1));
    
                                if(length(app.fourthAxisMarker.XData) > 1)
                                    app.fourthAxisMarker.XData(2) = app.fourthAxis.XData(app.dataPoints(2));
                                end
                            
                        case 5
                             
                                app.fifthAxisMarker.XData(1) = app.fifthAxis.XData(app.dataPoints(1));
    
                                if(length(app.fifthAxisMarker.XData) > 1)
                                    app.fifthAxisMarker.XData(2) = app.fifthAxis.XData(app.dataPoints(2));
                                end
                            
                        case 6
                             
                                app.sixthAxisMarker.XData(1) = app.sixthAxis.XData(app.dataPoints(1));
    
                                if(length(app.sixthAxisMarker.XData) > 1)
                                    app.sixthAxisMarker.XData(2) = app.sixthAxis.XData(app.dataPoints(2));
                                end
                            
                        end
                        
                    end

                    app.xPointsUndo(end,:) = [];
                    app.axisSelections(end) = [];

                    %Calculation of timeintervals and update the graphs
                    distanceValues = zeros(1,size(app.firstAxis.XData,1));
                    sizeofArray = length(app.firstAxis.XData(:));
                    for i = 1:sizeofArray-1
                        distanceValues(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                    end

                    updateGraphs(app, app.firstAxis.YData(:),distanceValues,1)
                    updateGraphs(app, app.secondAxis.YData(:),distanceValues,2)
                    updateGraphs(app, app.thirdAxis.YData(:),distanceValues,3)
                    updateGraphs(app, app.fourthAxis.YData(:),distanceValues,4)
                    updateGraphs(app, app.fifthAxis.YData(:),distanceValues,5)
                    updateGraphs(app, app.sixthAxis.YData(:),distanceValues,6)

                %Y-Value
                case 1

                    %Calculation of timeintervals and update the graphs
                    distanceValues = zeros(1,size(app.firstAxis.XData,1));
                    sizeofArray = length(app.firstAxis.XData(:));
                    for i = 1:sizeofArray-1
                        distanceValues(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                    end

                    switch app.axisSelections(end)
                        case 1
                            app.firstAxis.YData(:) = app.yPointsUndo(end,:);
                            app.yPointsUndo(end,:) = [];
                            app.axisSelections(end) = [];
                            updateGraphs(app, app.firstAxis.YData(:),distanceValues,1)
                        case 2
                            app.secondAxis.YData(:) = app.yPointsUndo(end,:);
                            app.yPointsUndo(end,:) = [];
                            app.axisSelections(end) = [];
                            updateGraphs(app, app.secondAxis.YData(:),distanceValues,1)
                        case 3
                            app.thirdAxis.YData(:) = app.yPointsUndo(end,:);
                            app.yPointsUndo(end,:) = [];
                            app.axisSelections(end) = [];
                            updateGraphs(app, app.thirdAxis.YData(:),distanceValues,1)
                        case 4
                            app.fourthAxis.YData(:) = app.yPointsUndo(end,:);
                            app.yPointsUndo(end,:) = [];
                            app.axisSelections(end) = [];
                            updateGraphs(app, app.fourthAxis.YData(:),distanceValues,1)
                        case 5
                            app.fifthAxis.YData(:) = app.yPointsUndo(end,:);
                            app.yPointsUndo(end,:) = [];
                            app.axisSelections(end) = [];
                            updateGraphs(app, app.fifthAxis.YData(:),distanceValues,1)
                        case 6
                            
                            app.sixthAxis.YData(:) = app.yPointsUndo(end,:);
                            app.yPointsUndo(end,:) = [];
                            app.axisSelections(end) = [];
                            updateGraphs(app, app.sixthAxis.YData(:),distanceValues,1)
                    end        

            end
            app.timeAngleSelections(end) = [];
           end
            
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)

            %Reset the X-Values
            test = app.timeintervals;
            test2= app.firstAxis.XData(:);
            app.firstAxis.XData(:) = app.timeintervals;
            app.secondAxis.XData(:) = app.timeintervals;
            app.thirdAxis.XData(:) = app.timeintervals;
            app.fourthAxis.XData(:) = app.timeintervals;
            app.fifthAxis.XData(:) = app.timeintervals;
            app.sixthAxis.XData(:) = app.timeintervals;

            %Reset the Y-Values
            app.firstAxis.YData(:) = app.basePoints(:,1);
            app.secondAxis.YData(:) = app.basePoints(:,2);
            app.thirdAxis.YData(:) = app.basePoints(:,3);
            app.fourthAxis.YData(:) = app.basePoints(:,4);
            app.fifthAxis.YData(:) = app.basePoints(:,5);
            app.sixthAxis.YData(:) = app.basePoints(:,6);

            %Update Graphs
            
            %Calculate timeintervals
            distanceValues = zeros(1,size(app.firstAxis.XData,1));
            sizeofArray = length(app.firstAxis.XData(:));
            for i = 1:sizeofArray-1
                distanceValues(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
            end

            updateGraphs(app, app.firstAxis.YData(:),distanceValues,1)
            updateGraphs(app, app.secondAxis.YData(:),distanceValues,2)
            updateGraphs(app, app.thirdAxis.YData(:),distanceValues,3)
            updateGraphs(app, app.fourthAxis.YData(:),distanceValues,4)
            updateGraphs(app, app.fifthAxis.YData(:),distanceValues,5)
            updateGraphs(app, app.sixthAxis.YData(:),distanceValues,6)

            %Clear the rest
                
            app.TimeSpinner.Enable = false;
            app.AngleSpinner.Enable = false;
            
            app.TimeSpinner.Value = 0;
            app.AngleSpinner.Value = 0;

            app.StauchungsfaktorxfacheSlider.Enable = false;
            app.StreckungsfaktorxfacheSlider.Enable = false;

            app.StauchungsfaktorxfacheSlider.Value = 0;
            app.StreckungsfaktorxfacheSlider.Value = 0;

            app.dataPoints = [];

            app.NoAxisselectedLabel.Text = "No Axis selected";
            app.DataPointwiththeindexLabel.Text = "Data Point with the index: ";
            
            if(app.firstAxisMarker ~= 0)
                app.firstAxisMarker.XData = [];
                app.firstAxisMarker.YData = [];
            end
            if(app.secondAxisMarker ~= 0)
                app.secondAxisMarker.XData = [];
                app.secondAxisMarker.YData = [];
            end
            if(app.thirdAxisMarker ~= 0)
                app.thirdAxisMarker.XData = [];
                app.thirdAxisMarker.YData = [];
            end
            if(app.fourthAxisMarker ~= 0)
                app.fourthAxisMarker.XData = [];
                app.fourthAxisMarker.YData = [];
            end
            if(app.fifthAxisMarker ~= 0)
                app.fifthAxisMarker.XData = [];
                app.fifthAxisMarker.YData = [];
            end
            if(app.sixthAxisMarker ~= 0)
                app.sixthAxisMarker.XData = [];
                app.sixthAxisMarker.YData = [];
            end
            
            app.timeAngleSelections = [];
            app.xPointsUndo = [];
            app.yPointsUndo = [];
            app.axisSelections = [];
    
            app.axisSelected = false;

            %%TODO: If you change XSpinner when app.selectedIndex is nan
            %%than it does not compile --> needs to be fixed or
            %%app.selectedIndex shall not be set nan
            app.SelectedIndex = nan;
    

        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            x = zeros(length(app.firstAxis.XData),0);
            

            for i = 1:7
                switch i
                    
                    case 1
                        %Calculate timeintervals
                        distanceValues = zeros(size(app.firstAxis.XData,1),1);
                        sizeofArray = length(app.firstAxis.XData(:));
                        for i = 1:sizeofArray-1
                        distanceValues(i) = app.firstAxis.XData(i+1) - app.firstAxis.XData(i);
                        end
                        distanceValues(end + 1) = 0.1421;
                        x(:,end + 1) = reshape(distanceValues,[length(app.firstAxis.XData),1]);

                    case 2
                        x(:,end + 1) = app.firstAxis.YData(:);
                    case 3
                        x(:,end + 1) = app.secondAxis.YData(:);
                    case 4
                        x(:,end + 1) = app.thirdAxis.YData(:);
                    case 5
                        x(:,end + 1) = app.fourthAxis.YData(:);    
                    case 6
                        x(:,end + 1) = app.fifthAxis.YData(:);
                    case 7
                        x(:,end + 1) = app.sixthAxis.YData(:);
                end
            end
            save(app.matfileName,"x",'-v7.3');
            stop = 0;
            save('stop.mat',"stop");
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.erste_Achse = uiaxes(app.UIFigure);
            title(app.erste_Achse, 'Spline')
            xlabel(app.erste_Achse, 'Time')
            ylabel(app.erste_Achse, 'Angle')
            zlabel(app.erste_Achse, 'Z')
            app.zweite_Achse = uiaxes(app.UIFigure);
            title(app.zweite_Achse, 'Spline')
            xlabel(app.zweite_Achse, 'Time')
            ylabel(app.zweite_Achse, 'Angle')
            zlabel(app.zweite_Achse, 'Z')
            app.dritte_Achse = uiaxes(app.UIFigure);
            title(app.dritte_Achse, 'Spline')
            xlabel(app.dritte_Achse, 'Time')
            ylabel(app.dritte_Achse, 'Angle')
            zlabel(app.dritte_Achse, 'Z')
            app.vierte_Achse = uiaxes(app.UIFigure);
            title(app.vierte_Achse, 'Spline')
            xlabel(app.vierte_Achse, 'Time')
            ylabel(app.vierte_Achse, 'Angle')
            zlabel(app.vierte_Achse, 'Z')
            app.fuenfte_Achse = uiaxes(app.UIFigure);
            title(app.fuenfte_Achse, 'Spline')
            xlabel(app.fuenfte_Achse, 'Time')
            ylabel(app.fuenfte_Achse, 'Angle')
            zlabel(app.fuenfte_Achse, 'Z')
            app.sechste_Achse = uiaxes(app.UIFigure);
            title(app.sechste_Achse, 'Spline')
            xlabel(app.sechste_Achse, 'Time')
            ylabel(app.sechste_Achse, 'Angle')
            zlabel(app.sechste_Achse, 'Z')
            app.Geschwindigkeit_erste_Achse = uiaxes(app.UIFigure);
            title(app.Geschwindigkeit_erste_Achse, 'Geschwindigkeit')
            xlabel(app.Geschwindigkeit_erste_Achse, 'Time')
            ylabel(app.Geschwindigkeit_erste_Achse, 'Velocity')
            zlabel(app.Geschwindigkeit_erste_Achse, 'Z')
            app.Beschleuinigung_erste_Achse = uiaxes(app.UIFigure);
            title(app.Beschleuinigung_erste_Achse, 'Beschleuinigung')
            xlabel(app.Beschleuinigung_erste_Achse, 'Time')
            ylabel(app.Beschleuinigung_erste_Achse, 'Acceleration')
            zlabel(app.Beschleuinigung_erste_Achse, 'Z')
            app.Ruck_erste_Achse = uiaxes(app.UIFigure);
            title(app.Ruck_erste_Achse, 'Ruck')
            xlabel(app.Ruck_erste_Achse, 'Time')
            ylabel(app.Ruck_erste_Achse, 'Jerk')
            zlabel(app.Ruck_erste_Achse, 'Z')
            app.Geschwindigkeit_zweite_Achse = uiaxes(app.UIFigure);
            title(app.Geschwindigkeit_zweite_Achse, 'Geschwindigkeit')
            xlabel(app.Geschwindigkeit_zweite_Achse, 'Time')
            ylabel(app.Geschwindigkeit_zweite_Achse, 'Velocity')
            zlabel(app.Geschwindigkeit_zweite_Achse, 'Z')
            app.Beschleuinigung_zweite_Achse = uiaxes(app.UIFigure);
            title(app.Beschleuinigung_zweite_Achse, 'Beschleuinigung')
            xlabel(app.Beschleuinigung_zweite_Achse, 'Time')
            ylabel(app.Beschleuinigung_zweite_Achse, 'Acceleration')
            zlabel(app.Beschleuinigung_zweite_Achse, 'Z')
            app.Ruck_zweite_Achse = uiaxes(app.UIFigure);
            title(app.Ruck_zweite_Achse, 'Ruck')
            xlabel(app.Ruck_zweite_Achse, 'Time')
            ylabel(app.Ruck_zweite_Achse, 'Jerk')
            zlabel(app.Ruck_zweite_Achse, 'Z')
            app.Geschwindigkeit_dritte_Achse = uiaxes(app.UIFigure);
            title(app.Geschwindigkeit_dritte_Achse, 'Geschwindigkeit')
            xlabel(app.Geschwindigkeit_dritte_Achse, 'Time')
            ylabel(app.Geschwindigkeit_dritte_Achse, 'Velocity')
            zlabel(app.Geschwindigkeit_dritte_Achse, 'Z')
            app.Beschleuinigung_dritte_Achse = uiaxes(app.UIFigure);
            title(app.Beschleuinigung_dritte_Achse, 'Beschleuinigung')
            xlabel(app.Beschleuinigung_dritte_Achse, 'Time')
            ylabel(app.Beschleuinigung_dritte_Achse, 'Acceleration')
            zlabel(app.Beschleuinigung_dritte_Achse, 'Z')
            app.Ruck_dritte_Achse = uiaxes(app.UIFigure);
            title(app.Ruck_dritte_Achse, 'Ruck')
            xlabel(app.Ruck_dritte_Achse, 'Time')
            ylabel(app.Ruck_dritte_Achse, 'Jerk')
            zlabel(app.Ruck_dritte_Achse, 'Z')
            app.Ruck_vierte_Achse = uiaxes(app.UIFigure);
            title(app.Ruck_vierte_Achse, 'Ruck')
            xlabel(app.Ruck_vierte_Achse, 'Time')
            ylabel(app.Ruck_vierte_Achse, 'Jerk')
            zlabel(app.Ruck_vierte_Achse, 'Z')
            app.Beschleuinigung_vierte_Achse = uiaxes(app.UIFigure);
            title(app.Beschleuinigung_vierte_Achse, 'Beschleuinigung')
            xlabel(app.Beschleuinigung_vierte_Achse, 'Time')
            ylabel(app.Beschleuinigung_vierte_Achse, 'Acceleration')
            zlabel(app.Beschleuinigung_vierte_Achse, 'Z')
            app.Geschwindigkeit_vierte_Achse = uiaxes(app.UIFigure);
            title(app.Geschwindigkeit_vierte_Achse, 'Geschwindigkeit')
            xlabel(app.Geschwindigkeit_vierte_Achse, 'Time')
            ylabel(app.Geschwindigkeit_vierte_Achse, 'Velocity')
            zlabel(app.Geschwindigkeit_vierte_Achse, 'Z')
            app.Geschwindigkeit_fuenfte_Achse = uiaxes(app.UIFigure);
            title(app.Geschwindigkeit_fuenfte_Achse, 'Geschwindigkeit')
            xlabel(app.Geschwindigkeit_fuenfte_Achse, 'Time')
            ylabel(app.Geschwindigkeit_fuenfte_Achse, 'Velocity')
            zlabel(app.Geschwindigkeit_fuenfte_Achse, 'Z')
            app.Beschleuinigung_fuenfte_Achse = uiaxes(app.UIFigure);
            title(app.Beschleuinigung_fuenfte_Achse, 'Beschleuinigung')
            xlabel(app.Beschleuinigung_fuenfte_Achse, 'Time')
            ylabel(app.Beschleuinigung_fuenfte_Achse, 'Acceleration')
            zlabel(app.Beschleuinigung_fuenfte_Achse, 'Z')
            app.Ruck_fuenfte_Achse = uiaxes(app.UIFigure);
            title(app.Ruck_fuenfte_Achse, 'Ruck')
            xlabel(app.Ruck_fuenfte_Achse, 'Time')
            ylabel(app.Ruck_fuenfte_Achse, 'Jerk')
            zlabel(app.Ruck_fuenfte_Achse, 'Z')
            app.Geschwindigkeit_sechste_Achse = uiaxes(app.UIFigure);
            title(app.Geschwindigkeit_sechste_Achse, 'Geschwindigkeit')
            xlabel(app.Geschwindigkeit_sechste_Achse, 'Time')
            ylabel(app.Geschwindigkeit_sechste_Achse, 'Velocity')
            zlabel(app.Geschwindigkeit_sechste_Achse, 'Z')
            app.Beschleuinigung_sechste_Achse = uiaxes(app.UIFigure);
            title(app.Beschleuinigung_sechste_Achse, 'Beschleuinigung')
            xlabel(app.Beschleuinigung_sechste_Achse, 'Time')
            ylabel(app.Beschleuinigung_sechste_Achse, 'Acceleration')
            zlabel(app.Beschleuinigung_sechste_Achse, 'Z')
            app.Ruck_sechste_Achse = uiaxes(app.UIFigure);
            title(app.Ruck_sechste_Achse, 'Ruck')
            xlabel(app.Ruck_sechste_Achse, 'Time')
            ylabel(app.Ruck_sechste_Achse, 'Jerk')
            zlabel(app.Ruck_sechste_Achse, 'Z')
            app.RedoButton = uibutton(app.UIFigure, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Icon = 'icons8-buchung-64.png';
            app.RedoButton.Text = 'Redo';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Icon = 'icons8-wiederkehrender-termin-64.png';
            app.UndoButton.Text = {'Undo'; ''};
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Icon = 'settings-gears.png';
            app.GenerateButton.Text = {'Generate'; ''};
            app.NoAxisselectedLabel = uilabel(app.UIFigure);
            app.NoAxisselectedLabel.Text = 'No Axis selected.';
            app.DataPointwiththeindexLabel = uilabel(app.UIFigure);
            app.DataPointwiththeindexLabel.Text = 'Data Point with the index: ';
            app.TimeSpinnerLabel = uilabel(app.UIFigure);
            app.TimeSpinnerLabel.Enable = 'off';
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.UIFigure);
            app.TimeSpinner.Step = 0.005;
            app.TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeSpinnerValueChanged, true);
            app.TimeSpinner.Enable = 'off';
            app.AngleSpinnerLabel = uilabel(app.UIFigure);
            app.AngleSpinnerLabel.Enable = 'off';
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.UIFigure);
            app.AngleSpinner.Step = 0.005;
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.AngleSpinner.Enable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StauchungsfaktorxfacheSliderLabel = uilabel(app.UIFigure);
            app.StauchungsfaktorxfacheSliderLabel.Enable = 'off';
            app.StauchungsfaktorxfacheSliderLabel.Text = {'Stauchungsfaktor'; '(x-fache)'};
            app.StauchungsfaktorxfacheSlider = uislider(app.UIFigure);
            app.StauchungsfaktorxfacheSlider.Limits = [0 2.5];
            app.StauchungsfaktorxfacheSlider.ValueChangedFcn = createCallbackFcn(app, @StauchungsfaktorxfacheSliderValueChanged, true);
            app.StauchungsfaktorxfacheSlider.Enable = 'off';
            app.StreckungsfaktorxfacheSliderLabel = uilabel(app.UIFigure);
            app.StreckungsfaktorxfacheSliderLabel.Enable = 'off';
            app.StreckungsfaktorxfacheSliderLabel.Text = {'Streckungsfaktor'; '(x-fache)'};
            app.StreckungsfaktorxfacheSlider = uislider(app.UIFigure);
            app.StreckungsfaktorxfacheSlider.Limits = [1 5];
            app.StreckungsfaktorxfacheSlider.ValueChangedFcn = createCallbackFcn(app, @StreckungsfaktorxfacheSliderValueChanged, true);
            app.StreckungsfaktorxfacheSlider.Enable = 'off';
            app.StreckungsfaktorxfacheSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Haendische_Nachbearbeitung(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end