classdef PouringLadle_Inspect_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PouringLadleInspectUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ConvertbagTab                   matlab.ui.container.Tab
        Info_Import_Bag                 matlab.ui.control.Button
        ResetSettingsButton             matlab.ui.control.Button
        SaveSettingsButton              matlab.ui.control.Button
        StartConvertButton              matlab.ui.control.Button
        PlotfirstframeButton            matlab.ui.control.Button
        ImportbagButton                 matlab.ui.control.Button
        SelectfileandstarttheconverterButton  matlab.ui.control.Button
        Info_ProfileBag                 matlab.ui.control.Button
        Info_ConvBag                    matlab.ui.control.Button
        Info_AreaBag                    matlab.ui.control.Button
        ContinueButton_Bag              matlab.ui.control.Button
        SelectCreateconversionProfileButton  matlab.ui.control.Button
        ProfilesDropDown                matlab.ui.control.DropDown
        ProfilesDropDownLabel           matlab.ui.control.Label
        x_maxSpinner                    matlab.ui.control.Spinner
        x_maxSpinnerLabel               matlab.ui.control.Label
        x_minSpinner                    matlab.ui.control.Spinner
        x_minSpinnerLabel               matlab.ui.control.Label
        z_maxSpinner                    matlab.ui.control.Spinner
        z_maxSpinnerLabel               matlab.ui.control.Label
        z_minSpinner                    matlab.ui.control.Spinner
        z_minSpinnerLabel               matlab.ui.control.Label
        y_maxSpinner                    matlab.ui.control.Spinner
        y_maxSpinnerLabel               matlab.ui.control.Label
        y_minSpinner                    matlab.ui.control.Spinner
        y_minSpinnerLabel               matlab.ui.control.Label
        SetallxySpinner                 matlab.ui.control.Spinner
        SetallxySpinnerLabel            matlab.ui.control.Label
        SetshapeofboundryBoxButton      matlab.ui.control.Button
        ConversionOptionsButton         matlab.ui.control.Button
        timeOfRecordingSpinner          matlab.ui.control.Spinner
        timeOfRecordingSpinnerLabel     matlab.ui.control.Label
        PlaybackSpeedSpinner            matlab.ui.control.Spinner
        PlaybackSpeedSpinnerLabel       matlab.ui.control.Label
        fpsOfRecordingSpinner           matlab.ui.control.Spinner
        fpsOfRecordingSpinnerLabel      matlab.ui.control.Label
        UseSettingsOfBagButton          matlab.ui.control.Button
        SettingsOfTheRecordedBagFileTextArea  matlab.ui.control.TextArea
        SelectedbagFileEditField        matlab.ui.control.EditField
        SelectedbagFileLabel            matlab.ui.control.Label
        FilterDataTab                   matlab.ui.container.Tab
        EndFrSpinner                    matlab.ui.control.Spinner
        StartFrSpinner                  matlab.ui.control.Spinner
        plotLadleButton                 matlab.ui.control.Button
        removeLadleButton               matlab.ui.control.Button
        SelectnewLadleButton            matlab.ui.control.Button
        StartFilterButton               matlab.ui.control.Button
        ImportcsvButton                 matlab.ui.control.Button
        Info_ImportCSV                  matlab.ui.control.Button
        SelectfileandstartthefilterButton  matlab.ui.control.Button
        Info_CSVTimerange               matlab.ui.control.Button
        Info_SelectLadle                matlab.ui.control.Button
        Info_LocationCsv                matlab.ui.control.Button
        DropDownFramesliderFilter       matlab.ui.control.DropDown
        ContinueButton_Filter           matlab.ui.control.Button
        SelectthestartandendframetobefilterdButton  matlab.ui.control.Button
        SetthePositionofthepointcloudButton  matlab.ui.control.Button
        EndFrameSlider                  matlab.ui.control.Slider
        EndFrameSliderLabel             matlab.ui.control.Label
        StartFrameSlider                matlab.ui.control.Slider
        StartFrameSliderLabel           matlab.ui.control.Label
        LadleShapeDropDown              matlab.ui.control.DropDown
        LadleShapeDropDownLabel         matlab.ui.control.Label
        dgammaSpinner_Filter            matlab.ui.control.Spinner
        dgammaSpinner_2Label            matlab.ui.control.Label
        dbetaSpinner_Filter             matlab.ui.control.Spinner
        dbetaSpinner_2Label             matlab.ui.control.Label
        dalphaSpinner_Filter            matlab.ui.control.Spinner
        dalphaSpinner_2Label            matlab.ui.control.Label
        dzSpinner_Filter                matlab.ui.control.Spinner
        dzSpinner_2Label                matlab.ui.control.Label
        dySpinner_Filter                matlab.ui.control.Spinner
        dySpinner_2Label                matlab.ui.control.Label
        dxSpinner_Filter                matlab.ui.control.Spinner
        dxSpinner_2Label                matlab.ui.control.Label
        SelectedcsvFileEditField        matlab.ui.control.EditField
        SelectedcsvFileEditFieldLabel   matlab.ui.control.Label
        dgammaSpinner                   matlab.ui.control.Spinner
        dgammaSpinnerLabel              matlab.ui.control.Label
        dbetaSpinner                    matlab.ui.control.Spinner
        dbetaSpinnerLabel               matlab.ui.control.Label
        dalphaSpinner                   matlab.ui.control.Spinner
        dalphaSpinnerLabel              matlab.ui.control.Label
        dzSpinner                       matlab.ui.control.Spinner
        dzSpinnerLabel                  matlab.ui.control.Label
        dySpinner                       matlab.ui.control.Spinner
        dySpinnerLabel                  matlab.ui.control.Label
        dxSpinner                       matlab.ui.control.Spinner
        dxSpinnerLabel                  matlab.ui.control.Label
        SelectedstlEditField            matlab.ui.control.EditField
        SelectedstlLabel                matlab.ui.control.Label
        SetLadleShapestlasfilterobjectButton  matlab.ui.control.Button
        EvaluatePlaybackTab             matlab.ui.container.Tab
        Info_FileselectEvaluate         matlab.ui.control.Button
        OptEvaluateLable                matlab.ui.control.Label
        Opt_Evaluate                    matlab.ui.control.Button
        SelectfilesandstarttheevaluationButton  matlab.ui.control.Button
        OptEvaluationPanel              matlab.ui.container.Panel
        CalAlphaBetaSwitch              matlab.ui.control.Switch
        CalAlphaBetaSwitchLabel         matlab.ui.control.Label
        calcAlpaBetaButton              matlab.ui.control.Button
        CalculateZDiff                  matlab.ui.control.Switch
        CalculateZDiffLabel             matlab.ui.control.Label
        calculateZDiffButton            matlab.ui.control.Button
        CalculateDerivativSwitch        matlab.ui.control.Switch
        CalculateDerivativSwitchLabel   matlab.ui.control.Label
        DistofAngleSwitch               matlab.ui.control.Switch
        DistofAngleSwitchLabel          matlab.ui.control.Label
        DistofResidualsSwitch           matlab.ui.control.Switch
        DistofResidualsSwitchLabel      matlab.ui.control.Label
        FitSurfaceDropDown              matlab.ui.control.DropDown
        FitSurfaceDropDownLabel         matlab.ui.control.Label
        distOfAnglesButton              matlab.ui.control.Button
        calculateGradientButton         matlab.ui.control.Button
        distOfResidualsButton           matlab.ui.control.Button
        FitSurfaceButton                matlab.ui.control.Button
        Info_selectfrEvaluate           matlab.ui.control.Button
        FrameOtTimeDropDown             matlab.ui.control.DropDown
        SelectaframeortimetobeEvaluatedButton  matlab.ui.control.Button
        FrameSlider                     matlab.ui.control.Slider
        FrameSliderLabel                matlab.ui.control.Label
        Selected2csvFilesEditField      matlab.ui.control.EditField
        Select2FileButton               matlab.ui.control.Button
        StartPlaybackButton             matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        AlphaBetaTab                    matlab.ui.container.Tab
        ContourOptionsDropDownLabel_3   matlab.ui.control.Label
        Opt_AlphaBeta                   matlab.ui.control.Button
        Panel_AlphaBeta                 matlab.ui.container.Panel
        setdelta_xySpinner              matlab.ui.control.Spinner
        setdelta_xySpinnerLabel         matlab.ui.control.Label
        Info_TypeAngleDeriv_2           matlab.ui.control.Button
        SetLocationtocalculateAlphaBetaButton  matlab.ui.control.Button
        setxyCoordSpinner               matlab.ui.control.Spinner
        xydistanceto00Label             matlab.ui.control.Label
        PlottingalpabetaButtonGroup     matlab.ui.container.ButtonGroup
        pointslinesButton               matlab.ui.control.RadioButton
        linesonlyButton                 matlab.ui.control.RadioButton
        pointsonlyButton                matlab.ui.control.RadioButton
        UIAxesBeta                      matlab.ui.control.UIAxes
        UiAxisAlpha                     matlab.ui.control.UIAxes
        DerivativAngleTab               matlab.ui.container.Tab
        DistAngleOptionsDropDownLabel   matlab.ui.control.Label
        Opt_DistAngle                   matlab.ui.control.Button
        Warning_NoFit_DistAngle         matlab.ui.control.Button
        OptAnglePanel                   matlab.ui.container.Panel
        YSpinner_2                      matlab.ui.control.Spinner
        YSpinner_2Label                 matlab.ui.control.Label
        XSpinner_2                      matlab.ui.control.Spinner
        XSpinner_2Label                 matlab.ui.control.Label
        XYcoordinatestoevaluateatLabel_2  matlab.ui.control.Label
        Info_locatonEvaluate_2          matlab.ui.control.Button
        DisplayzDiffatxyovertimeButton_2  matlab.ui.control.Button
        SetOptionsforPlottingAnglesoverTimeLabel  matlab.ui.control.Label
        Image5_3                        matlab.ui.control.Image
        Image5_2                        matlab.ui.control.Image
        SetOptionsfortheHistogrammLabel  matlab.ui.control.Label
        SetOptionsfortheContourPlotLabel  matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Info_TypeAngleDeriv_4           matlab.ui.control.Button
        SetOptionsforHistogramButton    matlab.ui.control.Button
        PlottingAnglesButtonGroup       matlab.ui.container.ButtonGroup
        AlphaBetaButton                 matlab.ui.control.RadioButton
        Betaonlyy_AxisButton            matlab.ui.control.RadioButton
        AlphaonlyxAxisButton            matlab.ui.control.RadioButton
        AngleDerivButtonGroup           matlab.ui.container.ButtonGroup
        absolutevalueXYButton           matlab.ui.control.RadioButton
        BetaYDirectionButton            matlab.ui.control.RadioButton
        AlphaXDirectionButton           matlab.ui.control.RadioButton
        Info_Anglelevels                matlab.ui.control.Button
        Info_TypeAngleDeriv_3           matlab.ui.control.Button
        Info_Color_colorbar_Ang         matlab.ui.control.Button
        SetOptionsfortypeofDerivativsButton  matlab.ui.control.Button
        MethodofcreatingButtonGroup_2   matlab.ui.container.ButtonGroup
        UseFixdValuesButton_2           matlab.ui.control.RadioButton
        UseNumberofLinesButton_2        matlab.ui.control.RadioButton
        ChangetheWayhowLinesareplottedButton_2  matlab.ui.control.Button
        SetMaxMinvaluestobeColorizedButton_2  matlab.ui.control.Button
        MaxSpinner_2                    matlab.ui.control.Spinner
        MaxSpinner_2Label               matlab.ui.control.Label
        MinSpinner_2                    matlab.ui.control.Spinner
        MinSpinner_2Label               matlab.ui.control.Label
        FixedValuesEditField_2          matlab.ui.control.EditField
        FixedValuesEditFieldLabel_2     matlab.ui.control.Label
        NumberofLinesSpinner_2          matlab.ui.control.Spinner
        NumberofLinesSpinner_2Label     matlab.ui.control.Label
        DistAnglesTabgroup              matlab.ui.container.TabGroup
        AnglesTopvewTab                 matlab.ui.container.Tab
        UIAxes_Derivativ                matlab.ui.control.UIAxes
        AngleatxyTab                    matlab.ui.container.Tab
        UiAxisBeta_xy                   matlab.ui.control.UIAxes
        UiAxisAlpha_xy                  matlab.ui.control.UIAxes
        DistOfAnglesTab                 matlab.ui.container.Tab
        UIAxesDistAngles                matlab.ui.control.UIAxes
        Panel_DistAngle                 matlab.ui.container.Panel
        ZDifferenceTab                  matlab.ui.container.Tab
        Opt_Contoursurf                 matlab.ui.control.Button
        ContourOptionsDropDownLabel     matlab.ui.control.Label
        Warning_NoFit_ZDiff             matlab.ui.control.Button
        TimeDifferenceEditField         matlab.ui.control.EditField
        OptionsZdiff                    matlab.ui.container.Panel
        ZValueSpinner                   matlab.ui.control.Spinner
        ZValueSpinnerLabel              matlab.ui.control.Label
        Info_Zoffset                    matlab.ui.control.Button
        AdjustreferenceplaneButton      matlab.ui.control.Button
        YSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        XYcoordinatestoevaluateatLabel  matlab.ui.control.Label
        Info_locatonEvaluate            matlab.ui.control.Button
        DisplayzDiffatxyovertimeButton  matlab.ui.control.Button
        Info_colorspaceZdiff            matlab.ui.control.Button
        Info_LevelsZDiff                matlab.ui.control.Button
        ChangetheWayhowLinesareplottedButton  matlab.ui.control.Button
        SetMaxMinvaluestobeColorizedButton  matlab.ui.control.Button
        MaxSpinner                      matlab.ui.control.Spinner
        MaxLabel                        matlab.ui.control.Label
        MinSpinner                      matlab.ui.control.Spinner
        MinLabel                        matlab.ui.control.Label
        FixedValuesEditField            matlab.ui.control.EditField
        FixedValuesEditFieldLabel       matlab.ui.control.Label
        NumberofLinesSpinner            matlab.ui.control.Spinner
        NumberofLinesSpinnerLabel       matlab.ui.control.Label
        MethodofcreatingButtonGroup     matlab.ui.container.ButtonGroup
        UseFixdValuesButton             matlab.ui.control.RadioButton
        UseNumberofLinesButton          matlab.ui.control.RadioButton
        TimeDifferenceEditFieldLabel    matlab.ui.control.Label
        ZDiffVisualizVariants           matlab.ui.container.TabGroup
        ContourplotTab                  matlab.ui.container.Tab
        UIAxes_z_diff                   matlab.ui.control.UIAxes
        ZDiffovertimeTab_2              matlab.ui.container.Tab
        UiAxes_ZDiffAtXY                matlab.ui.control.UIAxes
        distZdiff                       matlab.ui.container.Tab
        UIAxesResiduals_ZDiff           matlab.ui.control.UIAxes
        QualityofFitTab                 matlab.ui.container.Tab
        Warning_NoFit                   matlab.ui.control.Button
        DistRedOptionsDropDownLabel     matlab.ui.control.Label
        Opt_DistResd                    matlab.ui.control.Button
        Panel_DistResd                  matlab.ui.container.Panel
        SelecthistogramstoplotButtonGroup  matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        SecondOriginButton              matlab.ui.control.RadioButton
        FirstOriginButton               matlab.ui.control.RadioButton
        SSEEditField                    matlab.ui.control.NumericEditField
        SSEEditFieldLabel               matlab.ui.control.Label
        RsquaredEditField               matlab.ui.control.NumericEditField
        RsquaredEditFieldLabel          matlab.ui.control.Label
        RMSEEditField                   matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        ZDiffVisualizVariants_2         matlab.ui.container.TabGroup
        ResidualsFitplaneTab            matlab.ui.container.Tab
        UIAxesResiduals_Fit             matlab.ui.control.UIAxes
        gofvariablesoverTimeTab         matlab.ui.container.Tab
        UIAxesRSquared                  matlab.ui.control.UIAxes
        UIAxesSSE                       matlab.ui.control.UIAxes
        UIAxesRMSE                      matlab.ui.control.UIAxes
        MaxMinTab                       matlab.ui.container.Tab
        UiAxesMaxMin                    matlab.ui.control.UIAxes
        Select1FileButton               matlab.ui.control.Button
        Selected1csvFilesEditField      matlab.ui.control.EditField
        SelectedcsvFilesEditFieldLabel  matlab.ui.control.Label
        FrSpinner                       matlab.ui.control.Spinner
        ExportEvaluationDataTab         matlab.ui.container.Tab
        MinMaxzValsCheckBox             matlab.ui.control.CheckBox
        Info_dataformatExp              matlab.ui.control.Button
        Info_PointcloudsToExp           matlab.ui.control.Button
        ExportFitplaneButton            matlab.ui.control.Button
        ExportAnglesButton              matlab.ui.control.Button
        ExportZDiffButton               matlab.ui.control.Button
        PointcloudEvaluationValuestoExportButton  matlab.ui.control.Button
        ZDiffxyCheckBox                 matlab.ui.control.CheckBox
        IndexCheckBox                   matlab.ui.control.CheckBox
        ExportdependingontheOriginButtonGroup  matlab.ui.container.ButtonGroup
        OnlyfromFile2Button             matlab.ui.control.RadioButton
        OnlyfromFile1Button             matlab.ui.control.RadioButton
        ExportbothButton                matlab.ui.control.RadioButton
        Info_PrevExport                 matlab.ui.control.Button
        Info_ParamToExport              matlab.ui.control.Button
        Info_ValuesToExport             matlab.ui.control.Button
        TimestampsCheckBox              matlab.ui.control.CheckBox
        OriginofDataCheckBox            matlab.ui.control.CheckBox
        gofofFitplaneCheckBox           matlab.ui.control.CheckBox
        IncludeCollumtitelsSwitch       matlab.ui.control.Switch
        IncludeCollumtitelsSwitchLabel  matlab.ui.control.Label
        SeperatorDropDown               matlab.ui.control.DropDown
        SeperatorDropDownLabel          matlab.ui.control.Label
        PreviewofexportedDataButton     matlab.ui.control.Button
        UITableDataToSave               matlab.ui.control.Table
        SelectdataformatandExportButton  matlab.ui.control.Button
        ParametersforcsvExportButton    matlab.ui.control.Button
        ScalarEvaluationValuestoExportButton  matlab.ui.control.Button
        AlphaBetaCheckBox               matlab.ui.control.CheckBox
        SavetoxlsButton                 matlab.ui.control.StateButton
        SavetocsvButton                 matlab.ui.control.StateButton
        ProgressBar_LPS                 ProgressBar_LPS
        PouringLadleInspectLabel        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MinEditField                    matlab.ui.control.NumericEditField
        MinLabel_2                      matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        RefreshPlotsButton              matlab.ui.control.Button
        PouringLadleInspectV13102022Label  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Opt_Contoursurf_Graphics        matlab.ui.control.Button
        GraphicsOptionsLabel            matlab.ui.control.Label
        skippedEditField                matlab.ui.control.NumericEditField
        skippedFramesLabel              matlab.ui.control.Label
        ProcessedfpsGauge               matlab.ui.control.LinearGauge
        ProcessedfpsLabel               matlab.ui.control.Label
        GraphicsOptionsareaffectallplotsPanel  matlab.ui.container.Panel
        Image4                          matlab.ui.control.Image
        LocationSwitch                  matlab.ui.control.Switch
        LocationxyLabel                 matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        ApplyChangesButton              matlab.ui.control.Button
        ContinueBtnLabel                matlab.ui.control.Label
        RemoveBtnLabel                  matlab.ui.control.Label
        StartBtnLabel                   matlab.ui.control.Label
        LoadBtnLabel                    matlab.ui.control.Label
        ColorSelectorContBtn            wt.ColorSelector
        ColorSelectorLoadBtn            wt.ColorSelector
        ColorSelectorRemoveBtn          wt.ColorSelector
        ColorSelectorStartbtn           wt.ColorSelector
        EditCustomColorProfileButton    matlab.ui.control.Button
        FitobjectSpinner                matlab.ui.control.Spinner
        FitobjectSpinnerLabel           matlab.ui.control.Label
        delta_xySpinner                 matlab.ui.control.Spinner
        delta_xySpinnerLabel            matlab.ui.control.Label
        MaxMinSpinner                   matlab.ui.control.Spinner
        MaxMinSpinnerLabel              matlab.ui.control.Label
        LadleSpinner                    matlab.ui.control.Spinner
        LadleSpinnerLabel               matlab.ui.control.Label
        TransparencyMultiplyerButton    matlab.ui.control.Button
        EdgecolorLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        FacecolorLabel                  matlab.ui.control.Label
        ColorSelectorEdge               wt.ColorSelector
        ColorSelectorFace               wt.ColorSelector
        UpdateFreqSpinner               matlab.ui.control.Spinner
        UpdateFreqSpinnerLabel          matlab.ui.control.Label
        AdvancedColorTranspOptionsLabel  matlab.ui.control.Label
        Opt_AdvgraphicsOpt              matlab.ui.control.Button
        Info_AreaToDisplay              matlab.ui.control.Button
        SetOptionsforAreatoDisplayOnlyonTopviewButton  matlab.ui.control.Button
        CalculateTopviewFluid           matlab.ui.control.CheckBox
        Info_ResUpdateFreq              matlab.ui.control.Button
        Info_ColorTransp                matlab.ui.control.Button
        Info_ThingsToPlot               matlab.ui.control.Button
        FitplaneSwitch                  matlab.ui.control.Switch
        FitplaneSwitchLabel             matlab.ui.control.Label
        ChangeReselutionsUpdateFrequancysButton  matlab.ui.control.Button
        MeshsizeSpinner                 matlab.ui.control.Spinner
        MeshsizeSpinnerLabel            matlab.ui.control.Label
        delta_xyDropDown                matlab.ui.control.DropDown
        delta_xyDropDownLabel           matlab.ui.control.Label
        Pointcloud2DropDown             matlab.ui.control.DropDown
        Pointcloud2DropDownLabel        matlab.ui.control.Label
        Pointcloud1DropDown             matlab.ui.control.DropDown
        Pointcloud1DropDownLabel        matlab.ui.control.Label
        ColorMaxMinDropDown             matlab.ui.control.DropDown
        ColorMaxMinDropDownLabel        matlab.ui.control.Label
        ColorLadleDropDown              matlab.ui.control.DropDown
        ColorLadleDropDownLabel         matlab.ui.control.Label
        SkippedFrSwitch                 matlab.ui.control.Switch
        SkippedFrSwitchLabel            matlab.ui.control.Label
        FPSCounterSwitch                matlab.ui.control.Switch
        FPSCounterSwitchLabel           matlab.ui.control.Label
        TransparencySlider              matlab.ui.control.Slider
        TransparencySliderLabel         matlab.ui.control.Label
        ChangeColorTransparencyButton   matlab.ui.control.Button
        SwitchObjectsonoroffButton      matlab.ui.control.Button
        CoordSysSwitch                  matlab.ui.control.Switch
        CoordSystemLabel                matlab.ui.control.Label
        FittedLadleSwitch               matlab.ui.control.Switch
        FitplaneLabel                   matlab.ui.control.Label
        delta_xySwitch                  matlab.ui.control.Switch
        delta_xyLabel                   matlab.ui.control.Label
        MaxMinSwitch                    matlab.ui.control.Switch
        MaxMinLabel                     matlab.ui.control.Label
        UIAxesPtcld                     matlab.ui.control.UIAxes
        UIAxesCoordSystem               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

        properties (Access = private)
%TODO Feutures:
%   -!!!!!!!!!!!!!!!!!!!Add max min of current Pointcloud!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
%   -!!!!!!!!!!!!!!!!!!!!Residuals Hist for Z-Diff !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!

%   -Refresh Data of graphics insted of recreating them
%   -Parralel optimization
%       -mainly in evaluation Fcn to increase respnsivness of Ui
%   -Vectorization if possible
%   -Implement slice View for the Pointcloud
%   -Implementation of more statistics regarding zdiff (max, dist)
%   
% TODO Eventual fetuers
%   -Implementation of the option to plot values to external UiFigure
%
%TODO Visuals/ UI
%   -fix icon/ Layout of sucsess Messagebox while exporting to .csv 
%
%TODO Bugs
%   -wenn eine kellengeometrie impotiert werden soll un der Tab gewechselt wird, wird die
%   Standartkelle angezeigt und der Button plotKelle bleibt im Save Ladle Zustand
%   -Evaluation points disapear if the frameslider is used.(fitplane on)
%   -width of bars in residuals hist changes from frame to frame
%   -Refresh button dosnt plot the Fitplane
%   -if a new file gets imported while the slider is selected to Time th slider is updated as if it
%   -woud be set to time
%   -The main plot looks it self at "Topview" positon => TODO: button to set camera angle to 35,35
%   or integrate it to the coord System plot like it is done in Fusion360
%
        
%Parralel pool
        ParPool
            
%Property to link UiAxis       
        hlink

%Main Loop Control
        stopFlag

%Graphical Options
        transparency
        color_Ladle
        color_MaxMin
        color_dx_dy
        color_PtCld_1_Face
        color_PtCld_2_Face
        color_PtCld_1_Edge
        color_PtCld_2_Edge
        modeSurface
        color_Button_load
        color_Button_start
        color_button_remove
        color_button_continue
        transp_Ladle
        transp_dxdy
        transp_maxmin
        transp_fitobj
    
%Objects created from/ used to create pointclouds
        Fit_Obj_Ladle

%plot Object (for UI)
        evalpoints
        lastFrame_1
        lastFrame_2
        lastFrame_zdiff
            linspaceContourf_ZDiff %HelpVar
            linspaceContourf_Deriv
        hist_dist_1
        hist_dist_2
        hist_angle_1
        hist_angle_2
% % %         ladleTobview_zdiff
% % %         ladleTobview_gradient
        derivativAng
        fluidTopviewDiff
        fluidTopviewAng
        ladleTopviewDiff
        ladleTopviewAng

%Filepaths
        %Bag Files
        file_Bag        
        path_Bag 
        %.csv Pointcloud to be filterd
        file_Filter
        path_Filter
        %Stl of Ladle
        file_Ladle
        path_Ladle
        %Filepaths to compare Data(.csv)
        file_CSV1
        file_CSV2
        path_CSV1
        path_CSV2            

%Propertys to control behavior of Buttons (0,1)
        button_plotFirstFrame_Bag_state
        button_selectFile_bag_state
        button_select1FileButtonPushed_state
        button_select2FileButtonPushed_state
        button_startConvert_bag_pushed_state
        button_startFilter_pushed_state
        button_plotdXY_state
        button_plotSTL_pushed_state
        button_importCSV_state
        button_plottingAngles_state
        button_optAlphaBeta_state%Plot points lines or both (alpha & beta)
        fitData_state
        button_optContourf_state
        button_optContourf_state_2
        butto_graphicsOpt_state
        butto_AdvgraphicsOpt_state
        button_alphaBetaOPt_state
        button_distredOpt_state
        button_distAngleOpt_state
        button_evaluOpt_state
        button_stop_repeat_state
        
%Options for exporting Data 
        opt_seperator                

%Arrays to save & export data
        Vect_DatToSave
        Vect_Origin
        Vect_AlphaBeta
        Vect_Timestamp      
        Vect_gof
        Vect_index
        Vect_ZDiffXY
        Vect_Min_Max

%pointclouds  
        %Pointcloud extracted from the bag file
        Arr_ptClds_bag
        Arr_PtCld_bag_firstFr
        %pointclouds to be filterd       
        Arr_PtClds_unfilterd
        Arr_Ptclds_filterd
        Arr_PtClds_Backup%For Filter PtCld
        %to compare to pointclds
        Arr_PtClds_Combined 
        Arr_PtClds1        
        Arr_PtClds2      
        Arr_PtZdiff %Needs to be implemented
        Arr_AnglePtCld %Needs to be implemented
        Arr_PtCldFitpl
        %Ladle Shape and Vertices       
        Arr_ladlePtCld  %Pointcld of Ladle_TriangObj
        Arr_maxValues_Ladle

%State of plotte elements as 1=Plotted 0= not plotted        
        firstFrplotted_Bag
        firstFrplotted_Filter
        firstfrplotted_Evaluate
        ladlePlotted
        c_Zdiff
        c_Angles
        towOriginsExist
        
%Selected Frames as interval to be filterd
        start_Frame
        end_Frame
        filelength_bag
        FPS_bag
    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%{start of privat Functions}%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
    methods (Access = private)
%#################{main function to generate pointcloud/ calc. alpa & beta}############################# 
function  [vect_Origin,vect_Timestamp,vect_AlphaBeta,vect_gof,vect_ZatXY,plotCld1,plotCld2,Arr_ZDiff,Arr_PtFitplane,Arr_AnglesPt]= evaluate_ptclouds(app,combinedDat,thingsToDo,frameNumbers,diffrentOriginsExist)
            disp('  function: evaluate_ptclouds')                     
            if isempty(combinedDat) == 1 %dont execute function for empty Arr
                msgbox('Error! No Data given','Error','error')
        %read input Vars and Evaluate them=================================
            elseif isempty(combinedDat) == 0
                ArrTimestamps = combinedDat(1,:);               
                switch nargin           
                   case 3
                        startFrame_Num = 1;
                        endFrame_Num = length(ArrTimestamps)/3;
                        diffrentOriginsExist = 0;
                   case 4 
                        startFrame_Num = frameNumbers(1,1);
                        endFrame_Num = frameNumbers(1,2);
                        diffrentOriginsExist = 0;
                   case 5
                        startFrame_Num = frameNumbers(1,1);
                        endFrame_Num = frameNumbers(1,2);
                end

                %get size mxn of input combinedDat
                sizeCombDat = [size(combinedDat,1),size(combinedDat,2)];

                %get timestamps out of combined Dat
                Timestmpt = ArrTimestamps(1,1:3:end);
                Origins = ArrTimestamps(1,3:3:end);

                %Get range to calculate Progress
                range = endFrame_Num-startFrame_Num;
                if range == 0
                    range = 1;
                end

        %get what to do from UI============================================

                %read max/ min values from Ui
                xmin = app.Arr_maxValues_Ladle(1,1);                 
                xmax = app.Arr_maxValues_Ladle(1,2);
                ymin = app.Arr_maxValues_Ladle(1,3); 
                ymax = app.Arr_maxValues_Ladle(1,4);
                zmin = app.Arr_maxValues_Ladle(1,5);
                zmax = app.Arr_maxValues_Ladle(1,6);
                delta_xy = app.setdelta_xySpinner.Value; 
                Z_offset = app.ZValueSpinner.Value;

                %Location to evaluate ZDiff over Time 
                x_loc = app.XSpinner.Value;
                y_loc = app.YSpinner.Value;
                locationEv = app.setxyCoordSpinner.Value;

                %get Fittype from UI 
                fittype = app.FitSurfaceDropDown.Value;

        %Create variables============================================                                                         
                %Number of the last frame Used from ArrayData1 & 2 in combined Dat                
                lastFrame1_Num = 1;
                lastFrame2_Num = 1;
                index = 0;

                app.Vect_Min_Max = [];
                                                             
                %generates Pointcloud to compare two Pointclouds
                ptCldXYCompare = app.generatePlaneOfPoints(abs(xmax-xmin)+6,abs(ymax-ymin)+6,app.MeshsizeSpinner.Value,xmin-3,ymin-3);
                [xx,yy] = meshgrid(xmin:1:xmax,ymin:1:ymax);
                                
                %find next element with other origin (only has an effect if the frameslider is used)
                if startFrame_Num == endFrame_Num && diffrentOriginsExist == 1
                    [startFrame_Num,endFrame_Num] = app.getNigborWithDiffOiginr(startFrame_Num,Origins);
                end

                %Create empty Arrays
                framenum = endFrame_Num-startFrame_Num;
                Z1_Points = [];
                Z2_Points = [];
                Z1_Points_Filterd = [];
                Z2_Points_Filterd = [];

                %output Vectors
                vect_AlphaBeta = zeros(framenum,2);
                vect_Timestamp = zeros(framenum,1);
                vect_Origin = zeros(framenum,1);
                vect_ZatXY = zeros(framenum,1);
                vect_gof = zeros(framenum,3);  

                %Output Arrays (Pointclouds)
                Arr_ZDiff = zeros(sizeCombDat(1,1),sizeCombDat(1,2));           Arr_ZDiff(1,:) = combinedDat(1,:);                
                Arr_AnglesPt = zeros(sizeCombDat(1,1),sizeCombDat(1,2));        Arr_AnglesPt(1,:) = combinedDat(1,:);                
                Arr_PtFitplane = zeros(sizeCombDat(1,1),sizeCombDat(1,2));      Arr_PtFitplane(1,:) = combinedDat(1,:);

                %Evaluation points for calculating alpha & beta
                evalX = [-locationEv;locationEv;0;0];
                evalY = [0;0;-locationEv;locationEv];
                evalZ = [zmin+0.5*(zmax-zmin);zmin+0.5*(zmax-zmin);...
                    zmin+0.5*(zmax-zmin);zmin+0.5*(zmax-zmin)];
                                          
        %Set variables that affect the apperence of plots==================
                %Color to plot
                colorEdge_1 = app.color_PtCld_1_Edge;
                colorFace_1 = app.color_PtCld_1_Face;
                colorEdge_2 = app.color_PtCld_2_Edge;
                colorFace_2 = app.color_PtCld_2_Face;

                %Kinspace to control conturf
                linspaceZdiff = app.linspaceContourf_ZDiff;
                linspaceDeriv = app.linspaceContourf_Deriv;

                %Define Vizualization Style of the Fitplane
                mode = app.FitplaneSwitch.Value;

                %Define Linestyle for Alpha & Beta
                switch app.PlottingalpabetaButtonGroup.SelectedObject
                    case app.pointslinesButton
                        markerT = '-o';
                    case app.linesonlyButton
                        markerT = '-';
                    case app.pointsonlyButton
                        markerT = 'o';
                end

                %Plot Options Derivatis/angles of Surface
                switch app.AngleDerivButtonGroup.SelectedObject
                    case app.absolutevalueXYButton
                        derivMode = 1;   
                    case app.AlphaXDirectionButton
                        derivMode = 2;   
                    case app.BetaYDirectionButton
                        derivMode = 3;
                end 

                %Set Markerszize for Pointclods
                markZize = 5;

                %Vizual aperence of Hist
                binedgeAng = -35:35;              

        %Create Empty plot opjects=========================================
                plotCld1 = plot3(app.UIAxesPtcld,0,0,NaN);
                plotCld2 = plot3(app.UIAxesPtcld,0,0,NaN);   
                plotRMSE = plot(app.UIAxesRMSE,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plotRSquare = plot(app.UIAxesRSquared,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plotSSE = plot(app.UIAxesSSE,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plot_alpha_1 = plot(app.UiAxisAlpha,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plot_alpha_2 = plot(app.UiAxisAlpha,0,NaN,markerT,'Color',colorEdge_2,'MarkerSize',4,'MarkerFaceColor',colorFace_2);
                plot_beta_1 = plot(app.UIAxesBeta,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plot_beta_2 = plot(app.UIAxesBeta,0,NaN,markerT,'Color',colorEdge_2,'MarkerSize',4,'MarkerFaceColor',colorFace_2);
                plot_alpha_1_XY = plot(app.UiAxisAlpha_xy,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plot_alpha_2_XY = plot(app.UiAxisAlpha_xy,0,NaN,markerT,'Color',colorEdge_2,'MarkerSize',4,'MarkerFaceColor',colorFace_2);
                plot_beta_1_XY = plot(app.UiAxisBeta_xy,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plot_beta_2_XY = plot(app.UiAxisBeta_xy,0,NaN,markerT,'Color',colorEdge_2,'MarkerSize',4,'MarkerFaceColor',colorFace_2);
                plot_ZdiffXY_1 = plot(app.UiAxes_ZDiffAtXY,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plot_ZdiffXY_2 = plot(app.UiAxes_ZDiffAtXY,0,NaN,markerT,'Color',colorEdge_2,'MarkerSize',4,'MarkerFaceColor',colorFace_2);
                plot_Min = plot(app.UiAxesMaxMin,0,NaN,markerT,'Color',colorEdge_1,'MarkerSize',4,'MarkerFaceColor',colorFace_1);
                plot_Max = plot(app.UiAxesMaxMin,0,NaN,markerT,'Color',colorEdge_2,'MarkerSize',4,'MarkerFaceColor',colorFace_2);
                app.hist_angle_1 = plot3(app.UIAxesDistAngles,0,0,NaN);
                app.hist_angle_2 = plot3(app.UIAxesDistAngles,0,0,NaN);

                if app.LocationSwitch.Value == "On"
                    plotEvalP = plot3(app.UIAxesPtcld,evalX,evalY,evalZ,'o','MarkerFaceColor',"r",'Color',"r","MarkerSize",10);
                end

        % Starts timer=====================================================
                tic
        %Start of main Loop================================================
                for currFrame_Num = startFrame_Num:endFrame_Num                    
                   %Function to stop Execution while it is running
                   if app.stopFlag == 1
                       break
                   end

                   %Sets new index to adress current objects in Arrays
                   index = index + 1;

                   %Saves current origin
                   origin = Origins(1,index);
                                        
                   %filter rows equal 0
                   XYZ_Rec = app.nonzeros_for_nX3_Arrays(combinedDat(2:end,(currFrame_Num-1)*3+1:(currFrame_Num-1)*3+3));
                    
                   % Only use lines ~= 0 to minimize computetime                 
                   X = XYZ_Rec(:,1);
                   Y = XYZ_Rec(:,2);
                   Z = XYZ_Rec(:,3);
                                   
                   %Delets old plotted objects depending on origin
                   if currFrame_Num <= endFrame_Num
                       if origin == 1
                           delete(plotCld1) 
                           colorFace = colorFace_1;
                           colorEdge = colorEdge_1;
                       elseif origin == 2
                           delete(plotCld2)
                           colorFace = colorFace_2;
                           colorEdge = colorEdge_2;
                       end               
                   end                
                   
       %Select way to calculate alpha and beta===============================================================================             
                   switch thingsToDo
                       %calculate alpha and beta  with an simple algorithm
                       case 'CalculateManualy'
                           %variables to calculate angels
                           yminTot = 0;
                           ymaxTot = 0;
                           xminTot = 0;
                           xmaxTot = 0;
                           count_ymin = 0;
                           count_ymax = 0;
                           count_xmin = 0;
                           count_xmax = 0;

                           %Save min and max values
                           minVal = min(Z);
                           maxVal = max(Z);
                           app.MinEditField.Value = minVal;
                           app.MaxEditField.Value = maxVal;
                           app.Vect_Min_Max(index,1:2) = [minVal,maxVal];
                           
                           %Calculate xmin/max to calculate alpa & beta
                           for j = 1:length(X)
                               if X(j) > -delta_xy/2 && X(j) < delta_xy/2 %represents box around y-axis
                                   if Y(j) > -locationEv - delta_xy/2 && Y(j) < -locationEv + delta_xy/2 % box around y= ymin
                                       yminTot = yminTot + Z(j);% adds elements of ymin up
                                       count_ymin = count_ymin +1;% counts elements added up
                                   elseif Y(j) > locationEv-delta_xy/2 && Y(j) < locationEv + delta_xy/2% box around y= ymax
                                       ymaxTot = ymaxTot + Z(j);
                                       count_ymax = count_ymax + 1;
                                   end
                               elseif Y(j) > -delta_xy/2 && Y(j) < delta_xy/2 %represents box around x-axis
                                   if X(j)>  -locationEv - delta_xy/2 && X(j)< -locationEv + delta_xy/2 % box around x= xmin
                                       xminTot = xminTot + Z(j);
                                       count_xmin = count_xmin +1;
                                   elseif X(j)> locationEv-delta_xy/2 && X(j) <  locationEv +delta_xy/2 % box around x= xmax
                                       xmaxTot = xmaxTot+ Z(j);
                                       count_xmax = count_xmax + 1;
                                   end
                               end                        
                           end
                           
                           %Calculate Z value @points 
                           xminAv_Z = xminTot/count_xmin;
                           xmaxAv_Z = xmaxTot/count_xmax;
                           yminAv_Z = yminTot/count_ymin;
                           ymaxAv_Z = ymaxTot/count_ymax;  

                           %calculate alpa & beta                                     
                           alpha = atand((ymaxAv_Z-yminAv_Z)/(2*locationEv)); %Rot around X
                           beta = atand((xmaxAv_Z-xminAv_Z)/(2*locationEv)); %Rot around Y % xmax-xmin-delta_xy
            
                           %Do diffrent things depending on origin of Frame                  
                           if origin == 1
                               %Plot Object of plotted Data
                               plotCld1 = plot3(app.UIAxesPtcld,X(:),Y(:),Z(:),'o','MarkerFaceColor',colorFace_1,'Color',colorEdge_1,'MarkerSize',markZize);
    
                               %generates and updates lastframe (needed for ploting lines for alpha & beta)
                               lastFrame1_Num = currFrame_Num;                                   

                           elseif origin == 2   
                               %Plot Object of plotted Data
                               plotCld2 = plot3(app.UIAxesPtcld,X(:),Y(:),Z(:),'o','MarkerFaceColor',colorFace_2,'Color',colorEdge_2,'MarkerSize',markZize);
    
                               %generates and updates lastframe (needed for ploting lines for alpha & beta)
                               lastFrame2_Num = currFrame_Num;    
                           end 

       %calculate alpha and beta by using fitobjects=======================          
                       case 'FitPlane'                                                  
                           %Create Fitobject
                           [fitplane_Data, gof, output]= fit([X,Y],Z,fittype);
                           
                           %Read goodnes of fit Vars
                           rsquare = gof.rsquare;
                           rmse = gof.rmse;
                           sse = gof.sse;

                           %saves gof Variables
                           vect_gof(index,1:3) = [rsquare,sse,rmse];%Stores gof variables  

                           %if rmse is NaN set it to Zero (prevent crash)
                           if isnan(rmse) 
                                rmse = 0;
                           end                                                                            
                                                      
                           %Calculate Z value @points 
                           xminAv_Z = fitplane_Data(-locationEv,0);
                           xmaxAv_Z = fitplane_Data(locationEv,0);
                           yminAv_Z = fitplane_Data(0,-locationEv);
                           ymaxAv_Z = fitplane_Data(0,locationEv);
                           locToEval_Z = fitplane_Data(x_loc,y_loc);
                           evalZ = [xminAv_Z;xmaxAv_Z;yminAv_Z;ymaxAv_Z];
                           vect_ZatXY(index,1) = locToEval_Z+Z_offset; %saves Zatxy

                           %calculate alpa & beta from z values                                    
                           alpha = atand((ymaxAv_Z-yminAv_Z)/(2*locationEv)) ; %Rot around X
                           beta = atand((xmaxAv_Z-xminAv_Z)/(2*locationEv)); %Rot around Y
                           
                           %generate Pointcloud from the fitobject
                           Z_Points = fitplane_Data(ptCldXYCompare(:,1),ptCldXYCompare(:,2));
                           X_Dat = ptCldXYCompare(:,1);
                           Y_Dat = ptCldXYCompare(:,2);
                           Z_Dat = Z_Points;
                                         
                           %Filter X_Dat etc (only points inside the ladle remain)               
                           fitted_Z_Dat = app.Fit_Obj_Ladle(X_Dat,Y_Dat);
                           X_Filterd = X_Dat((~isnan(X_Dat)) & (Z_Dat > fitted_Z_Dat));
                           Y_Filterd = Y_Dat((~isnan(Y_Dat)) & (Z_Dat > fitted_Z_Dat));
                           Z_Filterd = Z_Dat((~isnan(Z_Dat)) & (Z_Dat > fitted_Z_Dat));
                           Z_Points_Filterd = [X_Filterd,Y_Filterd,Z_Filterd];

                           %Save min and max values
                           minVal = min(Z_Filterd);
                           maxVal = max(Z_Filterd);
                           app.MinEditField.Value = minVal;
                           app.MaxEditField.Value = maxVal;
                           app.Vect_Min_Max(index,1:2) = [minVal,maxVal];

                           %Save pointcloud in an array
                           Arr_PtFitplane(2:size(Z_Points_Filterd,1)+1,(index-1)*3+1:(index-1)*3+3) = Z_Points_Filterd;
                                                                                               
                           %Do diffrent things depending on origin of Frame           
                           if origin == 1                             
                               Z1_Points_Filterd = Z_Points_Filterd;
                               Z1_Points = Z_Points;
                               lastFrame1_Num = currFrame_Num;                                                 
                            elseif origin == 2   
                               Z2_Points_Filterd = Z_Points_Filterd; 
                               Z2_Points = Z_Points;
                               lastFrame2_Num = currFrame_Num;  
                           end    

                           %Calculate Derivativ
                           if app.CalculateDerivativSwitch.Value == "On"  || app.DistofAngleSwitch.Value == "On"
                               [derivativeX,derivativeY] = differentiate(fitplane_Data,X_Filterd,Y_Filterd);
                                derivativeTotla = sqrt(derivativeX.^2+derivativeY.^2); 

                                [derivativeX_xy,derivativeY_xy] = differentiate(fitplane_Data,app.XSpinner_2.Value,app.YSpinner_2.Value);

                                %Angle at location xy
                                alpha_XY = atand(derivativeY_xy);
                                beta_XY = atand(derivativeX_xy);

                                %Calculate Angles from Derivativ
                                derivAngX = atand(derivativeY);
                                derivAngY = atand(derivativeX);
                                derivAngTotal = atand(derivativeTotla);

                                %Save angles as Array
                                if derivMode == 1 %abs Value                                  
                                   Arr_AnglesPt(2:size(X_Filterd,1)+1,(index-1)*3+1:(index-1)*3+3) = [X_Filterd,Y_Filterd,derivAngTotal];
                                elseif derivMode == 2 %X direction (Alpha)                                   
                                   Arr_AnglesPt(2:size(X_Filterd,1)+1,(index-1)*3+1:(index-1)*3+3) = [X_Filterd,Y_Filterd,derivAngX];
                                elseif derivMode == 3 %Y direction (Beta)                                  
                                   Arr_AnglesPt(2:size(X_Filterd,1)+1,(index-1)*3+1:(index-1)*3+3) = [X_Filterd,Y_Filterd,derivAngY];
                               end
                           end  

                           %Compare against other Dataset or against reference plane
                           if app.CalculateZDiff.Value == "On" 
                               %If only file 1 is given use Reference plane
                               if isempty(Z1_Points) == 1
                                   Z1_Points = Z_offset;   %Not the best value/ place to add ?                               
                               end
                               %If only file 2  is given use Reference plane
                               if isempty(Z2_Points) == 1
                                   Z2_Points = Z_offset;  %Not the best value/ place to add ?                                
                               end

                               %Things To do for comparing 
                               DiffZ = Z1_Points - Z2_Points;
                               DiffX = ptCldXYCompare(:,1);
                               DiffY = ptCldXYCompare(:,2);

                               %Filter values to reduce Load on CPU                
                               fitted_Z_Dat = app.Fit_Obj_Ladle(ptCldXYCompare(:,1),ptCldXYCompare(:,2));
                               X_zDiff = DiffX((~isnan(DiffX)) & (DiffZ > fitted_Z_Dat));
                               Y_zDiff = DiffY((~isnan(DiffY)) & (DiffZ > fitted_Z_Dat));
                               Z_zDiff = DiffZ((~isnan(DiffZ)) & (DiffZ > fitted_Z_Dat));

                               Arr_ZDiff(2:size(X_zDiff,1)+1,(index-1)*3+1:(index-1)*3+3) = [X_zDiff,Y_zDiff,Z_zDiff];
                           end

       %End of Calculations start of vizualization=========================
                                        
                           %Visualize Pointcloud as Plane
                           if mode == "Normal"                                                                                                                                                                             
                                connectivity = delaunay(X_Filterd,Y_Filterd); 
                                if origin == 1
                                   plotCld1 = patch(app.UIAxesPtcld,'Faces',connectivity,'Vertices',Z_Points_Filterd,'FaceColor',colorFace);                                                                                    
                                elseif origin == 2
                                   plotCld2 = patch(app.UIAxesPtcld,'Faces',connectivity,'Vertices',Z_Points_Filterd,'FaceColor',colorFace);
                                end                               
                           elseif mode == "Simple"                                                            
                                if origin == 1
                                    plotCld1 = plot3(app.UIAxesPtcld,X_Filterd(:),Y_Filterd(:),Z_Filterd(:),'o','MarkerFaceColor',colorFace,'Color',colorEdge,'MarkerSize',markZize);                                                                                   
                                elseif origin == 2
                                    plotCld2 = plot3(app.UIAxesPtcld,X_Filterd(:),Y_Filterd(:),Z_Filterd(:),'o','MarkerFaceColor',colorFace,'Color',colorEdge,'MarkerSize',markZize);
                                end                                                                                                                 
                           end 

                           %Plot ZdiffXY
                           time = Timestmpt(1,currFrame_Num);%get Timestamps 
                           if origin == 1                                       
                               plot_ZdiffXY_1.XData = cat(2,plot_ZdiffXY_1.XData,time);
                               plot_ZdiffXY_1.YData = cat(2,plot_ZdiffXY_1.YData,vect_ZatXY(index,1));
                           else                                        
                               plot_ZdiffXY_2.XData = cat(2,plot_ZdiffXY_1.XData,time);
                               plot_ZdiffXY_2.YData = cat(2,plot_ZdiffXY_2.YData,vect_ZatXY(index,1));
                           end
                          
                           %Visualize Evaluation points
                           if app.LocationSwitch.Value == "On"
                               plotEvalP.ZData = evalZ(:);%plot3(app.UIAxesPtcld,evalX(:),evalY(:),evalZ(:),'o','MarkerFaceColor',"r",'color',"r",'MarkerSize',10);
                           end                                  
                               
                           %Visualize gradient/derivativ
                           if app.CalculateDerivativSwitch.Value == "On"                               
                               %Depending on derivMode diffrent parts of the derivativ are plotted
                               if derivMode == 1 %abs Value
                                   Z_Contour = griddata(X_Filterd,Y_Filterd,derivAngTotal,xx,yy);                                   
                               elseif derivMode == 2 %X direction (Alpha)
                                   Z_Contour = griddata(X_Filterd,Y_Filterd,derivAngY,xx,yy);                                  
                               elseif derivMode == 3 %Y direction (Beta)
                                   Z_Contour = griddata(X_Filterd,Y_Filterd,derivAngX,xx,yy);                                   
                               end

                               %Plot angle at given location
                               if origin == 1
                                   plot_alpha_1_XY.XData = cat(2,plot_alpha_1_XY.XData,time);%Adds new data to plot
                                   plot_alpha_1_XY.YData = cat(2,plot_alpha_1_XY.YData,alpha_XY);
                                   plot_beta_1_XY.XData = cat(2,plot_beta_1_XY.XData,time);
                                   plot_beta_1_XY.YData = cat(2,plot_beta_1_XY.YData,beta_XY);
                               else 
                                   plot_alpha_2_XY.XData = cat(2,plot_alpha_2_XY.XData,time);
                                   plot_alpha_2_XY.YData = cat(2,plot_alpha_2_XY.YData,alpha_XY);
                                   plot_beta_2_XY.XData = cat(2,plot_beta_2_XY.XData,time);
                                   plot_beta_2_XY.YData = cat(2,plot_beta_2_XY.YData,beta_XY);
                               end
                               
                               %Plot new deriv.
                               delete(app.derivativAng)
                               [~,app.derivativAng] = contourf(app.UIAxes_Derivativ,xx,yy,Z_Contour,linspaceDeriv,"ShowText",true);                                 

                           end

                           %Visualize distr. of Angles
                           if app.DistofAngleSwitch.Value == "On"
                               %Delet Hists.
                               delete(app.hist_angle_1)
                               delete(app.hist_angle_2)

                               switch app.button_plottingAngles_state                                       
                                    case 0 %'Alpha only (x-Axis)'
                                        app.hist_angle_1 = histogram(app.UIAxesDistAngles,derivAngX,'BinEdges',binedgeAng,'FaceColor', colorFace_1);                        
                                    case 1%'Beta only (y_Axis)'                                        
                                        app.hist_angle_2 = histogram(app.UIAxesDistAngles,derivAngY,'BinEdges',binedgeAng,'FaceColor', colorFace_2);                       
                                    case 2%'Alpha & Beta'                                                                                                                                                           %Plot Hists.
                                        app.hist_angle_1 = histogram(app.UIAxesDistAngles,derivAngX,'BinEdges',binedgeAng,'FaceColor', colorFace_1);
                                        app.hist_angle_2 = histogram(app.UIAxesDistAngles,derivAngY,'BinEdges',binedgeAng,'FaceColor', colorFace_2);
                                end                                                                 
                           end
                       
                           %Vizualize ZDiff
                           if app.CalculateZDiff.Value == "On" 
                               %Create grid data for conturf from scatterd data
                               Z_Contour = griddata(X_zDiff,Y_zDiff,Z_zDiff,xx,yy);                              
                                                     
                               %Only plot ZDiff on even framenumbers to iliminate Bug that contourf is not rendering for z = const
                               if mod(currFrame_Num,2) == 0 || startFrame_Num == endFrame_Num
                                   %calc things to plot
                                   z(1,1) = 1;z(end,end) = 1;%to iliminate Bug that contourf is not rendering for z = const
                                   app.TimeDifferenceEditField.Value = num2str(abs(Timestmpt(1,lastFrame1_Num)-Timestmpt(1,lastFrame2_Num))) + " [s]";
                                   %Updates Plot
                                   delete(app.lastFrame_zdiff)
                                   [~,app.lastFrame_zdiff] = contourf(app.UIAxes_z_diff,xx,yy,Z_Contour,linspaceZdiff,"ShowText",true); %,"LabelFormat","%.f [mm]"
                               end
                               cla(app.UIAxesResiduals_ZDiff)
                               histogram(app.UIAxesResiduals_ZDiff,DiffZ,'BinEdges',binedgeAng,'FaceColor', colorFace_1);
                           end  

                           %Plots residuals of Fitted Data
                           if app.DistofResidualsSwitch.Value == "On"
                               %Save and display gof variables
                               app.RsquaredEditField.Value = rsquare;
                               app.RMSEEditField.Value = rmse;
                               app.SSEEditField.Value = sse;
                               residuals = output.residuals;
                               cla(app.UIAxesResiduals_Fit)
                               histfit(app.UIAxesResiduals_Fit,residuals);

                               %Plot gof over Time
                               plotRSquare.XData = cat(2,plotRSquare.XData, time);
                               plotRSquare.YData = cat(2,plotRSquare.YData,rsquare);
                               plotRMSE.XData = cat(2,plotRMSE.XData, time);
                               plotRMSE.YData = cat(2,plotRMSE.YData,rmse);
                               plotSSE.XData = cat(2,plotSSE.XData, time);
                               plotSSE.YData = cat(2,plotSSE.YData,sse);

                           end  
      
                           %Get topview of the Fluidto mask Plots
                           if app.CalculateTopviewFluid.Value == 1
                                app.getTopviewOfFluid(Z1_Points_Filterd,Z2_Points_Filterd)
                           end  
                   end 
 %Saves Data to Array ArrData============================================     
                   vect_Origin(index,1) = combinedDat(1,(currFrame_Num-1)*3+3);               
                   vect_Timestamp(index,1) = Timestmpt(1,currFrame_Num);
                   vect_AlphaBeta(index,1:2) = [alpha beta]; %saves alpa &beta to an array (1x2) (alpha,beta)  
                                     
 %Visualization of output Vars & Update of the UI                  
                   %plots Alpha, Beta & zDiffXY                  
                   time = Timestmpt(1,currFrame_Num);%get Timestamps 
                   if origin == 1
                       plot_alpha_1.XData = cat(2,plot_alpha_1.XData,time);%Adds new data to plot
                       plot_alpha_1.YData = cat(2,plot_alpha_1.YData,alpha);
                       plot_beta_1.XData = cat(2,plot_beta_1.XData,time);
                       plot_beta_1.YData = cat(2,plot_beta_1.YData,beta);
                   else 
                       plot_alpha_2.XData = cat(2,plot_alpha_2.XData,time);
                       plot_alpha_2.YData = cat(2,plot_alpha_2.YData,alpha);
                       plot_beta_2.XData = cat(2,plot_beta_2.XData,time);
                       plot_beta_2.YData = cat(2,plot_beta_2.YData,beta);
                   end

                   plot_Min.XData = cat(2,plot_Min.XData,time);%Adds new data to plot
                   plot_Min.YData = cat(2,plot_Min.YData,minVal);
                   plot_Max.XData = cat(2,plot_Max.XData,time);%Adds new data to plot
                   plot_Max.YData = cat(2,plot_Max.YData,maxVal);

                 
                   %Update Frameslider to display current frame                                     
                   app.updateFrameslider(currFrame_Num,'Evaluate') 
                   
                   %Update Progress Bar
                   progress = abs((index/range)*100-1);
                   if progress >100
                       progress = 100;
                   end
                   app.ProgressBar_LPS.Progress = progress;

                   %Pause to refresh plots etc.
                   pause(0.00001) %1ns
                end

                %Update Ui
                app.Vect_index = transpose(1:1:(endFrame_Num+1-startFrame_Num));                
                app.RsquaredEditField.Value = 0;
                app.RMSEEditField.Value = 0;
                app.SSEEditField.Value = 0; 
%                 delete(plotEvalP)
            end       
        end 

 %###############################################{selects points inside STL}#############################        
function [pointclouds,plotCld] = convertBagToArr(app,filename,timetorec,playspeed,plot_mode)
            disp('  function convertBagToCSV')
                                                                        
            %read max/ min values 
            xmin = app.x_minSpinner.Value;                 
            xmax = app.x_maxSpinner.Value;                     
            ymin = app.y_minSpinner.Value; 
            ymax = app.y_maxSpinner.Value;
            zmin = app.z_minSpinner.Value;
            zmax = app.z_maxSpinner.Value;            
    
            fpsOfRec = app.fpsOfRecordingSpinner.Value*playspeed;
           
            % Make Config object to manage pipeline settings
            cfg = realsense.config();
                                    
            %make playback object
            validateattributes(filename, {'char','string'}, {'scalartext', 'nonempty'}, '', 'filename', 1);
            % Tell pipeline to stream from the given rosbag file
            cfg.enable_device_from_file(filename)

            % Make Pipeline object to manage streaming
            pipe = realsense.pipeline();            
            pointcloud = realsense.pointcloud();
    
            % Start streaming from the rosbag with default settings
            pipe_prof = pipe.start(cfg);
            device = pipe_prof.get_device();% uses .bag as "device"
            playback = device.as('playback');%enabels to use set playbackspeed
            playback.set_playback_speed(playspeed);%sets playbackspeed
                                                                                             
            tic;% starts timer           
            frameNumber = 0; %variable to count number of iterations            
            maxRow = 1;
            maxCollum = 0;
            pointclouds = zeros(60000,9000);
            pointcloud_firstFr = [];

            %Define Color to plot Filterd data
            color_Edge = app.color_PtCld_1_Edge;
            color_Face = app.color_PtCld_1_Face;
            timeStamp = 0;
            timeCheck = 0;
            markZize = 5;

            %creates lastFrame object
            plotCld = plot3(app.UIAxesPtcld,NaN,NaN,NaN);
                               
            % Main loop
            while timeStamp < timetorec  && app.stopFlag == 0                          
                deltaT = toc;% time since start of playback               
                frameNumber = frameNumber +1;% amount of processed frames
                
                % Obtain frames from a streaming device
                frameSet = pipe.wait_for_frames();
              
                % Select depth frame
                depth = frameSet.get_depth_frame();                
                
                % Produce pointcloud
                if (depth.logical())                                       
                   points = pointcloud.calculate(depth);
                              
                   % Adjust frame CS to matlab CS
                   vertices_Bag = points.get_vertices();                   
                   XRec = vertices_Bag(:,1,1)*1000;
                   YRec = -vertices_Bag(:,2,1)*1000;
                   ZRec = -vertices_Bag(:,3,1)*1000;
                   timeStamp_epoc = points.get_timestamp()/1000; %To Sec
                                      
                   %To enable to plot the first frame without filtering x,y
                   if frameNumber == 1 
                       X_ = XRec((ZRec > -300) & (ZRec < 10) & (XRec ~= 0));
                       Y_ = YRec((ZRec > -300) & (ZRec < 10) & (XRec ~= 0));
                       Z_ = ZRec((ZRec > -300) & (ZRec < 10) & (XRec ~= 0));
                       timeEpocStart = timeStamp_epoc;                     
                       pointcloud_firstFr = [X_,Y_,Z_];
                   end
                                                                                                
                   if frameNumber > 1%!!!!!!!!Implement framenumber-2 insted of -1 for indexing!!!!!!                       
                        if frameNumber == 2 %Save first frame now in 2.nd iter
                            X = X_((X_ > xmin)&(X_ < xmax)&(Y_ > ymin)&(Y_ < ymax)&(Z_ > zmin)&(Z_ < zmax));
                            Y = Y_((X_ > xmin)&(X_ < xmax)&(Y_ > ymin)&(Y_ < ymax)&(Z_ > zmin)&(Z_ < zmax));
                            Z = Z_((X_ > xmin)&(X_ < xmax)&(Y_ > ymin)&(Y_ < ymax)&(Z_ > zmin)&(Z_ < zmax)); 
                            pointclouds(1,1:3) = [0,0,0];
                            sizeX = size(X,1);
                            pointclouds(2:sizeX+1,1:3) = [X,Y,Z];
                        end
                        
                        %Calculate Timestamp 
                        timeStamp = timeStamp_epoc-timeEpocStart;

                        %stop while because bag playback starts to repet
                        if timeStamp < timeCheck
                            break
                        end
                       
                        %only use points inside of min and max values (square shaped area)
                        X = XRec((XRec > xmin)&(XRec < xmax)&(YRec > ymin)&(YRec < ymax)&(ZRec > zmin)&(ZRec < zmax));
                        Y = YRec((XRec > xmin)&(XRec < xmax)&(YRec > ymin)&(YRec < ymax)&(ZRec > zmin)&(ZRec < zmax));
                        Z = ZRec((XRec > xmin)&(XRec < xmax)&(YRec > ymin)&(YRec < ymax)&(ZRec > zmin)&(ZRec < zmax));  
      
                        sizeX = size(X,1);
                        %get the length of the ''longest'' pointcloud
                        if maxRow < sizeX
                            maxRow = sizeX;
                        end
        
                        %Saves data to variable pointclouds                   
                        pointclouds(2:sizeX+1,((frameNumber-2)*3+1):((frameNumber-2)*3+3)) = [X,Y,Z];  
                        %leaves the last pointcloud inside the plot
                        if timeStamp < timetorec
                               delete(plotCld);                   
                        end
                       
                        %plot pointcloud  
                        plotCld =  plot3(app.UIAxesPtcld,X(:),Y(:),Z(:),'o','MarkerFaceColor',color_Face,'Color',color_Edge,'MarkerSize',markZize);                       

                        %calculate and update values of fpsGauge
                        timeperframe = toc - deltaT; % time to process a single frame
                        fps = (1/timeperframe); %Frames per Second
                        app.ProcessedfpsGauge.Value = fps; % displays fps
                        frames_skpd =ceil(deltaT*fpsOfRec)-frameNumber; %if vewer frames are processed than recordet they are skipped
                        app.skippedEditField.Value = (abs(frames_skpd)+frames_skpd)/2; %if frames-skpd <0 it returns 0 else frames-skpd
                        pause(0.00001)

                        %update progressbar
%                         if timeStamp <= timetorec
%                             app.ProgressBar_LPS.Progress = (round(timeStamp,2)/timetorec)*100;
%                         else 
%                             app.ProgressBar_LPS.Progress = 100;
%                         end
                        
                        %Update time check
                        timeCheck = timeStamp;
        
                        %Saves pointcloud in ArrPtClds                      
                        pointclouds(1,((frameNumber-2)*3+1):((frameNumber-2)*3+3)) = [timeStamp,abs(frames_skpd),0];
                        maxCollum = (frameNumber-2)*3+3;  
                   end
                end                 
            end

            switch plot_mode
                case 'all'
                    %Adjusts length of pointclouds to avoid zero collums & rows
                    pointclouds = pointclouds(1:maxRow,1:maxCollum);
                case 'first'
                    pointclouds = pointcloud_firstFr;
            end     

            %Plots data depending on state of plot_mode
            if plot_mode == "first"                                
                plotCld = plot3(app.UIAxesPtcld,pointcloud_firstFr(:,1),pointcloud_firstFr(:,2),pointcloud_firstFr(:,3),'o','MarkerFaceColor',color_Face,'Color',color_Edge,'MarkerSize',markZize); 
            end 

            %resets fpsGauge
            app.ProcessedfpsGauge.Value = 0; %resets the displayd fps 

            %stops pipe
            pipe.stop();
        end
%######################################################{load data from csv}#############################        
        function Pointcloud = loadPtCldFromCSV(app,filepath)
            disp('  function: loadPtCldFromCSV')
            Pointcloud = readmatrix(filepath,'Delimiter',';', 'OutputType', 'double'); 
            Pointcloud = app.filterZeroCollums(Pointcloud);
        end
%##############################{plot boundry boxes (only for visualation!)}#############################        
function plot_max_min(app,plotmaxmin,plotdxy,plotXY,transpar,color1,color2) %Plots the Borders of the min and max values                       
            disp('  function plot_max_min')

            if  app.TabGroup.SelectedTab == app.ConvertbagTab
                %read max/ min values 
                xmin = app.x_minSpinner.Value;                 
                xmax = app.x_maxSpinner.Value;
                ymin = app.y_minSpinner.Value; 
                ymax = app.y_maxSpinner.Value;
                zmin = app.z_minSpinner.Value;
                zmax = app.z_maxSpinner.Value;
            else                                                                     
                %read max/ min values from Ui
                xmin = -app.setxyCoordSpinner.Value;                 
                xmax = app.setxyCoordSpinner.Value;
                ymin = -app.setxyCoordSpinner.Value; 
                ymax = app.setxyCoordSpinner.Value;
                zmin = app.Arr_maxValues_Ladle(1,5)-1;
                zmax = app.Arr_maxValues_Ladle(1,6)+1;
            end

            %set the transparency from 0 to 1
            transparency1 =transpar*app.transp_maxmin;
            transparency2 = transpar*app.transp_dxdy;

            %length of axis
            length_x = xmax-xmin;
            length_y = ymax-ymin;
            length_z = zmax-zmin;
            length_xy = app.setdelta_xySpinner.Value;
            delta = 0.001; %to eliminate an visual bug

            %length in x,y,z
            lengths = [length_x length_y length_z]; 
            lengths_xy = [length_xy length_xy length_z-delta*2];

            %vector to the cornerpoint with min values
            cp = [xmin ymin zmin]; 

            %Vector to cornerpoints
            cp_xmin = [(xmin+delta-length_xy/2)     ,(ymin+length_y/2-length_xy/2),(zmin+delta)];
            cp_xmax = [(xmax-length_xy/2-delta)     ,(ymin+length_y/2-length_xy/2),(zmin+delta)];
            cp_ymin = [(xmin+length_x/2-length_xy/2),(ymin+delta-length_xy/2)     ,(zmin+delta)];
            cp_ymax = [(xmin+length_x/2-length_xy/2),(ymax-delta-length_xy/2)     ,(zmin+delta)];            

            %defines the target axis
            axh = app.UIAxesPtcld;
           
            if plotmaxmin == "On"              
                app.plotCube(lengths,cp,transparency1,color1,axh);
            end

            if plotdxy == "On"                           
                app.plotCube(lengths_xy,cp_xmin,transparency2,color2,axh);
                app.plotCube(lengths_xy,cp_xmax,transparency2,color2,axh);
                app.plotCube(lengths_xy,cp_ymin,transparency2,color2,axh);
                app.plotCube(lengths_xy,cp_ymax,transparency2,color2,axh);
            end 

            if plotXY == "On"
                app.evalpoints = plot3(app.UIAxesPtcld,[xmin;xmax;0;0],...
                    [0;0;ymin;ymax],[zmin+0.5*(zmax-zmin);zmin+0.5*(zmax-zmin);...
                    zmin+0.5*(zmax-zmin);zmin+0.5*(zmax-zmin)],'o','MarkerFaceColor',"r",'Color',"r","MarkerSize",10);
            end
        end   
%#########################################{plots boxes to visualize things}#############################
        function h = plotCube(~,edges,origin,alpha,color,parent_Ax)
            disp('  function: plotCube')

            %Write data to new variables
            xmin = origin(1,1);
            ymin = origin(1,2);
            zmin = origin(1,3);
            xmax = (origin(1,1)+edges(1,1));
            ymax = (origin(1,2)+edges(1,2));
            zmax = (origin(1,3)+edges(1,3));

            points = [xmin,ymin,zmin;... %1
                xmin,ymin,zmax;...       %2
                xmin,ymax,zmin;...       %3
                xmin,ymax,zmax;...       %4
                xmax,ymin,zmin;...       %5
                xmax,ymin,zmax;...       %6
                xmax,ymax,zmin;...       %7
                xmax,ymax,zmax];        %8
    
            conectivity = [1,5,6,2;...
                1,5,7,3;...
                1,3,4,2;...
                2,6,8,4;...
                5,7,8,6;...
                3,7,8,4];
                        
            %plots triangulation object
            faceAlpha = alpha*0.4;
            h = patch('Faces',conectivity,'Vertices',points,'Parent',parent_Ax, ...
                'FaceAlpha',faceAlpha,'EdgeAlpha',alpha,'FaceColor',color,'LineWidth',1.2);                                
        end
%##############################################################{plot coord}#############################         
        function plotCoord_syst(app,scale,x,y,z,parent_Ax)
            disp('  function plotCoord_syst')
            switch nargin
               case 5
                   %Target Axis
                   parentAx = app.UIAxesCoordSystem;

                   %Origin of Coord System
                   x_coord = x;
                   y_coord = y;                                     
                   z_coord = z;

                   %Length of Vectors (when diffrent scales are needed)
                   xlength = 0.95;
                   ylength = 0.95;
                   zlength = 0.95;

               case 6
                   %Target Axis
                   parentAx = parent_Ax;

                   %Origin of Coord System
                   x_coord = x;
                   y_coord = y;                                     
                   z_coord = z;

                   %Length of Vectors (when diffrent scales are needed)
                   X_lim = xlim(parentAx);
                   Y_lim = ylim(parentAx);
                   Z_lim = zlim(parentAx);

                   xlength =(X_lim(1,2)-X_lim(1,1))*scale;
                   ylength = (Y_lim(1,2) -Y_lim(1,1))*scale;
                   zlength = (Z_lim(1,2)-Z_lim(1,1))*scale;
            end

            hold (parentAx,"on");

            %x-axis                                    
            text(parentAx,x_coord+xlength,y_coord,z_coord,'    X','Color','red','FontSize',16);
            app.plot_Vector(xlength,x_coord,y_coord,z_coord,0,90,0,'r',parentAx);

            %y-axis                                  
            text(parentAx,x_coord,y_coord+ylength,z_coord,'    Y','Color','green','FontSize',16);
            app.plot_Vector(ylength,x_coord,y_coord,z_coord,-90,0,0,'g',parentAx);

            %z-axis                                   
            text(parentAx,x_coord,y_coord,z_coord+zlength,'    Z','Color','blue','FontSize',16);
            app.plot_Vector(zlength,x_coord,y_coord,z_coord,0,0,0,'blue',parentAx);                                                                    
        end
%#############################################################{Plot Vector}#############################        
        function plot_Vector(~,length,xt,yt,zt,alpha,beta,gamma,color,parent)
            disp('  function: plot_Vector') 
            %number of triangels to aproximate cone shape
            n = 4;
            %create values for calculating shape of circle
            a = linspace(0,2*pi,n+1);          
            %adds an additional element to close the circle
            a(n+2) = 0;

            %Variables that define the width of the circles
            width_Cone = 0.12*length;
            width_Line = width_Cone*0.35;

            %calculate x & y coord. of the circle
            x = cos(a)*width_Cone;  
            y = sin(a)*width_Cone;
            z = ones(1,n+2)*length;

            %calculate x & y coord. of the line
            x_line = cos(a)*width_Line;
            y_line = sin(a)*width_Line;
            z_line_end = ones(1,n+2)*length;
            z_line_start = zeros(1,n+2); 

            %tip of cone
            x3 = 0;
            y3 = 0;
            z3 = length*(1+0.25);
                   
            %create rotation matrix
            R_x = [1 0 0;...
                0 cosd(alpha) -sind(alpha);...
                0 sind(alpha) cosd(alpha)];

            R_y = [cosd(beta) 0 sind(beta);...
                0 1 0;...
                -sind(beta) 0 cosd(beta)];

            R_z = [cosd(gamma) -sind(gamma) 0;...
                sind(gamma) cosd(gamma) 0;...
                0 0 1];

            %calculate rot Matrix
            Rot = R_x*R_y*R_z;

            %transpose row vectors for rotation
            x = transpose(x);
            y = transpose(y);
            z = transpose(z);
            x_line = transpose(x_line);
            y_line = transpose(y_line);
            z_line_end = transpose(z_line_end);
            z_line_start = transpose(z_line_start);
            
            %Creates vektors to be rotated in a loop
            xyz =[x,y,z];
            xyz_line_end = [x_line,y_line,z_line_end];
            xyz_line_start = [x_line,y_line,z_line_start];

            %rotates xyz & xyz_line_end & xyz_line_start
            xyz_line_end = transpose(Rot*transpose(xyz_line_end));
            xyz_line_start = transpose(Rot*transpose(xyz_line_start));
            xyz = transpose(Rot*transpose(xyz));
                     
            %rotates tip of cone
            xyz3 = [x3;y3;z3];
            xyz3 = transpose(Rot*xyz3);

            %move circle & lower & upper circle  of the line/cylinder & tip of cone in x,y,z
            x = xyz(:,1)+xt;
            y = xyz(:,2)+yt;
            z = xyz(:,3)+zt;           
            x_line_start = xyz_line_start(:,1)+xt;
            y_line_start = xyz_line_start(:,2)+yt;
            z_line_start = xyz_line_start(:,3)+zt;           
            x_line_end = xyz_line_end(:,1)+xt;
            y_line_end = xyz_line_end(:,2)+yt;
            z_line_end = xyz_line_end(:,3)+zt;                                                      
            x3 = xyz3(1,1)+xt;
            y3 = xyz3(1,2)+yt;
            z3 = xyz3(1,3)+zt;

            % plots circels        
            fill3(parent,x,y,z,color);
            fill3(parent,x_line,y_line,z_line_start,color);
            fill3(parent,x_line,y_line,z_line_end,color);
           
            %plots cone/cylinder
            for m = 2:(n+1)                                                            
                fill3(parent,[x(m-1,1),x(m,1),x3],[y(m-1,1),y(m,1),y3],[z(m-1,1),z(m,1),z3],color)%,'LineStyle','none' 
                fill3(parent,[x_line_start(m-1,1),x_line_start(m,1),x_line_end(m,1),x_line_end(m-1,1)], ...%4 elements of x form an square
                    [y_line_start(m-1,1),y_line_start(m,1),y_line_end(m,1),y_line_end(m-1,1)], ...
                    [z_line_start(m-1,1),z_line_start(m,1),z_line_end(m,1),z_line_end(m-1,1)],color)%,'LineStyle','none'              
            end            
        end
%##############################################################{plot Ladle}############################        
        function plotLadle(app,filepath,transparency,color)                               
            disp('  function: plotLadle')
                     
            %load data from .stl          
            Ladle_TriangObj =stlread(filepath); %saves stl as triangulation object
            points_New = Ladle_TriangObj.Points*1000; %triangulation objects cant be modified so a new point list is created nx3 (x,y,z) like a pointcloud
            
            %rotates the STL with euler
            R_x = [1 0 0;...
                0 cosd(app.dalphaSpinner.Value) -sind(app.dalphaSpinner.Value);...
                0 sind(app.dalphaSpinner.Value) cosd(app.dalphaSpinner.Value)];

            R_y = [cosd(app.dbetaSpinner.Value) 0 sind(app.dbetaSpinner.Value);...
                0 1 0;...
                -sind(app.dbetaSpinner.Value) 0 cosd(app.dbetaSpinner.Value)];

            R_z = [cosd(app.dgammaSpinner.Value) -sind(app.dgammaSpinner.Value) 0;...
                sind(app.dgammaSpinner.Value) cosd(app.dgammaSpinner.Value) 0;...
                0 0 1];

            %creates Rot matrix
            Rot = R_x*R_y*R_z;                
            points_New = transpose(Rot*transpose(points_New));
                         
            %applys translat changes to loaded stl
            translat = [app.dxSpinner.Value,app.dySpinner.Value,app.dzSpinner.Value];%creates translation vector                        
            conectivity = Ladle_TriangObj.ConnectivityList;%creates conectivity list 
            points_New = points_New + translat;%adds translation to vertecis

            %saves Pointcloud in app Property
            app.Arr_ladlePtCld = points_New;

            %creates new triangulation object
            Ladle_TriangObj = triangulation(conectivity,points_New);

            %plots triangulation object
            faceAlpha = transparency*app.transp_Ladle;
            trisurf(Ladle_TriangObj,'Parent',app.UIAxesPtcld,'FaceAlpha',faceAlpha,'EdgeAlpha',transparency,'FaceColor',color,'LineWidth',0.1);
                        
            %Gets topview of ladle
            topview = app.getTopViewOfLadle(points_New);
            polyshapeLadle = polyshape({[-100,-100,100,100],topview(:,1)},{[-100,100,100,-100],topview(:,2)});
            app.ladleTopviewDiff = plot(polyshapeLadle,"Parent",app.UIAxes_z_diff,"FaceColor","#FFFFFF",FaceAlpha=1,LineWidth=1.5);
            app.ladleTopviewAng = plot(polyshapeLadle,"Parent",app.UIAxes_Derivativ,"FaceColor","#FFFFFF",FaceAlpha=1,LineWidth=1.5);

            %get max & min values of the Ladle
            app.Arr_maxValues_Ladle = app.get_MaxMinOfPtCld(points_New);
           
            %saves FitObject in app Property
            app.Fit_Obj_Ladle = fit([points_New(:,1),points_New(:,2)],points_New(:,3),'linearinterp','Exclude', points_New(:,3) > app.Arr_maxValues_Ladle(1,6)-0.1); 
           
            %Plots fitobject
            switch app.FittedLadleSwitch.Value
                case 'On'               
                plot(app.Fit_Obj_Ladle,[points_New(:,1),points_New(:,2)],points_New(:,3),'Parent',app.UIAxesPtcld);
            end                                                 
        end
%##############################################{gets topvew of ladle}#########################+########
        function topview = getTopViewOfLadle(~,points_Ladle)
            disp('  function: getTopViewOfLadle')

            %create empty topview Array
            topview = zeros(size(points_Ladle,1),3);

            %set startindex to 1
            topvew_index = 1;

            %get points from points_Ladle to calculate the topview
            for i = 1:size(points_Ladle,1)
                if points_Ladle(i,3) > -5 && points_Ladle(i,3) < 5
                    topview(topvew_index,1:3) = points_Ladle(i,1:3);
                    topview(end,3) = 0;
                    topvew_index = topvew_index +1;
                end                
            end

            %Rezise topview (to filter zeros)
            topview = topview(1:topvew_index-1,1:3);

            %create an backup of topview 
            topview_Backup = topview;
            point_Start_Index = 1;%index of point1
            Dist_Points = zeros(size(topview,1),1);%All distences
            IndexList = zeros(size(topview,1),2);%Sorted list of points to use Fill Command
            indexHelp = zeros(size(topview,1),1);%Array that holds infomation wether a point was used      
            point_End_Index = 1;%point with min dist is the endpoint of the line

            for num = 1:size(topview,1)
                Point_start = topview(point_Start_Index,:);%Startpoint of the line
                indexHelp(point_Start_Index,1) = 1; 
                minDist = 128;
                %Find closesd point
                for i = 1:size(topview,1)
                    if indexHelp(i,1) == 0
                        Point_Current = topview(i,:);%x,y,z corrds of current point
                        diffX = (Point_start(1,1)-Point_Current(1,1));%x distance
                        diffY = (Point_start(1,2)-Point_Current(1,2));%y distance
                        Dist_Points(i,1) = sqrt(diffX^2+diffY^2);%Calc dist in xy plane
                        
                        %find min Value & its index
                        if minDist > Dist_Points(i,1)
                            minDist = Dist_Points(i,1);
                            point_End_Index = i;
                        end
                    else 
                        Dist_Points(i,1) = 128;%high value te exclude point
                    end
                end                                              
                IndexList(num,1:2) = [point_Start_Index,point_End_Index];                
                point_Start_Index = point_End_Index;                               
            end

            %sorts topview in the order that enables it to be plotted with patch            
            for j = 1:size(topview)
                index = IndexList(j);
                topview(j,:) = topview_Backup(index,:);
            end
                                          
        end
%##############################################{gets topvew of Fluid}#########################+########
        function getTopviewOfFluid(app,pointsFluid1,pointsFluid2)
            disp('  function: getTopviewOfFluid')

            if isempty(pointsFluid1) == 0 && isempty(pointsFluid2) == 0
                pointsFluid1(:,3) = 1;
                pointsFluid2(:,3) = 1;
                pointsFluid = intersect(pointsFluid1,pointsFluid2,"rows");
            elseif isempty(pointsFluid1) == 0 && isempty(pointsFluid2) == 1
                pointsFluid1(:,3) = 1;
                pointsFluid = pointsFluid1;
            elseif isempty(pointsFluid1) == 1 && isempty(pointsFluid2) == 0
                pointsFluid2(:,3) = 1;
                pointsFluid = pointsFluid2;
            end            
    
            pointCount = size(pointsFluid,1);
            index_x_changed_old = 1;
            currentX = pointsFluid(1,1);
            IterStep = 1;
            pointsSorted_Upper = zeros(pointCount,3);
            pointsSorted_Lower = zeros(pointCount,3);
            
            for i = 1:pointCount               
                if ~(currentX == pointsFluid(i,1))
                    index_x_changed = i-1;

                    max_Point = pointsFluid(index_x_changed,1:3);
                    min_Point = pointsFluid(index_x_changed_old,1:3);
                    currentX = pointsFluid(i,1);
                    
                    if IterStep == 1
                      pointsSorted_Lower(IterStep,1:3) = min_Point;
                      pointsSorted_Lower(IterStep,1:3) = max_Point;
                      pointsSorted_Upper(IterStep,1:3) = min_Point;
                    else
                      pointsSorted_Lower(IterStep,1:3) = max_Point;
                      pointsSorted_Upper(IterStep,1:3) = min_Point;
                    end
                    index_x_changed_old = index_x_changed+1;
                    IterStep = IterStep +1;           
                end                 
            end
            pointsSorted_Upper = pointsSorted_Upper(1:IterStep-1,:);
            pointsSorted_Lower = pointsSorted_Lower(1:IterStep-1,:);                      
            pointsSorted_Upper = flip(pointsSorted_Upper,1);

            if pointsSorted_Lower(end,:) == pointsSorted_Upper(1,:)
                pointsSorted = cat(1,pointsSorted_Lower(:,:),pointsSorted_Upper(2:end,:));               
            else
                pointsSorted = cat(1,pointsSorted_Lower(:,:),pointsSorted_Upper(:,:));
            end
        
            polyshapeFluid = polyshape({[-100,-100,100,100],pointsSorted(:,1)},{[-100,100,100,-100],pointsSorted(:,2)});
            
            delete(app.fluidTopviewDiff);
            app.fluidTopviewDiff = plot(polyshapeFluid,"Parent",app.UIAxes_z_diff,"FaceColor",[0.90,0.90,0.90],FaceAlpha=1,LineWidth=1.5); 
            uistack(app.fluidTopviewDiff,"down");

            delete(app.fluidTopviewAng);
            app.fluidTopviewAng = plot(polyshapeFluid,"Parent",app.UIAxes_Derivativ,"FaceColor",[0.90,0.90,0.90],FaceAlpha=1,LineWidth=1.5); 
            uistack(app.fluidTopviewAng,"down");
        end
%##############################################{reads options from Opt.txt}############################        
        function readOpt_bag(app,selectedOpt)
           disp('  function: readOpt_bag')

           %read all data from Opt.txt
           optionValues = readcell("Opt.txt",'Delimiter',';');

           %tests for all elements wether they are equal to selected
           for i = 1:size(optionValues,1)

               %Only use i'th option to be compared
               opt_i = optionValues(i,1);

               % if equal values out of Opt.txt are uesed as max & min               
               if strcmp(opt_i,selectedOpt) == 1 %selects option defined by selectedOpt

                    app.x_maxSpinner.Value = optionValues{i,2};
                    app.x_minSpinner.Value = optionValues{i,3};
                    app.y_maxSpinner.Value = optionValues{i,4};
                    app.y_minSpinner.Value = optionValues{i,5};                    
                    app.z_maxSpinner.Value = optionValues{i,6};
                    app.z_minSpinner.Value = optionValues{i,7};
                    app.timeOfRecordingSpinner.Value = optionValues{i,8};
                    app.fpsOfRecordingSpinner.Value = optionValues{i,9};
                    app.SetallxySpinner.Value = optionValues{i,10};
                    app.PlaybackSpeedSpinner.Value = optionValues{i,11};
                    app.setdelta_xySpinner.Value = optionValues{i,12};
               end
           end
           app.updateOptionList_Bag(); %list of objects in dropdown list get updated          
        end 
%################################################{updates some ui elements}############################                            
        function updateProcessedfpsGauge(app)
            disp('  function: updateProcessedfpsGauge')
            fps = app.fpsOfRecordingSpinner.Value*app.PlaybackSpeedSpinner.Value;
            app.ProcessedfpsGauge.Limits = [fps*0.5,fps*1.2];
            app.ProcessedfpsGauge.ScaleColorLimits = [fps*0.5 fps*0.8 ;fps*0.8 fps;fps fps*1.2];           
        end
%############################################{updates option dropdown list}############################        
        function updateOptionList_Bag(app)
            disp('  function: updateOptionList_Bag')
            optionValues = readcell("Opt.txt",'Delimiter',';');                       
            app.ProfilesDropDown.Items = optionValues(:,1);
        end                  
%##############################################{gets Max min values of stl}############################      
        function MaxMin = get_MaxMinOfPtCld(~,points)
            disp('  function: get_MaxMinOfSTL')
            %filter elements equal zero
            x = nonzeros(points(:,1));
            y = nonzeros(points(:,2));
            z = nonzeros(points(:,3));

            %the largest/ smallest elements are calculated
            x_min = min(x);
            x_max = max(x);
            y_min = min(y);
            y_max = max(y);
            z_min = min(z);
            z_max = max(z);

            %all elements are stored in one Varable
            MaxMin = [x_min, x_max, y_min, y_max, z_min,z_max];            
        end

%###########################################{creates box filld with points}############################        
        function [PointcloudDataFilterd,fitplane] = filterPtCloud(app,fitplane,PointcloudData,startFrame,endFrame)
            disp('  function: filterPtCloud')
            %prepare plot
            plot_filterd = plot3(app.UIAxesPtcld,0,0,NaN);

            %Create Variables needed to filter data
            maxPtCl_ElementsDat = size(PointcloudData,1);
            ArrTimestamps = PointcloudData(1,:);
            PointcloudDataFilterd = zeros(size(PointcloudData,1),size(PointcloudData,2));
            PointcloudDataFilterd(1,:) = ArrTimestamps;
            timestmpd_Correction = PointcloudData(1,(startFrame-1)*3+1);
            maxCollum = 1;

            %Define Color to plot Filterd data
            color_Edge = app.color_PtCld_1_Edge;
            color_Face = app.color_PtCld_1_Face;
            markZize = 5;
                       
            %loop to filter the elements of pointcloud
            for currentFrameNumber = startFrame:endFrame

                %Function to stop Execution
                if app.stopFlag == 1
                    break
                end

                app.updateFrameslider(currentFrameNumber,"StartEndFrame") ;
                app.ProgressBar_LPS.Progress = round(100*(currentFrameNumber/endFrame));

                %Load data to x,y,z variables
                XRec = PointcloudData(2:maxPtCl_ElementsDat,(currentFrameNumber-1)*3+1);
                YRec = PointcloudData(2:maxPtCl_ElementsDat,(currentFrameNumber-1)*3+2);
                ZRec = PointcloudData(2:maxPtCl_ElementsDat,(currentFrameNumber-1)*3+3);

                %Adjust timestamps to be 0 at the first frame (needed if only a part of the recording is filterd)
                oldTimestamp = PointcloudDataFilterd(1,(currentFrameNumber-1)*3+1);
                newTimestamp = oldTimestamp-timestmpd_Correction; %set first timestamp to zero
                PointcloudDataFilterd(1,(currentFrameNumber-1)*3+1) = newTimestamp;
                
                %Filters pointcloud in Z
                X_Dat = XRec((ZRec > -300) & (ZRec < -10) & (XRec ~= 0));
                Y_Dat = YRec((ZRec > -300) & (ZRec < -10) & (XRec ~= 0));
                Z_Dat = ZRec((ZRec > -300) & (ZRec < -10) & (XRec ~= 0));
               
                %Calculates new Z values @ the old location to compare Values of Z                
                fittedDat = fitplane(X_Dat,Y_Dat);

                %Filter invalid Datapoints (if value is NaN or Z value is Lower than xy point of ladle)
                X_Filterd = X_Dat((~isnan(X_Dat)) & (Z_Dat > fittedDat));
                Y_Filterd = Y_Dat((~isnan(Y_Dat)) & (Z_Dat > fittedDat));
                Z_Filterd = Z_Dat((~isnan(Z_Dat)) & (Z_Dat > fittedDat));

                %get maxCollum size
                if maxCollum < size(Z_Filterd,1)
                   maxCollum = size(Z_Filterd,1); 
                end

                %Write data to PointcloudDataFilterd
                PointcloudDataFilterd(2:size(X_Filterd,1)+1,(currentFrameNumber-1)*3+1:(currentFrameNumber-1)*3+3) = [X_Filterd,Y_Filterd,Z_Filterd];   

                %Update plot 
                delete(plot_filterd);
                plot_filterd = plot3(app.UIAxesPtcld,X_Filterd,Y_Filterd,Z_Filterd,'o','MarkerFaceColor',color_Face,'Color',color_Edge,'MarkerSize',markZize);
                
                pause(0.0001);
            end

            %Rezise PointcloudDataFilterd
            maxcollum = maxCollum+1;
            PointcloudDataFilterd = PointcloudDataFilterd(1:maxcollum,(startFrame-1)*3+1:(endFrame-1)*3+3);
            %app.StartFrameSlider.Value = startFrame;
        end
%#################################################{Selects data for Export}############################         
        function [Data,str_NameOfVars,cellArrNames] = DataToSave(app)
              disp('  function: DataToSave')
              Data = [];
              str_NameOfVars = '';
              cellArrNames = {};

              if ~(isempty(app.Vect_index)) && app.IndexCheckBox.Value == 1
                  newEnd = size(app.Vect_index,2);                  
                  Data(:,end+1:end+newEnd) = app.Vect_index;
                  str_NameOfVars ="Index" ;
                  cellArrNames{1,end+1} = "Index";
              end

              if ~(isempty(app.Vect_Origin)) && app.OriginofDataCheckBox.Value == 1
                  newEnd = size(app.Vect_Origin,2);                  
                  Data(:,end+1:end+newEnd) = app.Vect_Origin;
                  str_NameOfVars = str_NameOfVars + ";Origin" ;
                  cellArrNames{1,end+1} = "Origin";
              end

              %Save timestamps
              if ~(isempty(app.Vect_Timestamp)) && app.TimestampsCheckBox.Value == 1
                  newEnd = size(app.Vect_Timestamp,2);                  
                  Data(:,end+1:end+newEnd) = app.Vect_Timestamp;
                  str_NameOfVars = str_NameOfVars + ";Timestamps";
                  cellArrNames{1,end+1} = "Timestamps";
              end

              %Save Alpha & Beta 
              if ~(isempty(app.Vect_AlphaBeta)) && app.AlphaBetaCheckBox.Value == 1
                  newEnd = size(app.Vect_AlphaBeta,2);                  
                  Data(:,end+1:end+newEnd) = app.Vect_AlphaBeta;
                  str_NameOfVars = str_NameOfVars + ";Alpha;Beta";
                  cellArrNames{1,end+1} = "Alpha"; cellArrNames{1,end+1} = "Beta";
              end

              %Save Max & Min 
              if ~(isempty(app.Vect_Min_Max)) && app.MinMaxzValsCheckBox.Value == 1
                  newEnd = size(app.Vect_Min_Max,2);                  
                  Data(:,end+1:end+newEnd) = app.Vect_Min_Max;
                  str_NameOfVars = str_NameOfVars + ";Min;Max";
                  cellArrNames{1,end+1} = "Min"; cellArrNames{1,end+1} = "Max";
              end

              %Save Zdiff
              if ~(isempty(app.Vect_ZDiffXY)) && app.ZDiffxyCheckBox.Value == 1
                  newEnd = size(app.Vect_ZDiffXY,2);                  
                  Data(:,end+1:end+newEnd) = app.Vect_ZDiffXY;
                  str_NameOfVars = str_NameOfVars + ";DiffZ(Y"+num2str(app.XSpinner.Value)+"Y"+num2str(app.YSpinner.Value)+")";
                  cellArrNames{1,end+1} = "DiffZ(X"+num2str(app.XSpinner.Value)+"Y"+num2str(app.YSpinner.Value)+")";
              end
             
              %Save gof of residuals
              if ~(isempty(app.Vect_gof)) && app.gofofFitplaneCheckBox.Enable == "on" && app.gofofFitplaneCheckBox.Value == 1
                  newEnd = size(app.Vect_gof,2);                 
                  Data(:,end+1:end+newEnd) = app.Vect_gof;
                  str_NameOfVars = str_NameOfVars + ";R-Squared;SSE;RMSE";
                  cellArrNames{1,end+1} = "R-Squared";cellArrNames{1,end+1} ="SSE";cellArrNames{1,end+1} ="RMSE";
              end            
        end
%###########################################################{updates plots}############################        
        function updatePlots(app)
            disp('  function: updatePlots => can call: convertBagToArr & evaluate_ptclouds & plotLadle & plot_max_min & rezisePlotforPtCLD')
            %Clear Axis
            cla(app.UIAxesPtcld);            

            % if stl was plotted it is plottet again 
            if app.ladlePlotted == 1
                app.plotLadle(fullfile(app.path_Ladle,app.file_Ladle),app.transparency,app.color_Ladle)               
            end

            %plot max & min boundry box           
            plot_max_min(app,app.MaxMinSwitch.Value,app.delta_xySwitch.Value,app.LocationSwitch.Value,app.transparency,app.color_MaxMin,app.color_dx_dy);             

            %Plot first frame of bag
            if app.firstFrplotted_Bag == 1
                [app.Arr_PtCld_bag_firstFr,app.lastFrame_1] = app.convertBagToArr(fullfile(app.path_Bag,app.file_Bag),0.01,1.5,'first');
            end 

            %Plot first frame of Filter
            if app.firstFrplotted_Filter == 1
                app.lastFrame_1 = plot3(app.UIAxesPtcld,app.Arr_PtClds_unfilterd(2:end,1),app.Arr_PtClds_unfilterd(2:end,2),app.Arr_PtClds_unfilterd(2:end,3),'o','MarkerFaceColor',app.color_PtCld_1_Face,'Color',app.color_PtCld_1_Edge,'MarkerSize',5);
            end

            %plot first frame of Evaluate
            if app.firstfrplotted_Evaluate == 1
                %Frame to display defined by the Frameslider
                frameToDisplay = app.FrameSlider.Value;
                frameToDisplay = round(frameToDisplay,0);
                app.FrameSlider.Value = frameToDisplay;
                app.lastFrame_1 = plot3(app.UIAxesPtcld,app.Arr_PtClds_Combined(2:end,1),app.Arr_PtClds_Combined(2:end,2),app.Arr_PtClds_Combined(2:end,3),'o','MarkerFaceColor',app.color_PtCld_1_Face,'Color',app.color_PtCld_1_Edge,'MarkerSize',5);
            end            
        end
%###########################################################{updates plots}############################        
        function getinfo(app,filename)
            disp('  function: getinfo')
            % Make Config object to manage pipeline settings
            config = realsense.config();
                                    
            %make playback object
            validateattributes(filename, {'char','string'}, {'scalartext', 'nonempty'}, '', 'filename', 1);
            % Tell pipeline to stream from the given rosbag file
            config.enable_device_from_file(filename)

            % Make Pipeline object to manage streaming
            pipe = realsense.pipeline();            
% % %             pointcloud = realsense.pointcloud();
    
            % Start streaming from the rosbag with default settings
            pipe_profile = pipe.start(config);
            device = pipe_profile.get_device();% uses .bag as "device"
            playback = device.as('playback');%enabels to use set playbackspeed

            %Get devicename
            name = device.get_info(realsense.camera_info.name);
            activProfile = pipe.get_active_profile();
            activstream = activProfile.get_stream(realsense.stream.depth);
            fps = double(activstream.fps);
            fpsStr = num2str(fps);

            infoSerialNumber = playback.get_info(realsense.camera_info.serial_number);
% % %             x = activProfile.get_device();
% % %             %playback.seek(10) Seeks time and starts playback there.
% % %             timeStmp = playback.get_position(); %returns current time in the Frame
% % %             options = realsense.options(realsense.option.min_distance);
% % %             
% % %             %ProjectorOn = options.get_option(realsense.option.emitter_enabled);
% % %             ProjectorOn = options.get_option(realsense.option.emitter_on_off);
% % %             mindist = options.get_option(realsense.option.min_distance);
% % %             mindist = options.get_option(realsense.option.min_distance);

            firmwareVersion = device.get_info(realsense.camera_info.firmware_version);

            %Get Reselution
            frameSet = pipe.wait_for_frames();   
            depth = frameSet.get_depth_frame();                                                                               
            reselution = depth.get_width() +" by "+depth.get_height() ;
            
            %Get filelength
            fileLength_Bag = playback.get_duration;
            fileLength_Bag = seconds(fileLength_Bag);
         
            str = ["Filename and Path:      " + filename,...
                "Device Name:              " + name,...
                "Firmware version         " + firmwareVersion,...                                    
                "Duration of playback:   " + num2str(fileLength_Bag) + " [s]",...
                "Reselution:                   " + reselution + " Pixels",...
                "Fps                               " + fpsStr + "[1/s]",...                     
                "Firmware Version         " + firmwareVersion,...
                "Serial Number             " + infoSerialNumber,...
                ];

            app.filelength_bag = fileLength_Bag-0.1;
            app.FPS_bag = fps;
            app.SettingsOfTheRecordedBagFileTextArea.Value = str;
            pipe.stop();
        end
%###################################################{get max min for plots}############################              
        function maxValues = max_min_Values_of_ptClds(~,pointcloud)
            disp('  function: max_min_Values_of_ptCld')          
            n = 10;
            X_Vals = pointcloud(2:end,1:3*n:end);
            Y_Vals = pointcloud(2:end,2:3*n:end);
            Z_Vals = pointcloud(2:end,3:3*n:end);
            
            %Set all elements equal 0 to NaN (if max, min are <0 it woud cause an error if elements are 0)
            X_Vals(X_Vals == 0) = NaN;
            Y_Vals(Y_Vals == 0) = NaN;
            Z_Vals(Z_Vals == 0) = NaN;

            %get global max and min Values (min(min)) because the first one returns an vector
            minX = min(min(X_Vals));
            maxX = max(max(X_Vals));
            minY = min(min(Y_Vals));
            maxY = max(max(Y_Vals));
            minZ = min(min(Z_Vals));
            maxZ = max(max(Z_Vals));

            %write data to return variable
            maxValues = [minX,maxX,minY,maxY,minZ,maxZ];         
        end
%###########################################{combines to datasets into one}############################        
        function combinedArrDaten = combineData(~,ArrData1,ArrData2)
            disp('  function: combineData')

        %Mayby rewrite with the Approach of using Sort for sorting t after
        %adding origin to Arr1 & Arr2
            
            %Case that only the 2. Array is given OR no data is given
            if isempty(ArrData1) == 1
                %Case ArrData is empty
                if isempty(ArrData2) == 0                      
                    ArrData2(1,3:3:size(ArrData2,2)) = 2;%Adds origin of Data                    
                    combinedArrDaten = ArrData2;%Writes data to output var                                       
                end 

                %Case no Data is Given
                if isempty(ArrData2) == 1
                    combinedArrDaten = [];
                end

            %Case that only an 1. Array is given/ not empty
            elseif isempty(ArrData2) == 1
                if isempty(ArrData1) == 0                    
                    ArrData1(1,3:3:size(ArrData1,2)) = 1;%Adds origin of Data                                        
                    combinedArrDaten = ArrData1; %Writes data to output var                   
                end 
                
            %Case both Arrays contain data
            elseif isempty(ArrData1) == 0 && isempty(ArrData2) == 0
                %get first lane of Data that contains the Timestamps
                Timestamps1 = ArrData1(1,1:3:end); 
                Timestamps2 = ArrData2(1,1:3:end);    

                %Calculate Time Overlap between the tow Datasets
                timeOverlap = min([max(Timestamps1),max(Timestamps2)]);
    
                %initialize Vars. for combining Data
                frameNum = 1;
                frameNum1 = 1;
                frameNum2 = 1;
                currentTime = 0;
                isAtEnd = [0,0];

                size1 = size(ArrData1,1);
                size2 = size(ArrData2,1);
                sizeMax = max([size1,size2]);
                combinedArrDaten = zeros(sizeMax,size(Timestamps1,2)+size(Timestamps2,2));
                   
                %Combine Data
                while currentTime <= timeOverlap %%&& violationsTime <= 1
                    timeStmpd1 = Timestamps1(1,frameNum1);
                    timeStmpd2 = Timestamps2(1,frameNum2);
    
                    %Add datapoint from ArrData1
                    if timeStmpd1 <= timeStmpd2 && isAtEnd(1,1) == 0
                        ArrayToAdd = ArrData1(1:size1,(frameNum1-1)*3+1:(frameNum1-1)*3+3);
                        combinedArrDaten(1:size1,(frameNum-1)*3+1:(frameNum-1)*3+3) = ArrayToAdd;

                        %indicator wich Dataset the frame came from
                        combinedArrDaten(1,3*(frameNum-1)+3) = 1;
                                      
                        %Update Framecounter
                        if frameNum1 < size(ArrData1,2)/3
                            frameNum1 = frameNum1 +1;
                        end 
                        
                        %Update helpvar isAtEnd
                        if timeStmpd1 == timeOverlap 
                           isAtEnd(1,1) = 1;
                        end
                                          
                    %Add Data from ArrData2
                    elseif timeStmpd2 <= timeStmpd1 && isAtEnd(1,2) == 0
                        ArrayToAdd = ArrData2(1:size2,(frameNum2-1)*3+1:(frameNum2-1)*3+3);
                        combinedArrDaten(1:size2,(frameNum-1)*3+1:(frameNum-1)*3+3) = ArrayToAdd;
    
                        %indicator wich Dataset the frame came from
                        combinedArrDaten(1,3*(frameNum-1)+3) = 2;
                     
                        if frameNum2 < size(ArrData2,2)/3
                            frameNum2 = frameNum2 +1;                         
                        end 
                                              
                        if timeStmpd2 == timeOverlap 
                               isAtEnd(1,2) = 1;
                        end
                    else
                        break 
                    end              
                    %Get current timestamp for next iteration
                    currentTime = min([timeStmpd1,timeStmpd2]);
                    frameNum = frameNum + 1;
                end
            end                                
        end
%###############################################{generates plane of points}############################        
function pointcloud = generatePlaneOfPoints(~,x,y,step,xmin,ymin) 
            disp('  function: generatePlaneOfPoints')

            %size of Array
            count = round((x/step)*(y/step));
            %Empty pointcloud Array
            pointcloud = zeros(count,3);
            %index to store data in pointcloud
            index = 1;

            %Generate Plane of Points
            for i = 0:step:x %X coord
                for j = 0:step:y% y coord
                        pointcloud(index,1:3) = [i,j,1];
                        pointcloud(index,1) = pointcloud(index,1) + xmin;
                        pointcloud(index,2) = pointcloud(index,2) + ymin;

                        %If an 0 woud be saved 0.001 is added
                        if pointcloud(index,1) == 0
                            pointcloud(index,1) = pointcloud(index,1)+0.001;
                        elseif pointcloud(index,2) == 0
                            pointcloud(index,2) = pointcloud(index,2)+0.001;
                        end

                        index = index +1;
                end
            end 
        end
%############################################################{rezises plot}############################        
        function rezisePlot(app)
            disp('  function: rezisePlot')
            %if scale is not given it get set to 1          
            %Ax limits are changed if the FilterDataTab is opend
            switch app.TabGroup.SelectedTab

                %Tab Bag: limits change with firstFr plotted!
                case app.ConvertbagTab
                    if app.firstFrplotted_Bag == 1
                        %Max values defined by the Frame
                        maxValues1 = app.max_min_Values_of_ptClds(app.Arr_PtCld_bag_firstFr);

                        %Max values defined by the Boundry box
                        maxValues2 = [app.x_minSpinner.Value,app.x_maxSpinner.Value,...
                            app.y_minSpinner.Value,app.y_maxSpinner.Value,...
                            app.z_minSpinner.Value,app.z_maxSpinner.Value];

                        %get global max Values
                        maxValues = [min(maxValues1(1),maxValues2(1)),max(maxValues1(2),maxValues2(2))...
                            min(maxValues1(3),maxValues2(3)),max(maxValues1(4),maxValues2(4))...
                            min(maxValues1(5),maxValues2(5)),max(maxValues1(6),maxValues2(6))];
                    else 
                        %Max values defined by the Boundry box
                        maxValues = [app.x_minSpinner.Value,app.x_maxSpinner.Value,...
                            app.y_minSpinner.Value,app.y_maxSpinner.Value,...
                            app.z_minSpinner.Value,app.z_maxSpinner.Value];                       
                    end

                %Tab Filter: Size of axlimits is  larger than on the oter CSV Tabs                
                case app.FilterDataTab            
                    maxValues = app.get_MaxMinOfPtCld(app.Arr_ladlePtCld);
                    maxValues = app.addScalarToAxlim(maxValues,15);

                %Tab Evaluate: Size is limited by the Ladle
                case app.EvaluatePlaybackTab          
                    maxValues = app.get_MaxMinOfPtCld(app.Arr_ladlePtCld);
                    maxValues = app.addScalarToAxlim(maxValues,1);

                %Tab Export: Size is limited by the Ladle
                case app.ExportEvaluationDataTab            
                    maxValues = app.get_MaxMinOfPtCld(app.Arr_ladlePtCld);
                    maxValues = app.addScalarToAxlim(maxValues,1);                
            end
           
            %ax limits of main plot that disp ladle etc.
            app.UIAxesPtcld.XLim = [maxValues(1,1),maxValues(1,2)]; %Set limits of x-Axis                                    
            app.UIAxesPtcld.YLim = [maxValues(1,3),maxValues(1,4)]; %Set limits ofy-Axis             
            app.UIAxesPtcld.ZLim = [(maxValues(1,5)-0.1),(maxValues(1,6)+10)];%set limits of z-Axis

            %ax limits of the plot that disp z diff
            app.UIAxes_z_diff.XLim = [maxValues(1,1)-7.5,maxValues(1,2)+7.5];
            app.UIAxes_z_diff.YLim = [maxValues(1,3)-10-(maxValues(1,4)-maxValues(1,3))*0.1,maxValues(1,4)+4];

            %ax limits of the plot that disp z diff
            app.UIAxes_Derivativ.XLim = [maxValues(1,1)-7.5,maxValues(1,2)+7.5];
            app.UIAxes_Derivativ.YLim = [maxValues(1,3)-10-(maxValues(1,4)-maxValues(1,3))*0.1,maxValues(1,4)+4];
        end
%####################################################{moves the pointcloud}############################       
        function pointcloudRotated = rotatePtCld(~,pointclouds,alpha,beta,gamma,dx,dy,dz)
            disp('  function: rotatePtCld')
            
        %More Vectorisation avoid loop!!

            %define Rot matrix            
            R_x = [1 0 0;...
                0 cosd(alpha) -sind(alpha);...
                0 sind(alpha) cosd(alpha)];

            R_y = [cosd(beta) 0 sind(beta);...
                0 1 0;...
                -sind(beta) 0 cosd(beta)];

            R_z = [cosd(gamma) -sind(gamma) 0;...
                sind(gamma) cosd(gamma) 0;...
                0 0 1];

            Rot = R_x*R_y*R_z;

            %Loop to rotate & move all x,y,z triplets
            for j = 1:size(pointclouds,2)/3
                                               
                %get pointcloud (nx3) Array
                pointcloud = pointclouds(:,(j-1)*3+1:(j-1)*3+3);
                countPoints = size(nonzeros(pointcloud(:,1)),1);
                pointcloudShort = pointcloud(1:countPoints,:);
                pointcloudShort(:,1) = pointcloudShort(:,1) + dx;
                pointcloudShort(:,2) = pointcloudShort(:,2) + dy;
                pointcloudShort(:,3) = pointcloudShort(:,3) + dz;                 

                %rotates data
                pointcloudShort = transpose(Rot*transpose(pointcloudShort));
                pointclouds(1:countPoints,(j-1)*3+1:(j-1)*3+3) = pointcloudShort;                
            end  
            
            %Fill return Value with Data
            pointcloudRotated = pointclouds;            
        end
%###############################################{updates Position of PtCld}############################        
        function updatePosOfPtCld(app)
            disp('  function: updatePosOfPtCld')

            %get values to move the Pointcloud
            alpha_f = app.dalphaSpinner_Filter.Value;
            beta_f = app.dbetaSpinner_Filter.Value;
            gamma_f = app.dgammaSpinner_Filter.Value;
            dx_f = app.dxSpinner_Filter.Value;
            dy_f = app.dySpinner_Filter.Value;
            dz_f = app.dzSpinner_Filter.Value;

            app.Arr_PtClds_unfilterd(2:end,:) = app.rotatePtCld(app.Arr_PtClds_Backup(2:end,:),alpha_f,beta_f,gamma_f,dx_f,dy_f,dz_f);                                                                                    
            app.updatePlots();
        end
%###############################################{Read options of Ladle}#################################        
        function readOpt_Ladle(app,selectedOpt)
           disp('   function: readOpt_Ladle can call: app.updateOptionList_Ladle')

           %Read all options saved in Opt_Ladle.txt
           optionValues = readcell("Opt_Ladle.txt",'Delimiter',';');

           %tests for all elements wether they are equal to selected
           for i = 1:size(optionValues,1)
               %Load i'th option to compare its name
               opt_i = optionValues(i,1);

               % if equal values out of Opt.txt are uesed as max & min               
               if strcmp(opt_i,selectedOpt) == 1 %selects option defined by selectedOpt
                    app.path_Ladle = cell2mat(optionValues(i,2));
                    app.file_Ladle = cell2mat(optionValues(i,3));
                    app.dxSpinner.Value = optionValues{i,4};
                    app.dySpinner.Value = optionValues{i,5};
                    app.dzSpinner.Value = optionValues{i,6};
                    app.dalphaSpinner.Value = optionValues{i,7};
                    app.dbetaSpinner.Value =optionValues{i,8};
                    app.dgammaSpinner.Value =optionValues{i,9};

                    %If a valid file was saved then its Plotted
                    if  strcmp(app.path_Ladle,'0') == 1
                        app.ladlePlotted = 0;
                    else
                        app.ladlePlotted = 1;                        
                    end                                        
               end
           end
           app.updateOptionList_Ladle(); %list of objects in dropdown list get updated            
        end
 %###############################################{Read options List of Ladle}#################################       
        function updateOptionList_Ladle(app)
            %debug output
            disp('  function: updateOptionList_Ladle')
            optionValues = readcell("Opt_Ladle.txt",'Delimiter',';');                       
            app.LadleShapeDropDown.Items = optionValues(:,1);           
        end
%###############################################{Filter collums that contain 0}#################################        
        function ArrDataFilterd = filterZeroCollums(~,ArrData)
            disp('  function: filterZeroCollums')

            %Vectorize reintegrate in Eval Function 
            
            indexEnd = 0;
            
            for i = 1:3:(size(ArrData,2)-3)
                if  ~(ArrData(2,i) == 0 && ArrData(2,i+1) == 0 && ArrData(2,i+2) == 0)
                    indexEnd = indexEnd +1;                    
                end
            end

            ArrDataFilterd = ArrData(:,1:indexEnd*3);
        end                               
%###############################################{add Scalar value to Axlimits}#################################                
        function Axlim = addScalarToAxlim(~,Axlim_old,scalar)
            disp('  function: addScalarToAxlim')
            
            Axlim = Axlim_old;           
            %Add scalar value
            Axlim(1,1) = Axlim_old(1,1)-scalar;
            Axlim(1,2) = Axlim_old(1,2)+scalar;
            Axlim(1,3) = Axlim_old(1,3)-scalar;
            Axlim(1,4) = Axlim_old(1,4)+scalar;
            Axlim(1,5) = Axlim_old(1,5)-scalar;
            Axlim(1,6) = Axlim_old(1,6)+scalar;           
        end        
%###############################################{new version of te nonzeros function}#############################      
        function Arr_filterd = nonzeros_for_nX3_Arrays(~,Arr)

            %Vectorize and reintegrate it in Evaluate
            
            endIndex = 1;
            endclum = size(Arr,1);

            for i = 1:endclum
                if Arr(i,1) == 0 && Arr(i,2) == 0 && Arr(i,3) == 0
                    endIndex = i-1;
                    break
                end
            end

            %if no zeros exist endIndex woud be 1
            if endIndex == 1
                Arr_filterd = Arr;
            else
                Arr_filterd = Arr(1:endIndex,:);
            end                       
        end 
%###############################################{Displays data to be exported}#############################
        function updatePreviewofData(app,Data,varnames)
            app.UITableDataToSave.ColumnSortable = true;
            app.UITableDataToSave.ColumnName = varnames;
            app.UITableDataToSave.Data = Data;            
        end
%###############################################{Translate Time to Framenumber and Framenumber to Time}#############################
        function [timeOut,frameOut] = translateTimeAndFrameNum(~,timeIn,frameIn,ArrTime)
            %transpose to convert ArrTime into a collumVector
            ArrTimeCollum = transpose(ArrTime);

            if isempty(timeIn) == 1
                %Translate from frame to time
                timeOut = ArrTimeCollum(frameIn,1);
                frameOut = frameIn;

            elseif isempty(frameIn) == 1
                %Translate from time to frame
                frameOut = dsearchn(ArrTimeCollum,timeIn);
                timeOut = ArrTime(1,frameOut);                
            end            
        end
%###############################################{Translate Time to Framenumber and Framenumber to Time}#############################
        function updateFrameslider(app,FrameNum,Frameslider)
            
            switch Frameslider
                case 'Evaluate'
                    ArrTime = app.Arr_PtClds_Combined(1,1:3:end);
                    switch app.FrameOtTimeDropDown.Value
                        case 'Time'
        
                            [time,~] = app.translateTimeAndFrameNum([],FrameNum,ArrTime);
                            app.FrameSlider.Value = time;
        
                        case 'Frame'
                            app.FrameSlider.Value = FrameNum;
                    end  

                case 'StartEndFrame'
                     ArrTime = app.Arr_PtClds_unfilterd(1,1:3:end);

                     switch app.DropDownFramesliderFilter.Value
                        case 'Time'
        
                            [time,~] = app.translateTimeAndFrameNum([],FrameNum,ArrTime);
                            app.StartFrameSlider.Value = time;
        
                        case 'Frame'
                            app.StartFrameSlider.Value = FrameNum;
                     end
            end            
        end
%###############################################{updates Frameslider acording to current unit}#############################
        function framesliderUpdatelength(app)         
            switch app.FrameOtTimeDropDown.Value
                case 'Frame'
                    maxframeCount = size(app.Arr_PtClds_Combined,2)/3; 

                    %if only one Frame is stored inside of the CSV the limit must be set manualy            
                    if maxframeCount == 1
                        app.FrameSlider.Limits = [1,1.1];
                        app.FrameSlider.Value = app.FrameSlider.Limits(1);
                    else
                        app.FrameSlider.Limits = [1,maxframeCount];
                        app.FrameSlider.Value = app.FrameSlider.Limits(1);
                    end
                case 'Time'
                    if size(app.Arr_PtClds_Combined,2) == 3
                        app.FrameSlider.Limits = [app.Arr_PtClds_Combined(1,1),app.Arr_PtClds_Combined+0.1];
                        app.FrameSlider.Value = app.FrameSlider.Limits(1);
                    else
                        maxTime = app.Arr_PtClds_Combined(1,end-2);
                        maxTime = round(maxTime+0.51);
                        mintime = app.Arr_PtClds_Combined(1,1);
                        minTime = round(mintime-0.49);
                        app.FrameSlider.Limits = [minTime,maxTime];
                        app.FrameSlider.Value = app.FrameSlider.Limits(1);
                    end
            end            
        end
%###############################################{get next frame with other origin}#############################        
        function [startframeNum,endframeNum] = getNigborWithDiffOiginr(~,frameNumStartpoint,Origins)

            Origin = Origins(1,frameNumStartpoint);
            OriginIndex = zeros(1,2);
            for i = frameNumStartpoint:(-1):0
                if ~(Origin == Origins(1,i))
                    OriginIndex(1,1) = i;
                    break
                end
            end

            for j = frameNumStartpoint:1:size(Origins,2)
                if ~(Origin == Origins(1,j))
                    OriginIndex(1,2) = j;
                    break
                end
            end

            distLower = frameNumStartpoint - OriginIndex(1,1);
            distUpper = OriginIndex(1,2) - frameNumStartpoint;

            if  distLower < distUpper && ~(OriginIndex(1,1) == frameNumStartpoint)
                startframeNum = OriginIndex(1,1);
                endframeNum = frameNumStartpoint;
            elseif distLower == distUpper
                if mod(frameNumStartpoint,2) == 0
                    startframeNum = OriginIndex(1,1);
                    endframeNum = frameNumStartpoint;
                else
                    startframeNum = frameNumStartpoint;
                    endframeNum = OriginIndex(1,2);

                end
            else
                startframeNum = frameNumStartpoint;
                endframeNum = OriginIndex(1,2);
            end            
        end
%###############################################{Updates Button Color}#############################        
        function updatButtonColor(app)
            %set values for UI colors manualy Experimental
            app.color_Button_load = app.ColorSelectorLoadBtn.Value;
            app.color_Button_start = app.ColorSelectorStartbtn.Value;
            app.color_button_remove = app.ColorSelectorRemoveBtn.Value;
            app.color_button_continue = app.ColorSelectorContBtn.Value;

            %Set color for all Buttons
            %convrtTab
            app.RefreshPlotsButton.BackgroundColor = app.color_button_remove;
            app.ImportbagButton.BackgroundColor = app.color_Button_load;
            app.PlotfirstframeButton.BackgroundColor = app.color_Button_start;
            app.StartConvertButton.BackgroundColor = app.color_Button_start;
            app.ContinueButton_Bag.BackgroundColor = app.color_button_continue;
            app.SaveSettingsButton.BackgroundColor = app.color_button_continue;
            app.ResetSettingsButton.BackgroundColor = app.color_button_remove;
            app.UseSettingsOfBagButton.BackgroundColor = app.color_Button_start;
            %FilterTab
            app.ImportcsvButton.BackgroundColor = app.color_Button_load;
            app.StartFilterButton.BackgroundColor = app.color_Button_start;
            app.ContinueButton_Filter.BackgroundColor = app.color_button_continue;
            app.SelectnewLadleButton.BackgroundColor = app.color_Button_load;
            app.removeLadleButton.BackgroundColor = app.color_button_remove;
            app.plotLadleButton.BackgroundColor = app.color_Button_start;
            %Evaluatetab
            app.Select1FileButton.BackgroundColor = app.color_Button_load;
            app.Select2FileButton.BackgroundColor = app.color_Button_load;
            app.StartPlaybackButton.BackgroundColor = app.color_Button_start;
            %ExportTab
            app.SavetocsvButton.BackgroundColor = app.color_Button_load;
            app.SavetoxlsButton.BackgroundColor = app.color_Button_start;
            app.ApplyChangesButton.BackgroundColor = app.color_Button_start;
            app.ExportFitplaneButton.BackgroundColor = app.color_button_continue;
            app.ExportZDiffButton.BackgroundColor = app.color_button_continue;
            app.ExportAnglesButton.BackgroundColor = app.color_button_continue;

            app.transp_Ladle = app.LadleSpinner.Value/100;
            app.transp_dxdy = app.delta_xySpinner.Value/100;
            app.transp_maxmin = app.MaxMinSpinner.Value/100;
            app.transp_fitobj = app.FitobjectSpinner.Value/100;

            app.updatePlots            
        end        
    end                   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
	       	fprintf('\n function: startupFcn \n')
            %load options/ create opt.txt if it dosnt exist
            fileID1 = fopen('Opt.txt');
            if fileID1 == -1
                fopen('Opt.txt',"wt");
                writematrix(['Default;30;-30;30;-30;-170;-240;10;30;0.03;1;0.015;0;0;0',''],'Opt.txt');
            end

            fileID2 = fopen('Opt_Ladle.txt');
            if fileID2 == -1
                fopen('Opt_Ladle.txt',"wt");
                writematrix(['no ladle Saved;0;0;0;0;0;0;0;0',''],'Opt_Ladle')
                %Creates an Folder to store STL's
                mkdir('LadleSTL')
            end

            app.stopFlag = 0;
                       
            %Read data from Opt.txt & Opt_Ladle.txt
            app.readOpt_bag(app.ProfilesDropDown.Value);            
            app.readOpt_Ladle(app.LadleShapeDropDown.Value); %list of objects in dropdown list get updated

            %Create empty Filepaths            
            app.file_Bag = 0; app.path_Bag = 0;                   
            app.file_Filter = 0; app.path_Filter = 0;           
            app.file_Ladle = 0; app.path_Ladle = 0;            
            app.file_CSV1 = 0; app.file_CSV2 = 0;          
            app.path_CSV1 = 0; app.path_CSV2 = 0;  

            %Link Plots to display same viewing angle
            app.hlink = linkprop([app.UIAxesPtcld,app.UIAxesCoordSystem],{'View','CameraUpVector'});

            %set values for graphical options manualy
            app.transparency = 0.5;
            app.color_Ladle = [1.00,0.71,0.16];
            app.color_MaxMin = 'g';
            app.color_dx_dy = 'r';
            app.color_PtCld_1_Face = "#4DBEEE"; % blue
            app.color_PtCld_1_Edge = "#0000FF";
            app.color_PtCld_2_Face = "#ff7878"; % red
            app.color_PtCld_2_Edge = "#FF0000";
            app.updatButtonColor

            %Create Colorbar for z-Diff
            app.c_Zdiff = colorbar(app.UIAxes_z_diff,"south","FontSize",9);
            app.c_Zdiff.Label.String = 'Z-Difference [mm]';
            app.c_Zdiff.Label.FontSize = 12;
            app.c_Zdiff.Limits = app.UIAxes_z_diff.ZLim;
            caxis(app.UIAxes_z_diff,app.UIAxes_z_diff.ZLim) 
            
            %Create Colorbar for Angles
            app.c_Angles = colorbar(app.UIAxes_Derivativ,"south","FontSize",9);
            app.c_Angles.Label.String = 'Angle [°]';
            app.c_Angles.Label.FontSize = 12;
            app.c_Angles.Limits = app.UIAxes_Derivativ.ZLim;
            caxis(app.UIAxes_Derivativ,app.UIAxes_Derivativ.ZLim)
            app.towOriginsExist = 0;

            %set contourlines
            app.MethodofcreatingButtonGroupSelectionChanged                      
            app.MethodofcreatingButtonGroup_2SelectionChanged
            
            % set Ax Limits          
            app.UIAxesBeta.XLim = [0,app.timeOfRecordingSpinner.Value + 3];
            app.UiAxisAlpha.XLim = [0,app.timeOfRecordingSpinner.Value + 3];
                                                                                                                        
            %set values/ states of Buttons etc.                      
            app.fitData_state = "Off";
            app.button_plotdXY_state = "Off";
            app.StartPlaybackButton.Enable = "off";
            app.CalculateDerivativSwitch.Enable = "off";
            app.calculateGradientButton.Enable = "off";
            app.CalculateDerivativSwitchLabel.Enable = "off";            
            
            %Deactivate switches
            app.FitSurfaceDropDown.Value = 'none';
            app.DistofResidualsSwitch.Value = 'Off';
            app.DistofAngleSwitch.Value = 'Off';
            app.CalculateDerivativSwitch.Value = 'Off';

            %Set satat of Buttons (some change there usecase if pressed once)
            app.skippedEditField.Value = 0;
            app.button_selectFile_bag_state = 0;
            app.button_select1FileButtonPushed_state = 0;
            app.button_select2FileButtonPushed_state = 0;
            app.button_startConvert_bag_pushed_state = 0;
            app.button_startFilter_pushed_state = 0;            
            app.button_plotSTL_pushed_state = 0;
            app.button_importCSV_state = 0;
            app.button_plottingAngles_state = 0;
            app.button_plotFirstFrame_Bag_state = 0;
            app.button_optContourf_state = 0;
            app.button_optContourf_state_2 = 0;
            app.butto_graphicsOpt_state = 0;
            app.butto_AdvgraphicsOpt_state = 0;
            app.button_alphaBetaOPt_state = 0;
            app.button_distredOpt_state = 0;
            app.button_distAngleOpt_state = 0;
            app.button_evaluOpt_state = 0;
            app.button_stop_repeat_state = 0;

            %Options for exporting Data 
            app.opt_seperator = ';';                       

            %state of things that can be plotted
            app.firstFrplotted_Bag = 0;
            app.firstFrplotted_Filter = 0;
            app.firstfrplotted_Evaluate = 0;
            app.ladlePlotted = 0;

            %Create empty Pointclouds
            app.Arr_PtClds1 = [];
            app.Arr_PtClds2 = [];
            
            %plot the max and min values  
            hold(app.UIAxesPtcld,"on");
            hold(app.UIAxesCoordSystem,'on')
            hold(app.UiAxisAlpha,"on")
            hold(app.UIAxesBeta,"on")
            hold(app.UiAxisAlpha_xy,"on")
            hold(app.UiAxisBeta_xy,"on")
            hold(app.UIAxesResiduals_Fit,"on")
            hold(app.UIAxesDistAngles,"on")
            hold(app.UIAxes_z_diff,"on")
            hold(app.UIAxes_Derivativ,"on")
            hold(app.UiAxes_ZDiffAtXY,"on")
            hold(app.UiAxesMaxMin,"on")

            app.UiAxes_ZDiffAtXY.Visible  = "on";
            
            %Updates UI Elements
            app.TabGroupSelectionChanged
            app.plotCoord_syst(0.1,0,0,0)           
            app.updateProcessedfpsGauge
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
  fprintf('\nCallback: TabGroupSelectionChanged \n')
            selectedTab = app.TabGroup.SelectedTab;

            switch selectedTab
                case app.ConvertbagTab
                    %Clear UiAxis
                    cla(app.UIAxesPtcld)
                    %set state of buttons etc.
                    app.MaxMinSwitch.Value = 'On';
                    app.delta_xySwitch.Value = 'Off';
                    app.MaxMinSwitch.Enable = "on";
                    app.delta_xySwitch.Enable ="off";
                    app.button_plotdXY_state = 'Off';
                    app.LocationSwitch.Value = 'Off';
                    app.LocationSwitch.Enable = "off";
                    app.SkippedFrSwitch.Enable = "on";
                    app.FPSCounterSwitch.Enable = "on";
                    app.SkippedFrSwitch.Value = 'On';
                    app.FPSCounterSwitch.Value = 'On';
                    app.FPSCounterSwitchValueChanged
                    app.SkippedFrSwitchValueChanged
                    app.ladlePlotted = 0;
                    app.firstFrplotted_Filter = 0;
                    app.firstfrplotted_Evaluate = 0;
                    %Update Plots
                    app.updatePlots
                    app.rezisePlot()
                  
                case app.FilterDataTab                    
                    %Clear UiAxis
                    cla(app.UIAxesPtcld)
                    %set state of buttons etc.
                    app.MaxMinSwitch.Value = 'Off';
                    app.delta_xySwitch.Value = 'Off';
                    app.MaxMinSwitch.Enable = "off";
                    app.delta_xySwitch.Enable ="off";
                    app.button_plotdXY_state = 'Off';
                    app.LocationSwitch.Value = 'Off';
                    app.LocationSwitch.Enable = "off";
                    app.SkippedFrSwitch.Enable = "off";
                    app.FPSCounterSwitch.Enable = "off";
                    app.SkippedFrSwitch.Value = 'Off';
                    app.FPSCounterSwitch.Value = 'Off';
                    app.FPSCounterSwitchValueChanged
                    app.SkippedFrSwitchValueChanged
                    app.ladlePlotted = 1;
                    app.firstFrplotted_Bag = 0;
                    app.firstfrplotted_Evaluate = 0;
                    %Uses selected option from dropdown to load Option data of the Ladle                     
                    selectedOpt = app.LadleShapeDropDown.Value;
                    app.readOpt_Ladle(selectedOpt); 
                    app.SelectedstlEditField.Value = app.file_Ladle;
                    %Update Plots
                    app.updatePlots
                    app.rezisePlot()
                    
                case app.EvaluatePlaybackTab
                    %Clear UiAxis
                    cla(app.UIAxesPtcld)
                    %set state of buttons etc.
                    app.MaxMinSwitch.Value = 'Off';
                    app.delta_xySwitch.Value = 'On';
                    app.MaxMinSwitch.Enable = "on";
                    app.delta_xySwitch.Enable ="on";
                    app.button_plotdXY_state = 'On';
                    app.LocationSwitch.Value = 'On';
                    app.LocationSwitch.Enable = "on";
                    app.SkippedFrSwitch.Enable = "off";
                    app.FPSCounterSwitch.Enable = "off";
                    app.SkippedFrSwitch.Value = 'Off';
                    app.FPSCounterSwitch.Value = 'Off';
                    app.FPSCounterSwitchValueChanged
                    app.SkippedFrSwitchValueChanged
                    app.ladlePlotted = 1;
                    app.firstFrplotted_Bag = 0;
                    app.firstFrplotted_Filter = 0;
                    %Uses selected option from dropdown to load Option data of the Ladle                    
                    selectedOpt = app.LadleShapeDropDown.Value;
                    app.readOpt_Ladle(selectedOpt); 
                    app.SelectedstlEditField.Value = app.file_Ladle;
                    %Update Plots
                    app.FitSurfaceDropDownValueChanged%Update plot is executed by DropdownValueChanged
                    app.rezisePlot()

                case app.ExportEvaluationDataTab                   
                    %Clear UiAxis
                    cla(app.UIAxesPtcld)
                    %set state of buttons etc.
                    app.MaxMinSwitch.Value = 'Off';
                    app.delta_xySwitch.Value = 'Off';
                    app.MaxMinSwitch.Enable = "off";
                    app.delta_xySwitch.Enable ="off";
                    app.button_plotdXY_state = 'Off';
                    app.LocationSwitch.Value = 'Off';
                    app.LocationSwitch.Enable = "off";
                    app.SkippedFrSwitch.Enable = "off";
                    app.FPSCounterSwitch.Enable = "off";
                    app.SkippedFrSwitch.Value = 'Off';
                    app.FPSCounterSwitch.Value = 'Off';
                    app.FPSCounterSwitchValueChanged
                    app.SkippedFrSwitchValueChanged
                    app.ladlePlotted = 1;
                    %Uses selected option from dropdown to load Option data of the Ladle                    
                    selectedOpt = app.LadleShapeDropDown.Value;
                    app.readOpt_Ladle(selectedOpt); 
                    app.SelectedstlEditField.Value = app.file_Ladle;
                    
                    %Update Plots
                    app.updatePlots 
                    app.rezisePlot()

                    %Set checkboxes to zero
                    app.gofofFitplaneCheckBox.Value = 0;
                    app.ZDiffxyCheckBox.Value = 0;
                    if ~(app.FitSurfaceDropDown.Value == "none")
                        app.gofofFitplaneCheckBox.Enable = "on";                        
                        app.ZDiffxyCheckBox.Enable = "on";
                    else
                        app.gofofFitplaneCheckBox.Enable = "off";                        
                        app.ZDiffxyCheckBox.Enable = "off";
                    end

                    %Enables button to export ArrZdiff
                    if ~(isempty(app.Arr_PtZdiff))
                        app.ExportZDiffButton.Enable ="on";
                    else 
                        app.ExportZDiffButton.Enable ="off";
                    end

                    %Enables button to export ArrAnglespointcloud
                    if ~(isempty(app.Arr_AnglePtCld))
                        app.ExportAnglesButton.Enable ="on";
                    else 
                        app.ExportAnglesButton.Enable ="off";
                    end

                    %Enables button to export ArrFitplaneFilterd
                    if ~(isempty(app.Arr_PtCldFitpl))
                        app.ExportFitplaneButton.Enable ="on";
                    else 
                        app.ExportFitplaneButton.Enable ="off";
                    end
                   
                    %Update Table
                    [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
                    app.updatePreviewofData(app.Vect_DatToSave,cellArr)
            end                         
        end

        % Callback function
        function RefreshPlotsButtonValueChanged(app, event)
            fprintf('\nCallback: RefreshPlotsButtonValueChanged \n')
            %Clear all plots
            cla(app.UIAxesPtcld);                       
            hold (app.UIAxesPtcld,"on");
            app.updatePlots();            
            app.RefreshPlotsButton.Value = false;
        end

        % Callback function
        function GraphicsOptionsSwitchValueChanged(app, event)
            fprintf('\nCallback: ShowOptionsSwitchValueChanged \n')
            %Show or hide Options tab
            state = app.GraphicsOptionsSwitch.Value;
            switch state
                case 'On'
                    app.GraphicsOptionsareaffectallplotsPanel.Visible = "on";
                case 'Off'
                    app.GraphicsOptionsareaffectallplotsPanel.Visible = "off";
            end            
        end

        % Value changed function: MaxMinSwitch
        function MaxMinSwitchValueChanged(app, event)
            fprintf('\nCallback: MaxMinSwitchValueChanged \n')
            app.updatePlots
        end

        % Value changed function: delta_xySwitch
        function delta_xySwitchValueChanged(app, event)
            fprintf('\nCallback: delta_xySwitchValueChanged \n')
            %depending on switchstate plotdxy variable is set
            switch app.delta_xySwitch.Value
                case 'On'
                    app.button_plotdXY_state = 'On';
                case 'Off'
                    app.button_plotdXY_state = 'Off';
            end
            app.updatePlots
        end

        % Button pushed function: ImportbagButton
        function ImportbagButtonPushed(app, event)
            fprintf('\nCallback: ImportbagButtonValueChanged \n')
            %Deactivate buttons
            app.StartConvertButton.Enable = 'off';
            app.PlotfirstframeButton.Enable = 'off';
            app.ImportbagButton.Enable = "off";

            if app.button_selectFile_bag_state == 0
                %get path of bag file
                [app.file_Bag,app.path_Bag] = uigetfile('*.bag');
    
                %updates FpsGauge
                app.updateProcessedfpsGauge
    
                %Sets limits of alpha & beta plots
                app.UIAxesBeta.XLim = [0,app.timeOfRecordingSpinner.Value + 3];
                app.UiAxisAlpha.XLim = [0,app.timeOfRecordingSpinner.Value + 3];
                            
                if isequal(app.file_Bag,0) % if no file is selected
                    msgbox('User selected Cancel','Error','error');
                else  

                    %switch buttons on
                    app.StartConvertButton.Enable = 'on';
                    app.PlotfirstframeButton.Enable = 'on';
                    app.UseSettingsOfBagButton.Enable = "on";
                    app.SelectedbagFileEditField.Value = app.file_Bag; % Displays filname 
                    app.getinfo(fullfile(app.path_Bag,app.file_Bag));% gets info of bag

                    %Update Button to remove Frame State
                    app.ImportbagButton.Text = 'Clear Data';
                    app.ImportbagButton.BackgroundColor = app.color_button_remove;
                    app.button_selectFile_bag_state = 1; 
                end                

            elseif app.button_selectFile_bag_state == 1

                %Reset Variables to defult state
                app.skippedEditField.Value = 0;
                app.SelectedbagFileEditField.Value = 'no file selected!';
                app.file_Bag = 0;
                app.path_Bag = 0;
                app.firstFrplotted_Bag = 0;
                app.button_plotFirstFrame_Bag_state = 0;
                app.PlotfirstframeButton.BackgroundColor = app.color_Button_start;
                app.PlotfirstframeButton.Text = 'Plot first frame';
                app.firstFrplotted_Bag = 0;
                app.SettingsOfTheRecordedBagFileTextArea.Value = "";
    
                %update button to convert data state
                app.StartConvertButton.BackgroundColor = app.color_Button_start;
                app.StartConvertButton.Text ='Convert Data';
                app.button_startConvert_bag_pushed_state = 0;
                app.ContinueButton_Bag.Enable = 'off';
                app.ProgressBar_LPS.Progress = 0;
    
                %Update plots            
                app.updatePlots(); 
                app.rezisePlot()

                %Update Button to remove Frame State
                app.ImportbagButton.Text = 'Import .bag';
                app.ImportbagButton.BackgroundColor = app.color_Button_load;
                app.button_selectFile_bag_state = 0;

                %switch buttons off
                app.StartConvertButton.Enable = 'off';
                app.PlotfirstframeButton.Enable = 'off';  
                app.UseSettingsOfBagButton.Enable = "off";
            end
                        
            %Activates buttons/ set state
            app.ImportbagButton.Enable = "on";            
        end

        % Button pushed function: PlotfirstframeButton
        function PlotfirstframeButtonPushed(app, event)
                     fprintf('\nCallback: PlotfirstframeButtonValueChanged \n')
         %Deactivate buttons
         app.StartConvertButton.Enable = 'off';
         app.PlotfirstframeButton.Enable = 'off';
         app.ImportbagButton.Enable = "off";                       
         if app.button_plotFirstFrame_Bag_state == 0
             if isequal(app.file_Bag,0)% If no file was selected
                msgbox('no file selected','Error','error');
             else           
                 %Set states to 1 (first frame of bag plotted)
                 app.button_plotFirstFrame_Bag_state =1;
                 app.firstFrplotted_Bag = 1;
                 %plots first frame
                 [app.Arr_PtCld_bag_firstFr,app.lastFrame_1] = app.convertBagToArr(fullfile(app.path_Bag,app.file_Bag),0.01,1.5,'first');
                 %Updates button to the remove state
                 app.PlotfirstframeButton.BackgroundColor = app.color_button_remove;
                 app.PlotfirstframeButton.Text = 'Remove frame';
                 %Update plots
%                  app.updatePlots()
                 app.rezisePlot()
             end 
         else
             app.button_plotFirstFrame_Bag_state = 0;
             app.PlotfirstframeButton.BackgroundColor = app.color_Button_start;
             app.PlotfirstframeButton.Text = 'Plot first frame';
             app.firstFrplotted_Bag = 0;
             %Update plots
             app.updatePlots();
             app.rezisePlot()
         end

         %Activate Buttons
         app.StartConvertButton.Enable = 'on';
         app.PlotfirstframeButton.Enable = 'on';
         app.ImportbagButton.Enable = "on";
        end

        % Button pushed function: StartConvertButton
        function StartConvertButtonPushed(app, event)
            fprintf('\nCallback: StartConvertButtonValueChanged \n')
            %Deactivate buttons
            app.StartConvertButton.Enable = 'off';
            app.PlotfirstframeButton.Enable = 'off';
            app.ImportbagButton.Enable = "off";

            %update plots
            app.updatePlots

            if app.button_startConvert_bag_pushed_state == 0     
                                                           
                if isequal(app.file_Bag,0)
                    msgbox('no file selected','Error','error');
                else
                    %Start Converting, dispaly Progress update stopp/ repeat button
                    app.ProgressBar_LPS.Visible = "on";
                    app.StopButton.BackgroundColor = app.color_button_remove;
                    app.StopButton.Icon = "images/Stop2.png";
                    app.button_stop_repeat_state = 1;
                    app.Arr_ptClds_bag = app.convertBagToArr(fullfile(app.path_Bag,app.file_Bag),app.timeOfRecordingSpinner.Value,app.PlaybackSpeedSpinner.Value,'all');% converts data
                    app.ProgressBar_LPS.Visible = "off";
                    app.StopButton.BackgroundColor = app.color_Button_start;
                    app.StopButton.Icon = "images/Repeat.png";
                    app.button_stop_repeat_state = 0;
                    app.stopFlag = 0; 

                    %Update the Button
                    app.button_startConvert_bag_pushed_state = 1;%Sets state to 1 that means button save to csv is displayd
                    app.StartConvertButton.Text ='Save to .csv';
                    app.StartConvertButton.BackgroundColor = app.color_button_continue;
                    app.ContinueButton_Bag.Enable = 'on';
                end                                  
                
            elseif app.button_startConvert_bag_pushed_state == 1    
                
                %get file location to save Data to csv
                filenameC = uiputfile(".csv"); 

                if isequal(filenameC,0) % if no location was selected display error
                    msgbox('User selected Cancel','Error','error');                
                else                    
                    writematrix(app.Arr_ptClds_bag,filenameC,'Delimiter',';');%Saves data to selected location
                    msgbox(['file stored successfully as:',filenameC],'Succsess');%ceckbox to confirm sucsess
                    
                    %update button to convert data state
                    app.StartConvertButton.BackgroundColor = app.color_Button_start;
                    app.StartConvertButton.Text ='Convert Data';
                    app.button_startConvert_bag_pushed_state = 0;
                    app.firstFrplotted_Bag = 0;
                    app.ContinueButton_Bag.Enable = 'on';
                end                
            end
            %Activate Buttons
            app.StartConvertButton.Enable = 'on';
            app.PlotfirstframeButton.Enable = 'on';
            app.ImportbagButton.Enable = "on";
        end

        % Button pushed function: ContinueButton_Bag
        function ContinueButton_BagPushed(app, event)
            app.SelectedcsvFileEditField.Value = "converted " + app.file_Bag;
            app.TabGroup.SelectedTab = app.FilterDataTab;
            app.TabGroupSelectionChanged 
            app.Arr_PtClds_unfilterd = app.combineData(app.Arr_ptClds_bag,[]);
            app.Arr_PtClds_Backup = app.Arr_PtClds_unfilterd;
            maxValues = app.max_min_Values_of_ptClds(app.Arr_PtClds_unfilterd);

            %Relocate PtCld to fit Ladle automaticly (manual changes are still needed!)                 
            app.dxSpinner_Filter.Value = round(-(maxValues(1,1)+(maxValues(1,2)-maxValues(1,1))/2));%X                
            app.dySpinner_Filter.Value = round(-(maxValues(1,3)+(maxValues(1,4)-maxValues(1,3))/2));%Y              
            app.dzSpinner_Filter.Value = -maxValues(1,6);%Z  

            %Update Positon
            app.updatePosOfPtCld(); % uses Pointcloud backup to generate new pointcloud Unfilterd

            %Activates spinners to change position of the Pointcloud
            app.dxSpinner_Filter.Enable = "on";
            app.dySpinner_Filter.Enable = "on";
            app.dzSpinner_Filter.Enable = "on";
            app.dalphaSpinner_Filter.Enable = "on";
            app.dbetaSpinner_Filter.Enable = "on";
            app.dgammaSpinner_Filter.Enable = "on";

            %Activates Buttons
            app.StartFilterButton.Enable = "on";
            app.StartFrameSlider.Enable = "on";
            app.StartFrSpinner.Enable = "on";
            app.EndFrSpinner.Enable ="on";
            app.StartFrameSliderLabel.Enable = "on";
            app.EndFrameSliderLabel.Enable = "on";
            app.EndFrameSlider.Enable = "on";
            app.DropDownFramesliderFilter.Value = 'Frame';
            app.DropDownFramesliderFilter.Enable = "on";
            app.DropDownFramesliderFilterValueChanged();
            
            %update ImportCsvButton to clear data Button
            app.button_importCSV_state = 1;
            app.ImportcsvButton.Text = 'Clear Data';
            app.ImportcsvButton.BackgroundColor = app.color_button_remove;

            if isempty(app.Arr_PtClds_unfilterd) == 0
            
                framenumber = size(app.Arr_PtClds_unfilterd,2)/3;
                %if only one Frame is stored inside of the CSV the limit must be set manualy            
                if framenumber == 1
                    app.StartFrameSlider.Limits = [1,1.1];
                    app.EndFrameSlider.Limits = [1,1.1];
                    app.FrameSlider.Limits = [1,1.1];
                else
                    app.StartFrameSlider.Limits = [1,framenumber];
                    app.EndFrameSlider.Limits = [1,framenumber];
                    app.FrameSlider.Limits = [1,framenumber];
                    app.EndFrameSlider.Value = framenumber;
                end

                app.start_Frame = 1;
                app.end_Frame = app.EndFrameSlider.Value;

                %Update plots
                app.firstFrplotted_Filter = 1;
                app.updatePosOfPtCld();% uses Pointcloud backup to generate new pointcloud Unfilterd
            end  
        end

        % Value changed function: z_maxSpinner
        function z_maxSpinnerValueChanged(app, event)
            fprintf('\nCallback: z_maxSpinnerValueChanged \n')            
            app.updatePlots();
            app.rezisePlot();
        end

        % Value changed function: z_minSpinner
        function z_minSpinnerValueChanged(app, event)
            fprintf('\nCallback: z_minSpinnerValueChanged \n')            
            app.updatePlots();
            app.rezisePlot();
        end

        % Value changed function: y_maxSpinner
        function y_maxSpinnerValueChanged(app, event)
            fprintf('\nCallback: y_maxSpinnerValueChanged \n')
            app.updatePlots();
            app.rezisePlot();
        end

        % Value changed function: y_minSpinner
        function y_minSpinnerValueChanged(app, event)
            fprintf('\nCallback: y_minSpinnerValueChanged \n')
            app.updatePlots();  
            app.rezisePlot();
        end

        % Value changed function: x_maxSpinner
        function x_maxSpinnerValueChanged(app, event)
            fprintf('\nCallback: x_maxSpinnerValueChanged \n')
            app.updatePlots();
            app.rezisePlot();
        end

        % Value changed function: x_minSpinner
        function x_minSpinnerValueChanged(app, event)
            fprintf('\nCallback: x_minSpinnerValueChanged \n')
            app.updatePlots(); 
            app.rezisePlot();
        end

        % Value changed function: SetallxySpinner
        function SetallxySpinnerValueChanged(app, event)
            fprintf('\nCallback: SetallxySpinnerValueChanged \n')
            %Sets all max & min values to value
            value = app.SetallxySpinner.Value;
            app.x_minSpinner.Value = -value;
            app.x_maxSpinner.Value = value;
            app.y_minSpinner.Value = -value;
            app.y_maxSpinner.Value = value;

            %Updates plots to display new Values
            app.updatePlots(); 
            app.rezisePlot();
        end

        % Value changed function: setdelta_xySpinner
        function setdelta_xySpinnerValueChanged(app, event)
            fprintf('\nCallback: delta_xySpinnerValueChanged \n')
            app.updatePlots(); 
        end

        % Value changed function: PlaybackSpeedSpinner
        function PlaybackSpeedSpinnerValueChanged(app, event)
            fprintf('\nCallback: PlaybackSpeedSpinnerValueChanged \n')
            app.updateProcessedfpsGauge            
        end

        % Value changed function: fpsOfRecordingSpinner
        function fpsOfRecordingSpinnerValueChanged(app, event)
            fprintf('\nCallback: fpsOfRecordingSpinnerValueChanged \n')
            app.updateProcessedfpsGauge            
        end

        % Value changed function: timeOfRecordingSpinner
        function timeOfRecordingSpinnerValueChanged(app, event)
            fprintf('\nCallback: timeOfRecordingSpinnerValueChanged \n')
            %Updates limits of x-axis of the alpha & beta plots
            app.UIAxesBeta.XLim = [0,app.timeOfRecordingSpinner.Value + 3];
            app.UiAxisAlpha.XLim = [0,app.timeOfRecordingSpinner.Value + 3];            
        end

        % Value changed function: ProfilesDropDown
        function ProfilesDropDownValueChanged(app, event)
          fprintf('\nCallback: ProfilesDropDownValueChanged \n')
          %Uses selected option from dropdown to load Option data
          selectedOpt = app.ProfilesDropDown.Value;
          app.readOpt_bag(selectedOpt);
          app.updatePlots();
          app.rezisePlot()
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            fprintf('\nCallback: SaveSettingsButtonValueChanged \n')
            %get name for options
            titel= 'Save Settings'; % gets displayd in inputdgl            
            optionName= inputdlg(titel,'',[1 35],{'Profile_1'});

            %bool values to deside what to do
            bool = isempty(optionName);
            bool2 = 0;

            %max min Values
            x_max = app.x_maxSpinner.Value;                             %1
            x_min = app.x_minSpinner.Value;                             %2
            y_max = app.y_maxSpinner.Value;                             %3
            y_min = app.y_minSpinner.Value;                             %4            
            z_max = app.z_maxSpinner.Value;                             %5
            z_min = app.z_minSpinner.Value;                             %6
            
            %Playback options .Bag
            timeofrecording = app.timeOfRecordingSpinner.Value;         %7
            fpsofrecording = app.fpsOfRecordingSpinner.Value;           %8
            Setallxy = app.SetallxySpinner.Value;                       %9
            playbackspeed = app.PlaybackSpeedSpinner.Value;             %10
            delta = app.setdelta_xySpinner.Value;                       %11
            
            %creates an cellArray with the profile name and the min & max          
            optionValue =[optionName,...
                x_max,...
                x_min,...             
                y_max,...
                y_min,...
                z_max,...
                z_min,...
                timeofrecording,...
                fpsofrecording,...
                Setallxy,...
                playbackspeed,...
                delta];
                            
            %if the user selected a profile name its saved            
            if bool == 1% Case no name was selected
                msgbox('User selected Cancel','Error','error');          
            else % case an name was selected
                optionValues = readcell("Opt.txt",'Delimiter',';');%reads document with stored options
                for i = 1:size(optionValues,1)%cheks if selected name is in use

                   opt_i = optionValues(i,1); %Name of the i'th option 
              
                   %Check wether name alredy exist
                   if strcmp(opt_i,optionName) == 1 %selects option defined by selectedOpt StringCompare
                       msgbox([optionName{1},' alredy exist  select an other name'])
                       bool2 = 1;%if name alredy exists then nothing is saved                                                               
                   end                    
                end

                if bool2 == 0 % if name isent in use then save
                    writecell(optionValue,'Opt.txt','Delimiter',';','WriteMode','append');%saves the Profile
                end
                %update dopdown list
                app.updateOptionList_Bag(); 
                app.ProfilesDropDown.Value = optionName;
            end
        end

        % Button pushed function: ResetSettingsButton
        function ResetSettingsButtonPushed(app, event)
           fprintf('\nCallback: ResetSettingsButtonValueChanged \n')
           %restore defult state defined by the selected option
           selectedOpt = app.ProfilesDropDown.Value;
           app.readOpt_bag(selectedOpt);                                 
           
           %Refresh plots/ button state
           app.updatePlots();  
        end

        % Button pushed function: ImportcsvButton
        function ImportcsvButtonPushed(app, event)
            fprintf('\nCallback: ImportcsvButtonValueChanged \n')
            %Deactivates buttons
            app.ImportcsvButton.Enable = "off";
            app.StartFilterButton.Enable = "off";

            %Import data state
            if app.button_importCSV_state == 0
                %Get filepath of csv to filter
                [app.file_Filter,app.path_Filter] = uigetfile('*.csv');            
                app.updateProcessedfpsGauge
                            
                if isequal(app.file_Filter,0)
                    msgbox('User selected Cancel','Error','error');
                else                                                         
                    %Load and convert ptcld                                        
                    app.SelectedcsvFileEditField.Value = app.file_Filter; 
                    app.Arr_PtClds_unfilterd = app.loadPtCldFromCSV(fullfile(app.path_Filter,app.file_Filter));  
                    app.Arr_PtClds_unfilterd = app.combineData(app.Arr_PtClds_unfilterd,[]);
                    app.Arr_PtClds_Backup = app.Arr_PtClds_unfilterd;
                    maxValues = app.max_min_Values_of_ptClds(app.Arr_PtClds_unfilterd);
    
                    %Relocate PtCld to fit Ladle automaticly (manual changes are still needed!)                 
                    app.dxSpinner_Filter.Value = round(-(maxValues(1,1)+(maxValues(1,2)-maxValues(1,1))/2));%X                
                    app.dySpinner_Filter.Value = round(-(maxValues(1,3)+(maxValues(1,4)-maxValues(1,3))/2));%Y              
                    app.dzSpinner_Filter.Value = -maxValues(1,6);%Z  

                    %Update Positon
                    app.updatePosOfPtCld(); % uses Pointcloud backup to generate new pointcloud Unfilterd
    
                    %Activates spinners to change position of the Pointcloud
                    app.dxSpinner_Filter.Enable = "on";
                    app.dySpinner_Filter.Enable = "on";
                    app.dzSpinner_Filter.Enable = "on";
                    app.dalphaSpinner_Filter.Enable = "on";
                    app.dbetaSpinner_Filter.Enable = "on";
                    app.dgammaSpinner_Filter.Enable = "on";
    
                    %Activates Buttons
                    app.StartFilterButton.Enable = "on";
                    app.StartFrSpinner.Enable = "on";
                    app.EndFrSpinner.Enable ="on";
                    app.StartFrameSliderLabel.Enable = "on";
                    app.EndFrameSliderLabel.Enable = "on";
                    app.StartFrameSlider.Enable = "on";
                    app.EndFrameSlider.Enable = "on";
                    app.DropDownFramesliderFilter.Enable = "on";
                    
                    %update ImportCsvButton to clear data Button
                    app.button_importCSV_state = 1;
                    app.ImportcsvButton.Text = 'Clear Data';
                    app.ImportcsvButton.BackgroundColor = app.color_button_remove;
    
                    if isempty(app.Arr_PtClds_unfilterd) == 0
                    
                        framenumber = size(app.Arr_PtClds_unfilterd,2)/3;
                        %if only one Frame is stored inside of the CSV the limit must be set manualy            
                        if framenumber == 1
                            app.StartFrameSlider.Limits = [1,1.1];
                            app.EndFrameSlider.Limits = [1,1.1];
                            app.FrameSlider.Limits = [1,1.1];
                            app.StartFrSpinner.Limits = [1,1.1];
                            app.EndFrSpinner.Limits = [1,1.1];
                            app.FrSpinner.Limits = [1,1.1];
                        else
                            app.StartFrameSlider.Limits = [1,framenumber];
                            app.EndFrameSlider.Limits = [1,framenumber];
                            app.FrameSlider.Limits = [1,framenumber];
                            app.StartFrSpinner.Limits = [1,framenumber];
                            app.EndFrSpinner.Limits = [1,framenumber];
                            app.FrSpinner.Limits = [1,framenumber];
                            app.EndFrameSlider.Value = framenumber;
                            app.EndFrSpinner.Value = framenumber;
                        end

                        app.start_Frame = 1;
                        app.end_Frame = app.EndFrameSlider.Value;

                        %Update plots
                        app.firstFrplotted_Filter = 1;
                        app.updatePosOfPtCld();% uses Pointcloud backup to generate new pointcloud Unfilterd
                    end                               
                end

            %Clear data State
            elseif app.button_importCSV_state == 1
                app.skippedEditField.Value = 0;
                app.SelectedcsvFileEditField.Value = 'no file selected!';
                
                %removes the filepath & Data
                app.file_Filter = 0;
                app.path_Filter = 0;
                app.Arr_Ptclds_filterd = [];
    
                %plot the max and min values 
                app.firstFrplotted_Filter = 0;
                app.updatePlots(); 
    
                %Updates convert Data Button
                app.StartFilterButton.BackgroundColor = app.color_Button_start;
                app.StartFilterButton.Text ='Convert Data';
                app.button_startFilter_pushed_state = 0;
    
                %disabels spinners to set positon of the Pointcloud
                app.dxSpinner_Filter.Enable = "off";
                app.dySpinner_Filter.Enable = "off";
                app.dzSpinner_Filter.Enable = "off";
                app.dalphaSpinner_Filter.Enable = "off";
                app.dbetaSpinner_Filter.Enable = "off";
                app.dgammaSpinner_Filter.Enable = "off";
                app.DropDownFramesliderFilter.Enable = "off";
    
                %Disables Sliders
                app.StartFrSpinner.Enable = "off";
                app.EndFrSpinner.Enable ="off";
                app.StartFrameSliderLabel.Enable = "off";
                app.EndFrameSliderLabel.Enable = "off";
                app.StartFrameSlider.Enable = "off";
                app.EndFrameSlider.Enable = "off";
                app.ContinueButton_Filter.Enable = "off";

                %update ImportCsvButton to clear data Button
                app.button_importCSV_state = 0;
                app.ImportcsvButton.Text = 'Import .csv';
                app.ImportcsvButton.BackgroundColor = app.color_Button_load;               
            end                                                                
            %Activates Buttons
            app.ImportcsvButton.Enable = "on";
        end

        % Button pushed function: StartFilterButton
        function StartFilterButtonPushed(app, event)
            fprintf('\nCallback: StartFilterButtonValueChanged \n')
            %Deactivates buttons
            buttons = findobj(app.PouringLadleInspectUIFigure,'Type','uibutton');
            buttons_State = {buttons.Enable}; 
            set(buttons,'Enable','off')

            %deside what to do depending on the state of the Button
            if app.button_startFilter_pushed_state == 0 %Convert Data state                   
                %Start and endframe to be Filterd
                startframe = app.start_Frame;
                endframe = app.end_Frame;
                                                          
                %call Filter function
                delete(app.lastFrame_1);
                delete(app.lastFrame_2);
                
                %Start Filtering, display Progress and update repeat/stopp button
                app.ProgressBar_LPS.Visible = "on";
                app.StopButton.BackgroundColor = app.color_button_remove;
                app.StopButton.Icon = "images/Stop2.png";
                app.button_stop_repeat_state = 1;
                app.Arr_Ptclds_filterd = app.filterPtCloud(app.Fit_Obj_Ladle,app.Arr_PtClds_unfilterd,startframe,endframe);%Filter the Pointcloud
                app.ProgressBar_LPS.Visible = "off";
                app.StopButton.BackgroundColor = app.color_Button_start;
                app.StopButton.Icon = "images/Repeat.png";
                app.button_stop_repeat_state = 0;
                app.stopFlag = 0;

                %Update the Button
                app.button_startFilter_pushed_state = 1;
                app.StartFilterButton.Text ='Save to .csv';
                app.StartFilterButton.BackgroundColor = app.color_button_continue;

                %Activates Buttons
                [buttons.Enable] = deal(buttons_State{:});
                app.ImportcsvButton.Enable = "on";
                app.StartFilterButton.Enable = "on"; 

                app.ContinueButton_Filter.Enable = "on";

            elseif app.button_startFilter_pushed_state == 1 %Save Data state     
                %get filepath to save data
                filenameC = uiputfile(".csv"); 

                if isequal(filenameC,0) %if no filepath was selected
                    msgbox('User selected Cancel','Error','error');                
                else %Stores data at given location                                    
                    writematrix(app.Arr_Ptclds_filterd,filenameC,'Delimiter',';');%saves Data
                    msgbox(['file stored successfully as:',filenameC],'Succsess');

                end
                %Update the Button
                app.button_startFilter_pushed_state = 0;
                app.StartFilterButton.BackgroundColor = app.color_Button_start;
                app.StartFilterButton.Text ='Convert Data';

                %Activates Buttons
                [buttons.Enable] = deal(buttons_State{:});
                app.ImportcsvButton.Enable = "on";
                app.StartFilterButton.Enable = "on"; 

                %Clears Data
                app.Arr_Ptclds_filterd = [];
                app.firstFrplotted_Filter = 0;
                app.ContinueButton_Filter.Enable = "off";
            end


        end

        % Button pushed function: ContinueButton_Filter
        function ContinueButton_FilterPushed(app, event)
            app.Selected1csvFilesEditField.Value = "filterd " + app.file_Bag;
            app.TabGroup.SelectedTab = app.EvaluatePlaybackTab;

            %switch tab
            app.TabGroupSelectionChanged

            %Import Data
            app.Arr_PtClds1 = app.Arr_Ptclds_filterd;
            app.Arr_PtClds_Combined = app.combineData(app.Arr_PtClds1,app.Arr_PtClds2);

            if isempty(app.Arr_PtClds_Combined) == 0

                %Change state of button
                app.button_select1FileButtonPushed_state = 1;

                framenumber = size(app.Arr_PtClds_Combined,2)/3;
                %if only one Frame is stored inside of the CSV the limit must be set manualy            
                if framenumber == 1
                    app.FrameSlider.Limits = [1,1.1];
                else
                    app.FrameSlider.Limits = [1,framenumber];
                end

                %Updates Button to remove file state
                app.Select1FileButton.BackgroundColor = app.color_button_remove;
                app.Select1FileButton.Text = 'Remove 1. File';
                app.StartPlaybackButton.Enable = "off";
                
                app.FrameSlider.Enable ="on";
                app.StartPlaybackButton.Enable = "on";
                app.firstfrplotted_Evaluate = 1;
                app.FrameOtTimeDropDown.Value = 'Frame';
                app.FrameOtTimeDropDown.Enable = "on";
                app.FrameSlider.Enable = "on";
                app.FrameSliderLabel.Enable = "on";

                %%set Axlimits of alpha & beat plots
                app.UIAxesBeta.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                app.UiAxisAlpha.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
            end
        end

        % Value changed function: dxSpinner_Filter
        function dxSpinner_FilterValueChanged(app, event)
            fprintf('\nCallback: dxSpinner_2ValueChanged \n')
            app.updatePosOfPtCld() % uses Pointcloud backup to generate new pointcloud Unfilterd                                      
        end

        % Value changed function: dySpinner_Filter
        function dySpinner_FilterValueChanged(app, event)
            fprintf('\nCallback: dySpinner_2ValueChanged \n')
            app.updatePosOfPtCld() % uses Pointcloud backup to generate new pointcloud Unfilterd
        end

        % Value changed function: dzSpinner_Filter
        function dzSpinner_FilterValueChanged(app, event)
            fprintf('\nCallback: dzSpinner_2ValueChanged \n')
            app.updatePosOfPtCld() % uses Pointcloud backup to generate new pointcloud Unfilterd              
        end

        % Value changed function: dalphaSpinner_Filter
        function dalphaSpinner_FilterValueChanged(app, event)
            fprintf('\nCallback: dalphaSpinner_2ValueChanged \n')
            app.updatePosOfPtCld() % uses Pointcloud backup to generate new pointcloud Unfilterd
        end

        % Value changed function: dbetaSpinner_Filter
        function dbetaSpinner_FilterValueChanged(app, event)
            fprintf('\nCallback: dbetaSpinner_2ValueChanged \n')
            app.updatePosOfPtCld() % uses Pointcloud backup to generate new pointcloud Unfilterd              
        end

        % Value changed function: StartFrameSlider
        function StartFrameSliderValueChanged(app, event)
            fprintf('\nCallback: StartFrameSliderValueChanged \n')
            app.start_Frame = app.StartFrameSlider.Value;
            app.start_Frame = round(app.start_Frame,0);

            switch app.DropDownFramesliderFilter.Value
                case 'Frame'
                    frameToDisplay = app.StartFrameSlider.Value;                             
                    frameToDisplay = round(frameToDisplay,0);
                    app.StartFrameSlider.Value = frameToDisplay;
                    app.StartFrSpinner.Value = frameToDisplay;
%                     valueToDisp = frameToDisplay;

                case 'Time'
                    timeToDisplay = app.StartFrameSlider.Value;
                    ArrTime = app.Arr_PtClds_unfilterd(1,1:3:end);
                    [timeToDisplay,frameToDisplay] = app.translateTimeAndFrameNum(timeToDisplay,[],ArrTime);
                    app.StartFrameSlider.Value = timeToDisplay; 
                    app.StartFrSpinner.Value = timeToDisplay;
%                     valueToDisp = timeToDisplay;
            end


            if app.StartFrameSlider.Value > app.EndFrameSlider.Value
                app.start_Frame = app.end_Frame;
                app.StartFrameSlider.Value = app.EndFrameSlider.Value;
            else
                app.start_Frame = frameToDisplay;
            end         
            
            Arr = app.Arr_PtClds_unfilterd(2:end,:);

            if isempty(Arr) == 0
                delete(app.lastFrame_1);
                app.lastFrame_1 = plot3(app.UIAxesPtcld,Arr(:,(frameToDisplay-1)*3+1),Arr(:,(frameToDisplay-1)*3+2),Arr(:,(frameToDisplay-1)*3+3),'o','MarkerFaceColor',app.color_PtCld_1_Face,'Color',app.color_PtCld_1_Edge,'MarkerSize',5);             
            end         
        end

        % Value changed function: EndFrameSlider
        function EndFrameSliderValueChanged(app, event)
            fprintf('\nCallback: EndFrameSliderValueChanged \n')
            app.end_Frame = app.EndFrameSlider.Value;
            app.end_Frame = round(app.end_Frame,0);

            switch app.DropDownFramesliderFilter.Value
                case 'Frame'
                    frameToDisplay = app.EndFrameSlider.Value;                             
                    frameToDisplay = round(frameToDisplay,0);
                    app.EndFrameSlider.Value = frameToDisplay;
                    app.EndFrSpinner.Value = frameToDisplay;
                    valueToDisp = frameToDisplay;

                case 'Time'
                    timeToDisplay = app.EndFrameSlider.Value;
                    ArrTime = app.Arr_PtClds_unfilterd(1,1:3:end);
                    [timeToDisplay,frameToDisplay] = app.translateTimeAndFrameNum(timeToDisplay,[],ArrTime);
                    app.EndFrameSlider.Value = timeToDisplay;  
                    app.EndFrSpinner.Value = timeToDisplay;
                    valueToDisp = timeToDisplay;
            end

            if app.StartFrameSlider.Value > app.EndFrameSlider.Value
                app.end_Frame = app.start_Frame;
                app.EndFrameSlider.Value = app.StartFrameSlider.Value ;
            else
                app.end_Frame = frameToDisplay;
            end
%             app.EndFrameSlider.Value = app.end_Frame;            
            
            Arr = app.Arr_PtClds_unfilterd(2:end,:);
            
            if isempty(Arr) == 0
                delete(app.lastFrame_2);
                app.lastFrame_2 = plot3(app.UIAxesPtcld,Arr(:,(frameToDisplay-1)*3+1),Arr(:,(frameToDisplay-1)*3+2),Arr(:,(frameToDisplay-1)*3+3),'o','MarkerFaceColor',app.color_PtCld_2_Face,'Color',app.color_PtCld_2_Edge,'MarkerSize',5);           
            end
        end

        % Value changed function: dgammaSpinner_Filter
        function dgammaSpinner_FilterValueChanged(app, event)
            fprintf('\nCallback: dgammaSpinner_2ValueChanged \n')
            app.updatePosOfPtCld()             
        end

        % Callback function
        function SelectnewLadleButtonValueChanged(app, event)
            fprintf('\nCallback: SelectnewLadleShapeButtonValueChange \n')
            %Get filepath of Stl
            [app.file_Ladle,app.path_Ladle] = uigetfile('*.stl');            
                                    
            if isequal(app.file_Ladle,0)% If no filepath was selected
                msgbox('User selected Cancel','Error','error');
            else %If an filepath was selected                                                        
                app.SelectedstlEditField.Value= app.file_Ladle;
                app.dxSpinner.Enable = "on";
                app.dySpinner.Enable = "on";
                app.dzSpinner.Enable = "on";
                app.dalphaSpinner.Enable = "on";
                app.dbetaSpinner.Enable = "on";
                app.dgammaSpinner.Enable = "on";
            end  

            app.plotLadleButton.Enable = 'on';
            app.SelectnewLadleButton.Value = false;            
        end

        % Button pushed function: SelectnewLadleButton
        function SelectnewLadleButtonPushed(app, event)
            fprintf('\nCallback: SelectnewLadleShapeButtonValueChange \n')
            %Get filepath of Stl
            [app.file_Ladle,app.path_Ladle] = uigetfile('*.stl');            
                                    
            if isequal(app.file_Ladle,0)% If no filepath was selected
                msgbox('User selected Cancel','Error','error');
            else %If an filepath was selected                                                        
                app.SelectedstlEditField.Value= app.file_Ladle;
                app.dxSpinner.Enable = "on";
                app.dySpinner.Enable = "on";
                app.dzSpinner.Enable = "on";
                app.dalphaSpinner.Enable = "on";
                app.dbetaSpinner.Enable = "on";
                app.dgammaSpinner.Enable = "on";
            end  

            app.plotLadleButton.Enable = 'on';
        end

        % Button pushed function: removeLadleButton
        function removeLadleButtonPushed(app, event)
            fprintf('\nCallback: removestlButtonValueChanged \n')
            %Sets state of Stl plotted                              
            app.ladlePlotted = 0;

            %updates stored Variables
            app.file_Ladle = 0;
            app.path_Ladle = 0;
            
            %Updates Plots            
            app.updatePlots(); 

            %update Ui
            app.plotLadleButton.Enable = 'off';
            app.removeLadleButton.Enable = 'off';
            app.dxSpinner.Enable = "off";
            app.dySpinner.Enable = "off";
            app.dzSpinner.Enable = "off";
            app.dalphaSpinner.Enable = "off";
            app.dbetaSpinner.Enable = "off";
            app.dgammaSpinner.Enable = "off";
            app.SelectedstlEditField.Value = 'no file selected';
        end

        % Button pushed function: plotLadleButton
        function plotLadleButtonPushed(app, event)
            fprintf('\nCallback: plotstlButtonValueChanged \n') 
             switch app.button_plotSTL_pushed_state
                  case 0 %Plot STL
            
                    if isequal(app.file_Ladle,0)
                       msgbox('User selected Cancel','Error','error');
                    else                                                                             
                        app.plotLadle(fullfile(app.path_Ladle,app.file_Ladle),app.transparency,app.color_Ladle)
                        hold (app.UIAxesPtcld,"on");

                        app.ladlePlotted = 1;
                        app.removeLadleButton.Enable = 'on';
                        app.plotLadleButton.BackgroundColor = app.color_button_continue;
                        app.plotLadleButton.Text = 'Save STL';
                        app.button_plotSTL_pushed_state = 1;                      
                    end                    
                  case 1 %Save Stl

                        titel= 'Save Settings'; % gets displayd in inputdgl            
                        optionName= inputdlg(titel,'',[1 35],{'Profile_1'});
            
                        %bool values to deside what to do
                        bool = isempty(optionName);
                        bool2 = 0;
            
                        if isequal(app.file_Ladle,0)
                            msgbox('User selected Cancel','Error','error');
                        else                                                                             
                            copyfile(fullfile(app.path_Ladle,app.file_Ladle),"LadleSTL\");

                            app.path_Ladle = "LadleSTL\";
                        
                            %creates an cellArray with the profile name and the min & max          
                            optionValue =[optionName,app.path_Ladle,app.file_Ladle,...
                                app.dxSpinner.Value,app.dySpinner.Value,app.dzSpinner.Value,...
                                app.dalphaSpinner.Value,app.dbetaSpinner.Value,app.dgammaSpinner.Value];
                                                                      
                            %if the user selected a profile name its saved            
                            if bool == 1% Case no name was selected
                                msgbox('User selected Cancel','Error','error');          
                            else % case an name was selected
                                optionValues = readcell("Opt_Ladle.txt",'Delimiter',';');%reads document with stored options
                                for i = 1:size(optionValues,1)%cheks if selected name is in use
                
                                   opt_i = optionValues(i,1); %Name of the i'th option 

                                   if i == 1
                                       if strcmp('no ladle Saved',opt_i) == 1
                                           writematrix(optionValue,'Opt_Ladle.txt','Delimiter',';','WriteMode','overwrite');%saves the Profile
                                           bool2 = 1;
                                       end
                                   end
                              
                                   %Check wether name alredy exist
                                   if strcmp(opt_i,optionName) == 1 %selects option defined by selectedOpt StringCompare
                                       msgbox([optionName{1},' alredy exist  select an other name'])
                                       bool2 = 1;%if name alredy exists then nothing is saved                                                               
                                   end                    
                                end
                
                                if bool2 == 0 % if name isent in use then save
                                    writematrix(optionValue,'Opt_Ladle.txt','Delimiter',';','WriteMode','append');%saves the Profile
                                end
                                %update dopdown list
                                app.updateOptionList_Ladle(); 
                                app.LadleShapeDropDown.Value = optionName;
                            end
                            %update button to convert data state
                            app.plotLadleButton.BackgroundColor = app.color_Button_start;
                            app.plotLadleButton.Text = 'Plot STL';
                            app.button_plotSTL_pushed_state = 0;                
                        end                                                                     
              end            
        end

        % Value changed function: dxSpinner
        function dxSpinnerValueChanged(app, event)
            fprintf('\nCallback: dxSpinnerValueChanged \n') 
            app.updatePlots();
        end

        % Value changed function: dySpinner
        function dySpinnerValueChanged(app, event)
            fprintf('\nCallback: dySpinnerValueChanged \n')
            app.updatePlots();
        end

        % Value changed function: dzSpinner
        function dzSpinnerValueChanged(app, event)
            fprintf('\nCallback: dzSpinnerValueChanged \n')
            app.updatePlots();
        end

        % Value changed function: dalphaSpinner
        function dalphaSpinnerValueChanged(app, event)
            fprintf('\nCallback: dalphaSpinnerValueChanged \n')
            app.updatePlots();
        end

        % Value changed function: dbetaSpinner
        function dbetaSpinnerValueChanged(app, event)
            fprintf('\nCallback: dbetaSpinnerValueChanged \n')
            app.updatePlots();
        end

        % Value changed function: dgammaSpinner
        function dgammaSpinnerValueChanged(app, event)
            fprintf('\nCallback: dgammaSpinnerValueChanged \n')
            app.updatePlots();
        end

        % Button pushed function: Select1FileButton
        function Select1FileButtonPushed(app, event)
            fprintf('\nCallback: Select1FileButtonPushed \n')
            if app.button_select1FileButtonPushed_state == 0                
                
                %Deactivate buttons on Evaluate tab
                buttons = findobj(app.PouringLadleInspectUIFigure,'Type','uibutton');
                buttons_State = {buttons.Enable};
                set(buttons,'Enable','off')
                app.FrameSlider.Enable ="off";
                app.FrameOtTimeDropDown.Enable = "off";
                            
                %Get filepath
                [app.file_CSV1,app.path_CSV1] = uigetfile({'*.csv','*.txt'});%               
                                                               
                if isequal(app.file_CSV1,0)
                    msgbox('User selected Cancel','Error','error');                    
                    [buttons.Enable] = deal(buttons_State{:}); %Activate buttons
                    app.FrameSlider.Enable ="on";
                    app.FrSpinner.Enable = "on";
                    app.FrameSliderLabel.Enable = "on";
                    app.FrameOtTimeDropDown.Enable = "on";
                else 
                    %Change state of button
                    app.button_select1FileButtonPushed_state = 1;

                    if app.button_select1FileButtonPushed_state == 1 && app.button_select2FileButtonPushed_state == 1
                        app.towOriginsExist = 1;
                    else
                        app.towOriginsExist = 0;
                    end

                    %Import Data
                    app.Arr_PtClds1 = app.loadPtCldFromCSV(fullfile(app.path_CSV1,app.file_CSV1));
                    app.Arr_PtClds_Combined = app.combineData(app.Arr_PtClds1,app.Arr_PtClds2);

                    if isempty(app.Arr_PtClds_Combined) == 0

                        app.framesliderUpdatelength                          
                        
                        %Updates Button to remove file state
                        app.Select1FileButton.BackgroundColor = app.color_button_remove;
                        app.Select1FileButton.Text = 'Remove 1. File';
                        app.StartPlaybackButton.Enable = "off";

                        %Activate buttons on Evaluate tab
                        [buttons.Enable] = deal(buttons_State{:});                        
                        app.FrameSlider.Enable ="on";
                        app.FrSpinner.Enable = "on";
                        app.FrameSliderLabel.Enable = "on";
                        app.FrameOtTimeDropDown.Enable = "on";
                        app.StartPlaybackButton.Enable = "on";
                        app.firstfrplotted_Evaluate = 1;
                        app.Selected1csvFilesEditField.Value = app.file_CSV1;

                        %%set Axlimits of alpha & beat plots
                        app.UIAxesBeta.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxisAlpha.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxes_ZDiffAtXY.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxisAlpha_xy.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxisBeta_xy.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxes_ZDiffAtXY.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];

                    else
                        msgbox('No data!','Error','error')
                    end
                end               
            else 
                %Update state of button
                app.button_select1FileButtonPushed_state = 0;
                app.towOriginsExist = 0;

                %Clear filepath 
                app.file_CSV1 = 0;
                app.path_CSV1 = 0;

                %clear Arr_ptclds1
                app.Arr_PtClds1 = [];

                app.Arr_PtClds_Combined = app.combineData(app.Arr_PtClds1,app.Arr_PtClds2);

                if isempty(app.Arr_PtClds_Combined) == 0
                    app.framesliderUpdatelength

                    %%set Axlimits of alpha & beat plots
                    app.UIAxesBeta.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxisAlpha.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxes_ZDiffAtXY.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxisAlpha_xy.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxisBeta_xy.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxes_ZDiffAtXY.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                else
                    msgbox('No data!','Error','error')
                end

                %Update buttons
                app.Select1FileButton.BackgroundColor = app.color_Button_load;
                app.Select1FileButton.Text = 'Select 1. File';
                app.Selected1csvFilesEditField.Value = 'no 1. file Selected';
                app.gofofFitplaneCheckBox.Enable = "off";
                               
                %Update Ui elements depending on PtClds2
                if isempty(app.Arr_PtClds2) == 1
                    
                    app.FrameSlider.Enable ="off";
                    app.FrSpinner.Enable = "off";
                    app.FrameSliderLabel.Enable = "off";
                    app.FrameOtTimeDropDown.Enable = "off";
                    app.StartPlaybackButton.Enable = "off";
                    app.firstfrplotted_Evaluate = 0;
                end 

                %Clear and update Axis
                cla(app.UIAxesBeta);
                cla(app.UiAxisAlpha);
                cla(app.UiAxes_ZDiffAtXY)
                cla(app.UIAxesRMSE)
                cla(app.UIAxesRSquared)
                cla(app.UIAxesSSE)
                cla(app.UiAxisAlpha_xy)
                cla(app.UiAxisBeta_xy)
                cla(app.UiAxesMaxMin)
                app.updatePlots();                              
            end           
        end

        % Button pushed function: Select2FileButton
        function Select2FileButtonPushed(app, event)
            fprintf('\nCallback: Select2FileButtonPushed \n')
            if app.button_select2FileButtonPushed_state == 0
               
                %Deactivate buttons on Evaluate tab
                buttons = findobj(app.PouringLadleInspectUIFigure,'Type','uibutton');
                buttons_State = {buttons.Enable};
                set(buttons,'Enable','off')
                app.FrameSlider.Enable ="off";
                app.FrameOtTimeDropDown.Enable = "off";                                

                %get filename and path
                [app.file_CSV2,app.path_CSV2] = uigetfile({'*.csv','*.txt'});%               
                                                                      
                %Import Data
                if isequal(app.file_CSV2,0)% If no file was selected display an Error
                    msgbox('User selected Cancel','Error','error');
                    [buttons.Enable] = deal(buttons_State{:}); %Activate buttons
                    app.FrameSlider.Enable ="on";
                    app.FrSpinner.Enable = "on";
                    app.FrameSliderLabel.Enable = "on";
                    app.FrameOtTimeDropDown.Enable = "on";
                else  %If an Filepath was given import Data 

                    %Update state of button
                    app.button_select2FileButtonPushed_state = 1;

                    if app.button_select1FileButtonPushed_state == 1 && app.button_select2FileButtonPushed_state == 1
                        app.towOriginsExist = 1;
                    else
                        app.towOriginsExist = 0;
                    end

                    app.Arr_PtClds2 = app.loadPtCldFromCSV(fullfile(app.path_CSV2,app.file_CSV2));%read Data
                    app.Arr_PtClds_Combined = app.combineData(app.Arr_PtClds1,app.Arr_PtClds2);

                    if isempty(app.Arr_PtClds_Combined) == 0
                        app.framesliderUpdatelength
        
                        %Update Button
                        app.Select2FileButton.BackgroundColor = app.color_button_remove;
                        app.Select2FileButton.Text = 'Remove 2. File';
        
                        %Activate buttons on Evaluate tab
                        [buttons.Enable] = deal(buttons_State{:}); %Activate buttons
                        app.Selected2csvFilesEditField.Value = app.file_CSV2;
                        app.FrameSlider.Enable ="on";
                        app.FrSpinner.Enable = "on";
                        app.FrameSliderLabel.Enable = "on";
                        app.FrameOtTimeDropDown.Enable = "on";
                        app.StartPlaybackButton.Enable = "on";
                        app.firstfrplotted_Evaluate = 1;
                        
                        %set Axlimits of alpha & beat plots
                        app.UIAxesBeta.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxisAlpha.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxes_ZDiffAtXY.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxisAlpha_xy.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxisBeta_xy.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                        app.UiAxes_ZDiffAtXY.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    else
                        msgbox('No data!','Error','error')

                    end
                end                
            else 
                %Set state of button
                app.button_select2FileButtonPushed_state = 0;
                app.towOriginsExist = 0;

                %clear Path & Filename
                app.file_CSV2 = 0;
                app.path_CSV2 = 0;

                %Clear var PtClds2
                app.Arr_PtClds2 = [];

                app.Arr_PtClds_Combined = app.combineData(app.Arr_PtClds1,app.Arr_PtClds2);

                if isempty(app.Arr_PtClds_Combined) == 0
                    app.framesliderUpdatelength

                    %%set Axlimits of alpha & beat plots
                    app.UIAxesBeta.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxisAlpha.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxes_ZDiffAtXY.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxisAlpha_xy.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxisBeta_xy.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    app.UiAxes_ZDiffAtXY.XLim = [0,app.Arr_PtClds_Combined(1,end-2)];
                    
                end

                %Update Ui elements
                app.Select2FileButton.BackgroundColor = app.color_Button_load;
                app.Select2FileButton.Text = 'Select 2. File';
                app.Selected2csvFilesEditField.Value = 'no 2. file Selected';
                app.gofofFitplaneCheckBox.Enable = "off";

                %Update Ui elements depending on PtClds1
                if isempty(app.Arr_PtClds1) == 1
                    app.FrameSlider.Enable ="off";
                    app.FrSpinner.Enable = "off";
                    app.FrameSliderLabel.Enable = "off";
                    app.StartPlaybackButton.Enable = "off";
                    app.FrameOtTimeDropDown.Enable = "off";
                    app.firstfrplotted_Evaluate = 0;
                end  

                %Clear and update Axis
                cla(app.UIAxesBeta);
                cla(app.UiAxisAlpha);
                cla(app.UiAxes_ZDiffAtXY)
                cla(app.UIAxesRMSE)
                cla(app.UIAxesRSquared)
                cla(app.UIAxesSSE)
                cla(app.UiAxisAlpha_xy)
                cla(app.UiAxisBeta_xy)
                cla(app.UiAxesMaxMin)
                app.updatePlots();                                           
            end              
        end

        % Button pushed function: StartPlaybackButton
        function StartPlaybackButtonPushed(app, event)
            fprintf('\nCallback: StartPlaybackButtonPushed \n')
            %Clear Axis
            cla(app.UIAxesBeta);
            cla(app.UiAxisAlpha);
            cla(app.UiAxes_ZDiffAtXY)
            cla(app.UIAxesRMSE)
            cla(app.UIAxesRSquared)
            cla(app.UIAxesSSE)
            cla(app.UiAxisAlpha_xy)
            cla(app.UiAxisBeta_xy)
            cla(app.UiAxesMaxMin)

            buttons = findobj(app.PouringLadleInspectUIFigure,'Type','uibutton');
            buttons_State = {buttons.Enable};
            set(buttons,'Enable','off')

            switch app.fitData_state               
                case 'On'
                    thingsToDo = 'FitPlane';
                case 'Off'
                    thingsToDo = 'CalculateManualy';
            end
            
            %Evaluate and Visualize Progress
            app.ProgressBar_LPS.Visible = "on";
            app.StopButton.Enable ="on";
            app.StopButton.BackgroundColor = app.color_button_remove;
            app.StopButton.Icon = "images/Stop2.png";
            app.button_stop_repeat_state = 1;
            delete(app.lastFrame_1);
            delete(app.lastFrame_2);
            [app.Vect_Origin,app.Vect_Timestamp,app.Vect_AlphaBeta,app.Vect_gof,app.Vect_ZDiffXY,app.lastFrame_1,app.lastFrame_2,app.Arr_PtZdiff,app.Arr_PtCldFitpl,app.Arr_AnglePtCld]...
                = app.evaluate_ptclouds(app.Arr_PtClds_Combined,thingsToDo); 
            app.ProgressBar_LPS.Visible = "off";           
            app.StopButton.BackgroundColor = app.color_Button_start;
            app.StopButton.Icon = "images/Repeat.png";
            app.button_stop_repeat_state = 0;
            app.stopFlag = 0;
            
            app.gofofFitplaneCheckBox.Enable = "on";
            app.updatePlots();
            
            %Activate buttons on Evaluate tab
            [buttons.Enable] = deal(buttons_State{:});
        end

        % Value changed function: FrameOtTimeDropDown
        function FrameOtTimeDropDownValueChanged(app, event)
            ArrTime = app.Arr_PtClds_Combined(1,1:3:end);            
            switch app.FrameOtTimeDropDown.Value
                case 'Frame'
                    timeBackup = app.FrameSlider.Value;
                    maxframeCount = size(app.Arr_PtClds_Combined,2)/3; 
                    app.FrSpinner.Step = 1;

                    %if only one Frame is stored inside of the CSV the limit must be set manualy            
                    if maxframeCount == 1
                        app.FrameSlider.Limits = [1,1.1];
                        app.FrSpinner.Limits = [1,1.1];
                    else
                        app.FrameSlider.Limits = [1,maxframeCount];
                        app.FrSpinner.Limits = [1,maxframeCount];
                    end

                    [~,frame] = app.translateTimeAndFrameNum(timeBackup,[],ArrTime);
                    app.FrameSlider.Value = frame;
                    app.FrameSliderLabel.Text = "Frame";

                case 'Time'
                    frameBackup = round(app.FrameSlider.Value);
                    app.FrSpinner.Step = 1/30;

                    if size(app.Arr_PtClds_Combined,2) == 3
                        app.FrameSlider.Limits = [app.Arr_PtClds_Combined(1,1),app.Arr_PtClds_Combined+0.1];
                        app.FrSpinner.Limits = [app.Arr_PtClds_Combined(1,1),app.Arr_PtClds_Combined+0.1];
                    else
                        maxTime = app.Arr_PtClds_Combined(1,end-2);
                        maxTime = round(maxTime+0.51);
                        mintime = app.Arr_PtClds_Combined(1,1);
                        minTime = round(mintime-0.49);

                        app.FrameSlider.Limits = [minTime,maxTime];
                        app.FrSpinner.Limits = [minTime,maxTime];
                        

                        [time,~] = app.translateTimeAndFrameNum([],frameBackup,ArrTime);
                        app.FrameSlider.Value = time;
                        app.FrameSliderLabel.Text = "Time";
                    end
            end            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            fprintf('\nCallback: FrameSliderValueChanged \n')                       
            switch app.FrameOtTimeDropDown.Value
                case 'Frame'
                    frameToDisplay = app.FrameSlider.Value;                             
                    frameToDisplay = round(frameToDisplay,0);
                    app.FrameSlider.Value = frameToDisplay;
                    app.FrSpinner.Value = frameToDisplay;
                    framesToDisplay = [frameToDisplay,frameToDisplay];

                case 'Time'
                    timeToDisplay = app.FrameSlider.Value;
                    ArrTime = app.Arr_PtClds_Combined(1,1:3:end);
                    [timeToDisplay,frameToDisplay] = app.translateTimeAndFrameNum(timeToDisplay,[],ArrTime);
                    framesToDisplay = [frameToDisplay,frameToDisplay];
                    app.FrameSlider.Value = timeToDisplay; 
                    app.FrSpinner.Value = timeToDisplay;
            end
            cla(app.UiAxisAlpha)
            cla(app.UIAxesBeta)

            switch app.fitData_state               
                case 'On'
                    thingsToDo = 'FitPlane';
                case 'Off'
                    thingsToDo = 'CalculateManualy';
            end

            %app.loadPtCldFromCSV(fullfile(app.path_CSV,app.file_CSV))
            delete(app.lastFrame_1);
            delete(app.lastFrame_2);
            delete(app.evalpoints);
            delete(app.hist_angle_1);
            delete(app.hist_angle_2);
            
            [app.Vect_Origin,app.Vect_Timestamp,app.Vect_AlphaBeta,app.Vect_gof,app.Vect_ZDiffXY,app.lastFrame_1,app.lastFrame_2,app.Arr_PtZdiff,app.Arr_PtCldFitpl,app.Arr_AnglePtCld]...
                = app.evaluate_ptclouds(app.Arr_PtClds_Combined,thingsToDo,framesToDisplay,app.towOriginsExist);                                                
        end

        % Selection changed function: PlottingalpabetaButtonGroup
        function PlottingalpabetaButtonGroupSelectionChanged(app, event)
    
        end

        % Callback function
        function SeperatorEditFieldValueChanged(app, event)
            fprintf('\nCallback: SeperatorEditFieldValueChanged \n')
            app.opt_seperator = app.SeperatorEditField.Value;                        
        end

        % Value changed function: LadleShapeDropDown
        function LadleShapeDropDownValueChanged(app, event)
            fprintf('\nCallback: LadleShapeDropDownValueChanged \n')
            %Clears UiAxisPtcld
            cla(app.UIAxesPtcld)
            %Uses selected option from dropdown to load Option data of the Ladle            
            selectedOpt = app.LadleShapeDropDown.Value;
            app.readOpt_Ladle(selectedOpt); 
            app.SelectedstlEditField.Value = app.file_Ladle;
            app.updatePlots
            app.rezisePlot()
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            fprintf('\nCallback: TransparencySliderValueChanged \n')
            app.transparency = 1-app.TransparencySlider.Value/100;
            app.updatePlots()
        end

        % Value changed function: ColorLadleDropDown
        function ColorLadleDropDownValueChanged(app, event)
            fprintf('\nCallback: ColorLadleDropDownValueChanged \n')
            switch app.ColorLadleDropDown.Value
                case 'Red'
                    app.color_Ladle = 'r';
                case 'Green'
                    app.color_Ladle = 'g';                    
                case 'Blue'
                    app.color_Ladle = 'b';
                case 'Orange'
                    app.color_Ladle = [1.00,0.71,0.16];
                case 'Custom'
                    app.color_Ladle = app.ColorSelectorFace.Value;
            end 
            app.updatePlots()
        end

        % Value changed function: ColorMaxMinDropDown
        function ColorMaxMinDropDownValueChanged(app, event)
            fprintf('\nCallback: ColorMaxMinDropDownValueChanged \n')
            switch app.ColorMaxMinDropDown.Value
                case 'Red'
                    app.color_MaxMin = 'r';
                case 'Green'
                    app.color_MaxMin = 'g';                    
                case 'Blue'
                    app.color_MaxMin = 'b';
                case 'Orange'
                    app.color_MaxMin = [1.00,0.71,0.16];
                case 'Custom'
                    app.color_MaxMin = app.ColorSelectorFace.Value;
            end
            app.updatePlots()
        end

        % Value changed function: delta_xyDropDown
        function delta_xyDropDownValueChanged(app, event)
            fprintf('\nCallback: delta_xyDropDownValueChanged \n')
            switch app.delta_xyDropDown.Value
                case 'Red'
                    app.color_dx_dy = 'r';
                case 'Green'
                    app.color_dx_dy = 'g';                    
                case 'Blue'
                    app.color_dx_dy = 'b';
                case 'Orange'
                    app.color_dx_dy = [1.00,0.71,0.16];
                case 'Custom'
                    app.color_dx_dy = app.ColorSelectorFace.Value;
            end
            app.updatePlots()           
        end

        % Value changed function: Pointcloud1DropDown
        function Pointcloud1DropDownValueChanged(app, event)
            fprintf('\nCallback: Pointcloud1DropDownValueChanged \n')
            switch app.Pointcloud1DropDown.Value
                case 'Red'
                    app.color_PtCld_1_Face = "#ff7878";
                    app.color_PtCld_1_Edge = "#FF0000"; %'r'
                case 'Green'
                    app.color_PtCld_1_Face = "#00FF00"; % 'g'
                    app.color_PtCld_1_Edge = "#3BBE01";
                     
                case 'Blue'
                    app.color_PtCld_1_Face = "#4DBEEE"; % 'b'
                    app.color_PtCld_1_Edge = "#0000FF";

                case 'Orange'
                    app.color_PtCld_1_Face = "#FFDC4D";
                    app.color_PtCld_1_Edge = "#FFA005";  % 'j'
                case 'Custom'
                    app.color_PtCld_1_Face = app.ColorSelectorFace.Value;
                    app.color_PtCld_1_Edge = app.ColorSelectorEdge.Value;
            end
            app.updatePlots()             
        end

        % Value changed function: Pointcloud2DropDown
        function Pointcloud2DropDownValueChanged(app, event)
            fprintf('\nCallback: Pointcloud2DropDownValueChanged \n')
            switch app.Pointcloud2DropDown.Value
                case 'Red'
                    app.color_PtCld_2_Face = "#ff7878";
                    app.color_PtCld_2_Edge = "#FF0000"; %'r'
                case 'Green'
                    app.color_PtCld_2_Face = "#00FF00"; % 'g'
                    app.color_PtCld_2_Edge = "#3BBE01";
                     
                case 'Blue'
                    app.color_PtCld_2_Face = "#4DBEEE"; % 'b'
                    app.color_PtCld_2_Edge = "#0000FF";

                case 'Orange'
                    app.color_PtCld_2_Face = "#FFDC4D";
                    app.color_PtCld_2_Edge = "#FFA005";  % 'j'
                case 'Custom'
                    app.color_PtCld_2_Face = app.ColorSelectorFace.Value;
                    app.color_PtCld_2_Edge = app.ColorSelectorEdge.Value;
            end
            app.updatePlots()             
        end

        % Value changed function: FPSCounterSwitch
        function FPSCounterSwitchValueChanged(app, event)
            fprintf('\nCallback: FPSCounterSwitchValueChanged \n')
            switch app.FPSCounterSwitch.Value
                case 'On'
                    app.ProcessedfpsGauge.Visible = "on";
                    app.ProcessedfpsLabel.Visible = "on";
                case 'Off'
                    app.ProcessedfpsGauge.Visible = "off";
                    app.ProcessedfpsLabel.Visible = "off";
            end            
        end

        % Value changed function: SkippedFrSwitch
        function SkippedFrSwitchValueChanged(app, event)
            fprintf('\nCallback: SkippedFrSwitchValueChanged \n')
            switch app.SkippedFrSwitch.Value
                case 'On'
                    app.skippedEditField.Visible = "on";
                    app.skippedFramesLabel.Visible = "on";
                case 'Off'
                    app.skippedEditField.Visible = "off";
                    app.skippedFramesLabel.Visible = "off";
            end            
        end

        % Value changed function: CoordSysSwitch
        function CoordSysSwitchValueChanged(app, event)
            fprintf('\nCallback: CoordSysSwitchValueChanged \n')
            switch app.CoordSysSwitch.Value
                case 'On'
                    app.plotCoord_syst(0.1,0,0,0)
                case 'Off'
                    cla(app.UIAxesCoordSystem)
            end
        end

        % Value changed function: FittedLadleSwitch
        function FittedLadleSwitchValueChanged(app, event)
            switch app.FittedLadleSwitch.Value
                case 'On'
                    app.updatePlots
                case 'Off'
                    app.updatePlots
            end
        end

        % Value changed function: FitSurfaceDropDown
        function FitSurfaceDropDownValueChanged(app, event)
            fprintf('\nCallback: FittiypeDropDownValueChanged \n')
            if strcmp(app.FitSurfaceDropDown.Value,'none') == 1
                app.CalculateDerivativSwitch.Enable = "off";
                app.calculateGradientButton.Enable = "off";
                app.CalculateDerivativSwitchLabel.Enable = "off";

                app.DistofResidualsSwitch.Enable = "off";
                app.DistofResidualsSwitchLabel.Enable = "off";
                app.distOfResidualsButton.Enable = "off";

                app.DistofAngleSwitch.Enable = "off";
                app.DistofAngleSwitchLabel.Enable = "off";
                app.distOfAnglesButton.Enable = "off"; 

                app.CalAlphaBetaSwitch.Enable = "off";
                app.CalAlphaBetaSwitchLabel.Enable = "off";
                app.calcAlpaBetaButton.Enable = "off";

                app.CalculateZDiff.Enable = "off";
                app.CalculateZDiffLabel.Enable = "off";
                app.calculateZDiffButton.Enable = "off";

                app.Warning_NoFit.Visible ="on";
                app.Warning_NoFit_ZDiff.Visible = "on";
                app.Warning_NoFit_DistAngle.Visible = "on";

                app.fitData_state = 'Off';            
                app.delta_xySwitch.Value = 'On';
                app.updatePlots();                
            else
                app.CalculateDerivativSwitch.Enable = "on";
                app.calculateGradientButton.Enable = "on";
                app.CalculateDerivativSwitchLabel.Enable = "on";

                app.DistofResidualsSwitch.Enable = "on";
                app.DistofResidualsSwitchLabel.Enable = "on";
                app.distOfResidualsButton.Enable = "on";

                app.DistofAngleSwitch.Enable = "on";
                app.DistofAngleSwitchLabel.Enable = "on";
                app.distOfAnglesButton.Enable = "on";

                app.CalAlphaBetaSwitch.Enable = "on";
                app.CalAlphaBetaSwitchLabel.Enable = "on";
                app.calcAlpaBetaButton.Enable = "on";

                app.CalculateZDiff.Enable = "on";
                app.CalculateZDiffLabel.Enable = "on";
                app.calculateZDiffButton.Enable = "on";

                app.Warning_NoFit.Visible ="off";
                app.Warning_NoFit_ZDiff.Visible = "off";
                app.Warning_NoFit_DistAngle.Visible = "off";

                app.fitData_state = 'On';
                app.delta_xySwitch.Value = 'Off';
                app.updatePlots();
            end            
        end

        % Value changed function: CalculateDerivativSwitch
        function CalculateDerivativSwitchValueChanged(app, event)

        end

        % Value changed function: DistofAngleSwitch
        function DistofAngleSwitchValueChanged(app, event)

        end

        % Selection changed function: PlottingAnglesButtonGroup
        function PlottingAnglesButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottingAnglesButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Alpha only (x-Axis)'
                    app.button_plottingAngles_state = 0;

                case 'Beta only (y_Axis)'
                    app.button_plottingAngles_state = 1;

                case 'Alpha & Beta'
                    app.button_plottingAngles_state = 2;
            end            
        end

        % Callback function: ColorSelectorFace
        function ColorSelectorFaceValueChanged(app, event)

        end

        % Value changed function: OriginofDataCheckBox
        function OriginofDataCheckBoxValueChanged(app, event)
            fprintf('\nCallback: OriginofDataCheckBoxValueChanged \n')
            [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
            app.updatePreviewofData(app.Vect_DatToSave,cellArr)
        end

        % Value changed function: AlphaBetaCheckBox
        function AlphaBetaCheckBoxValueChanged(app, event)
            fprintf('\nCallback: alphabetaCheckBoxValueChanged \n')
            [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
            app.updatePreviewofData(app.Vect_DatToSave,cellArr)
        end

        % Value changed function: TimestampsCheckBox
        function TimestampsCheckBoxValueChanged(app, event)
            fprintf('\nCallback: TimestampsCheckBoxValueChanged \n')
            [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
            app.updatePreviewofData(app.Vect_DatToSave,cellArr)            
        end

        % Value changed function: gofofFitplaneCheckBox
        function gofofFitplaneCheckBoxValueChanged(app, event)
            fprintf('\nCallback: gofofFitplaneCheckBoxValueChanged \n')
            [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
            app.updatePreviewofData(app.Vect_DatToSave,cellArr)            
        end

        % Callback function
        function RMSEComparedCheckBoxValueChanged(app, event)
            fprintf('\nCallback: skippedframesCheckBoxValueChanged \n')
            [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
            app.updatePreviewofData(app.Vect_DatToSave,cellArr)            
        end

        % Value changed function: SeperatorDropDown
        function SeperatorDropDownValueChanged(app, event)
            fprintf('\nCallback: SeperatorDropDownValueChanged \n')
            switch app.SeperatorDropDown.Value
                
                case ','
                    app.opt_seperator = ',';

                case ';'
                    app.opt_seperator = ';';

                case '_'
                    app.opt_seperator = '_';

                case '/'
                    app.opt_seperator = '/';
                
                case 'space'
                    app.opt_seperator = ' ';
            end                        
        end

        % Value changed function: SavetocsvButton
        function SavetocsvButtonValueChanged(app, event)
            fprintf('\nCallback: SavetocsvButtonValueChanged \n')
            filenameC = uiputfile(".csv"); 
            if isequal(filenameC,0)
                msgbox('User selected Cancel','Error','error');                
            else 
                Data = app.UITableDataToSave.DisplayData;
                [~,Names] = app.DataToSave();
            
                switch app.IncludeCollumtitelsSwitch.Value
                    case 'On'
                        writelines(Names,filenameC);
                    case 'Off'
                end                                
                writematrix(Data,filenameC,'Delimiter',app.opt_seperator,'WriteMode','append');
                msgbox(['file stored successfully as: ',filenameC],'Succsess');
            end
            app.SavetocsvButton.Value = false;
        end

        % Value changed function: SavetoxlsButton
        function SavetoxlsButtonValueChanged(app, event)

        end

        % Button pushed function: Opt_Contoursurf
        function Opt_ContoursurfPushed(app, event)
            if app.button_optContourf_state == 0          
                app.OptionsZdiff.Enable = "on";
                app.OptionsZdiff.Visible = "on";
                app.button_optContourf_state = 1;
                app.Opt_Contoursurf.Icon = "images/ArrowUp.png";
            else
                app.OptionsZdiff.Enable = "off";
                app.OptionsZdiff.Visible = "off";
                app.button_optContourf_state = 0;
                app.Opt_Contoursurf.Icon = "images/ArrowDown.png";
            end
        end

        % Selection changed function: MethodofcreatingButtonGroup
        function MethodofcreatingButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodofcreatingButtonGroup.SelectedObject;
            if selectedButton == app.UseFixdValuesButton
                app.NumberofLinesSpinner.Enable = "off";
                app.NumberofLinesSpinner.Visible = "off";
                app.NumberofLinesSpinnerLabel.Visible = "off";
                app.FixedValuesEditField.Enable = "on";
                app.FixedValuesEditField.Visible = "on";
                app.FixedValuesEditFieldLabel.Visible = "on";
                app.FixedValuesEditFieldLabel.Enable = "on";
                app.FixedValuesEditFieldValueChanged               

            elseif selectedButton == app.UseNumberofLinesButton
                app.NumberofLinesSpinner.Enable = "on";
                app.NumberofLinesSpinner.Visible = "on";
                app.NumberofLinesSpinnerLabel.Visible = "on";
                app.FixedValuesEditField.Enable = "off";
                app.FixedValuesEditField.Visible = "off";
                app.FixedValuesEditFieldLabel.Visible = "off";
                app.FixedValuesEditFieldLabel.Enable = "off";
                app.NumberofLinesSpinnerValueChanged
            end
        end

        % Value changed function: NumberofLinesSpinner
        function NumberofLinesSpinnerValueChanged(app, event)
            value = app.NumberofLinesSpinner.Value;
            app.linspaceContourf_ZDiff = value;
        end

        % Value changed function: FixedValuesEditField
        function FixedValuesEditFieldValueChanged(app, event)
            value = app.FixedValuesEditField.Value;            
            Arr = []; %#ok<NASGU> 
            b = erase(value,{'[',']'});
            value = split(b,{' ',','});
            Arr = str2double(value)';            
            app.linspaceContourf_ZDiff = Arr;
        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            app.UIAxes_z_diff.ZLim = [app.MinSpinner.Value app.MaxSpinner.Value];
            app.c_Zdiff.Limits = app.UIAxes_z_diff.ZLim;
            caxis(app.UIAxes_z_diff,app.UIAxes_z_diff.ZLim) 
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            app.UIAxes_z_diff.ZLim = [app.MinSpinner.Value app.MaxSpinner.Value];
            app.c_Zdiff.Limits = app.UIAxes_z_diff.ZLim;
            caxis(app.UIAxes_z_diff,app.UIAxes_z_diff.ZLim) 
        end

        % Value changed function: CalculateTopviewFluid
        function CalculateTopviewFluidValueChanged(app, event)
            if app.CalculateTopviewFluid.Value == 0
                delete(app.fluidTopviewDiff);              
            end
        end

        % Callback function
        function Opt_AnglDropdownButtonPushed(app, event)
            if app.button_optContourf_state_2 == 0          
                app.OptAnglePanel.Enable = "on";
                app.OptAnglePanel.Visible = "on";
                app.button_optContourf_state_2 = 1;
                app.Opt_AnglDropdown.Icon = "images/ArrowUp.png";
            else
                app.OptAnglePanel.Enable = "off";
                app.OptAnglePanel.Visible = "off";
                app.button_optContourf_state_2 = 0;
                app.Opt_AnglDropdown.Icon = "images/ArrowDown.png";
            end
        end

        % Selection changed function: MethodofcreatingButtonGroup_2
        function MethodofcreatingButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.MethodofcreatingButtonGroup_2.SelectedObject;
            if selectedButton == app.UseFixdValuesButton_2
                app.NumberofLinesSpinner_2.Enable = "off";
                app.NumberofLinesSpinner_2.Visible = "off";
                app.NumberofLinesSpinner_2Label.Visible = "off";
                app.FixedValuesEditField_2.Enable = "on";
                app.FixedValuesEditField_2.Visible = "on";
                app.FixedValuesEditFieldLabel_2.Visible = "on";
                app.FixedValuesEditFieldLabel_2.Enable = "on";
                app.FixedValuesEditField_2ValueChanged
                
            elseif selectedButton == app.UseNumberofLinesButton_2
                app.NumberofLinesSpinner_2.Enable = "on";
                app.NumberofLinesSpinner_2.Visible = "on";
                app.NumberofLinesSpinner_2Label.Visible = "on";
                app.FixedValuesEditField_2.Enable = "off";
                app.FixedValuesEditField_2.Visible = "off";
                app.FixedValuesEditFieldLabel_2.Visible = "off";
                app.FixedValuesEditFieldLabel_2.Enable = "off";
                app.NumberofLinesSpinner_2ValueChanged
            end
        end

        % Value changed function: FixedValuesEditField_2
        function FixedValuesEditField_2ValueChanged(app, event)
            value = app.FixedValuesEditField_2.Value;           
            Arr = []; %#ok<NASGU> 
            b = erase(value,{'[',']'});
            value = split(b,{' ',','});
            Arr = str2double(value)';            
            app.linspaceContourf_Deriv = Arr;
        end

        % Value changed function: NumberofLinesSpinner_2
        function NumberofLinesSpinner_2ValueChanged(app, event)
            value = app.NumberofLinesSpinner_2.Value;
            app.linspaceContourf_Deriv = value;
        end

        % Value changed function: MaxSpinner_2
        function MaxSpinner_2ValueChanged(app, event)
            app.UIAxes_Derivativ.ZLim = [app.MinSpinner_2.Value app.MaxSpinner_2.Value];
            app.c_Angles.Limits = app.UIAxes_Derivativ.ZLim;
            caxis(app.UIAxes_Derivativ,app.UIAxes_Derivativ.ZLim) 
        end

        % Value changed function: MinSpinner_2
        function MinSpinner_2ValueChanged(app, event)
           app.UIAxes_Derivativ.ZLim = [app.MinSpinner_2.Value app.MaxSpinner_2.Value];
           app.c_Angles.Limits = app.UIAxes_Derivativ.ZLim;
           caxis(app.UIAxes_Derivativ,app.UIAxes_Derivativ.ZLim) 
        end

        % Value changed function: DropDownFramesliderFilter
        function DropDownFramesliderFilterValueChanged(app, event)
            ArrTime = app.Arr_PtClds_unfilterd(1,1:3:end);            
            switch app.DropDownFramesliderFilter.Value
                case 'Frame'
                    timeBackupStart = app.StartFrameSlider.Value;
                    timeBackupEnd = app.EndFrameSlider.Value;
                    app.StartFrSpinner.Step = 1;
                    app.EndFrSpinner.Step = 1; 

                    maxframeCount = size(app.Arr_PtClds_unfilterd,2)/3; 

                    %if only one Frame is stored inside of the CSV the limit must be set manualy            
                    if maxframeCount == 1
                        app.StartFrameSlider.Limits = [1,1.1];
                        app.EndFrameSlider.Limits = [1,1.1];
                        app.StartFrSpinner.Limits = [1,1.1];
                        app.EndFrSpinner.Limits =  [1,1.1];
                    else
                        app.StartFrameSlider.Limits = [1,maxframeCount];
                        app.EndFrameSlider.Limits = [1,maxframeCount];
                        app.StartFrSpinner.Limits = [1,maxframeCount];
                        app.EndFrSpinner.Limits =  [1,maxframeCount];
                    end

                    app.StartFrSpinner.Step = 1;
                    app.EndFrSpinner.Step = 1;

                    [~,frameStart] = app.translateTimeAndFrameNum(timeBackupStart,[],ArrTime);
                    [~,frameEnd] = app.translateTimeAndFrameNum(timeBackupEnd,[],ArrTime);
                    app.StartFrameSlider.Value = frameStart;
                    app.StartFrSpinner.Value = frameStart;
                    app.StartFrameSliderLabel.Text = "Start Frame";
                    app.EndFrameSlider.Value = frameEnd;
                    app.EndFrSpinner.Value = frameEnd;
                    app.EndFrameSliderLabel.Text = "End Frame";

                case 'Time'
                    timeBackupStart = app.StartFrameSlider.Value;
                    timeBackupEnd = app.EndFrameSlider.Value;
                    app.StartFrSpinner.Step = 1/30;
                    app.EndFrSpinner.Step = 1/30; 

                    if size(app.Arr_PtClds_Combined,2) == 3
                        app.StartFrameSlider.Limits = [app.Arr_PtClds_unfilterd(1,1),app.Arr_PtClds_unfilterd+0.1];
                        app.EndFrameSlider.Limits = [app.Arr_PtClds_unfilterd(1,1),app.Arr_PtClds_unfilterd+0.1];
                        app.StartFrSpinner.Limits = [app.Arr_PtClds_unfilterd(1,1),app.Arr_PtClds_unfilterd+0.1];
                        app.EndFrSpinner.Limits =  [app.Arr_PtClds_unfilterd(1,1),app.Arr_PtClds_unfilterd+0.1];
                        
                    else
                        maxTime = app.Arr_PtClds_unfilterd(1,end-2);
                        maxTime = round(maxTime+0.51);
                        mintime = app.Arr_PtClds_unfilterd(1,1);
                        minTime = round(mintime-0.49);

                        app.StartFrameSlider.Limits = [minTime,maxTime];
                        app.EndFrameSlider.Limits = [minTime,maxTime];
                        app.StartFrSpinner.Limits = [minTime,maxTime];
                        app.EndFrSpinner.Limits =  [minTime,maxTime];


                        [timeStart,~] = app.translateTimeAndFrameNum([],timeBackupStart,ArrTime);
                        [timeEnd,~] = app.translateTimeAndFrameNum([],timeBackupEnd,ArrTime);
                        app.StartFrameSlider.Value = timeStart;
                        app.StartFrSpinner.Value = timeStart;
                        app.StartFrameSliderLabel.Text = "Start Time";
                        app.EndFrameSlider.Value = timeEnd;
                        app.EndFrSpinner.Value = timeEnd;
                        app.EndFrameSliderLabel.Text = "End Time";
                    end
            end 
        end

        % Button pushed function: Opt_Contoursurf_Graphics
        function Opt_Contoursurf_GraphicsButtonPushed(app, event)
             if app.butto_graphicsOpt_state == 0
                 app.Opt_Contoursurf_Graphics.Icon = "images/ArrowUp.png";
                 app.GraphicsOptionsareaffectallplotsPanel.Visible = "on";
                 app.butto_graphicsOpt_state = 1;
             elseif app.butto_graphicsOpt_state == 1
                 app.Opt_Contoursurf_Graphics.Icon = "images/ArrowDown.png";
                 app.GraphicsOptionsareaffectallplotsPanel.Visible = "off";
                 app.butto_graphicsOpt_state = 0;              
             end                                    
        end

        % Selection changed function: AngleDerivButtonGroup
        function AngleDerivButtonGroupSelectionChanged(app, event)
            switch app.AngleDerivButtonGroup.SelectedObject
                case app.absolutevalueXYButton
                    title(app.UIAxes_Derivativ,"Absolut Angle")

                case app.AlphaXDirectionButton
                    title(app.UIAxes_Derivativ,"Alpha")

                case app.BetaYDirectionButton
                    title(app.UIAxes_Derivativ,"Beta")
            end            
        end

        % Button pushed function: Opt_AdvgraphicsOpt
        function Opt_AdvgraphicsOptButtonPushed(app, event)
             if app.butto_AdvgraphicsOpt_state == 0
                 app.Opt_AdvgraphicsOpt.Icon = "images/ArrowUp.png";
                 app.Panel_3.Visible = "on";
                 app.butto_AdvgraphicsOpt_state = 1;
             elseif app.butto_AdvgraphicsOpt_state == 1
                 app.Opt_AdvgraphicsOpt.Icon = "images/ArrowDown.png";
                 app.Panel_3.Visible = "off";
                 app.butto_AdvgraphicsOpt_state = 0;              
             end   
        end

        % Value changed function: IndexCheckBox
        function IndexCheckBoxValueChanged(app, event)
            fprintf('\nCallback: OriginofDataCheckBoxValueChanged \n')
            [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
            app.updatePreviewofData(app.Vect_DatToSave,cellArr)            
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            app.updatButtonColor
        end

        % Button pushed function: Opt_AlphaBeta
        function Opt_AlphaBetaButtonPushed(app, event)
            if app.button_alphaBetaOPt_state == 0
                 app.Opt_AlphaBeta.Icon = "images/ArrowUp.png";
                 app.Panel_AlphaBeta.Visible = "on";
                 app.button_alphaBetaOPt_state  = 1;
             elseif app.button_alphaBetaOPt_state  == 1
                 app.Opt_AlphaBeta.Icon = "images/ArrowDown.png";
                 app.Panel_AlphaBeta.Visible = "off";
                 app.button_alphaBetaOPt_state  = 0;              
             end    
        end

        % Button pushed function: Opt_DistResd
        function Opt_DistResdButtonPushed(app, event)
            if app.button_distredOpt_state == 0
                 app.Opt_DistResd.Icon = "images/ArrowUp.png";
                 app.Panel_DistResd.Visible = "on";
                 app.button_distredOpt_state  = 1;
             elseif app.button_distredOpt_state  == 1
                 app.Opt_DistResd.Icon = "images/ArrowDown.png";
                 app.Panel_DistResd.Visible = "off";
                 app.button_distredOpt_state  = 0;              
             end   
        end

        % Button pushed function: Opt_DistAngle
        function Opt_DistAngleButtonPushed(app, event)
            if app.button_distAngleOpt_state == 0
                 app.Opt_DistAngle.Icon = "images/ArrowUp.png";

                 app.OptAnglePanel.Visible = "on";
                 app.button_distAngleOpt_state  = 1;
                 app.OptAnglePanel.Enable = "on";

                 
% % %                  
% % %                  if app.DistAnglesTabgroup.SelectedTab == app.AnglesTopvewTab
% % %                      app.Panel_DistAngle.Visible = "on";
% % %                      app.button_distAngleOpt_state  = 1;
% % %                      app.Panel_DistAngle.Enable = "on";
% % %                  else
% % %                     app.OptAnglePanel.Visible = "on";
% % %                     app.button_distAngleOpt_state  = 1;
% % %                     app.OptAnglePanel.Enable = "on";
% % % 
% % %                  end

             elseif app.button_distAngleOpt_state  == 1
                 app.Opt_DistAngle.Icon = "images/ArrowDown.png";
                  
                 app.OptAnglePanel.Visible = "off";
                 app.button_distAngleOpt_state  = 0;
                 app.OptAnglePanel.Enable = "off";
                 
                 
% % %                  if app.DistAnglesTabgroup.SelectedTab == app.AnglesTopvewTab
% % %                      app.Panel_DistAngle.Visible = "off";
% % %                      app.button_distAngleOpt_state  = 0;
% % %                      app.Panel_DistAngle.Enable = "off";
% % %                   else
% % %                      app.OptAnglePanel.Visible = "off";
% % %                      app.button_distAngleOpt_state  = 0;
% % %                      app.OptAnglePanel.Enable = "off";
% % % 
% % %                  end                              
            end
        end

        % Button pushed function: Opt_Evaluate
        function Opt_EvaluateButtonPushed(app, event)
            if app.button_evaluOpt_state == 0
                 app.Opt_Evaluate.Icon = "images/ArrowUp.png";
                 app.OptEvaluationPanel.Visible = "on";
                 app.button_evaluOpt_state  = 1;
             elseif app.button_evaluOpt_state  == 1
                 app.Opt_Evaluate.Icon = "images/ArrowDown.png";
                 app.OptEvaluationPanel.Visible = "off";
                 app.button_evaluOpt_state = 0;              
             end        
        end

        % Button pushed function: UseSettingsOfBagButton
        function UseSettingsOfBagButtonPushed(app, event)
            app.timeOfRecordingSpinner.Value = app.filelength_bag;
            app.fpsOfRecordingSpinner.Value = app.FPS_bag;
        end

        % Value changed function: setxyCoordSpinner
        function setxyCoordSpinnerValueChanged(app, event)
           app.updatePlots 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           if app.button_stop_repeat_state == 0  
               switch app.TabGroup.SelectedTab
                    case app.ConvertbagTab
                        %update button to convert data state
                        app.StartConvertButton.BackgroundColor = app.color_Button_start;
                        app.StartConvertButton.Text ='Start Convert';
                        app.button_startConvert_bag_pushed_state = 0;
                        app.firstFrplotted_Bag = 0;
                        app.ContinueButton_Bag.Enable = 'off';                                              
                    case app.FilterDataTab  
                        %Update the Button
                        app.button_startFilter_pushed_state = 0;
                        app.StartFilterButton.BackgroundColor = app.color_Button_start;
                        app.StartFilterButton.Text ='Start Filter';       
                        %Clears Data
                        app.Arr_Ptclds_filterd = [];
                        app.firstFrplotted_Filter = 0;
                        app.ContinueButton_Filter.Enable = "off";                                                                                                                    
               end
               msgbox('Last conversion/ filtering can now be restarted ','Success',"custom",imread("images/sucsess.png"));
           else
               app.stopFlag = 1;
               msgbox('Execution was stoppd manualy!','Error','error');
           end                                               
        end

        % Value changed function: LocationSwitch
        function LocationSwitchValueChanged(app, event)
            app.updatePlots
        end

        % Value changed function: CalculateZDiff
        function CalculateZDiffValueChanged(app, event)

        end

        % Value changed function: CalAlphaBetaSwitch
        function CalAlphaBetaSwitchValueChanged(app, event)
      
        end

        % Value changed function: DistofResidualsSwitch
        function DistofResidualsSwitchValueChanged(app, event)

        end

        % Callback function
        function PlottingZDifferenceButtonGroupSelectionChanged(app, event)

        end

        % Button pushed function: Warning_NoFit, Warning_NoFit_DistAngle, 
        % ...and 1 other component
        function Warning_NoFit_2ButtonPushed(app, event)
            selection = uiconfirm(app.PouringLadleInspectUIFigure,'Select Fittype?','No Fittype Selected (set to "none")!','Icon','warning');
                        
            if selection == "OK"               
                app.button_evaluOpt_state  = 0;
                app.Opt_EvaluateButtonPushed

                app.FitSurfaceDropDown.Value = 'poly55';
                app.FitSurfaceDropDownValueChanged
            end

            %Adds blinking effect to dropdown
            for i = 1:5
                app.FitSurfaceDropDown.BackgroundColor = "r";
                pause(0.4)
                app.FitSurfaceDropDown.BackgroundColor = "#ff7878";
                pause(0.2)
            end
            app.FitSurfaceDropDown.BackgroundColor = "w";
        end

        % Button pushed function: ExportFitplaneButton
        function ExportFitplaneButtonPushed(app, event)
            %get filepath to save data
            filename = uiputfile(".csv"); 

            if isequal(filename,0) %if no filepath was selected
                msgbox('User selected Cancel','Error','error');                
            else %Stores data at given location                                    
                writematrix(app.Arr_PtCldFitpl,filename,'Delimiter',';');%saves Data
                msgbox(['file stored successfully as: ',filename],'Succsess');
            end
        end

        % Button pushed function: ExportZDiffButton
        function ExportZDiffButtonPushed(app, event)
                %get filepath to save data
                filename = uiputfile(".csv"); 

                if isequal(filename,0) %if no filepath was selected
                    msgbox('User selected Cancel','Error','error');                
                else %Stores data at given location                                    
                    writematrix(app.Arr_PtZdiff,filename,'Delimiter',';');%saves Data
                    msgbox(['file stored successfully as: ',filename],'Succsess');
                end
        end

        % Button pushed function: ExportAnglesButton
        function ExportAnglesButtonPushed(app, event)
                %get filepath to save data
                filename = uiputfile(".csv"); 

                if isequal(filename,0) %if no filepath was selected
                    msgbox('User selected Cancel','Error','error');                
                else %Stores data at given location                                    
                    writematrix(app.Arr_AnglePtCld,filename,'Delimiter',';');%saves Data
                    msgbox(['file stored successfully as: ',filename],'Succsess');
                end
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            %Adjusts Ylim of zDiffAtxy Axis ( higer xy higer z are expected)
            app.UiAxes_ZDiffAtXY.YLim = [-sqrt(app.XSpinner.Value^2+app.YSpinner.Value^2)*0.5-10+app.ZValueSpinner.Value,sqrt(app.XSpinner.Value^2+app.YSpinner.Value^2)*0.5+10+app.ZValueSpinner.Value];            
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            %Adjusts Ylim of zDiffAtxy Axis ( higer xy higer z are expected)
            app.UiAxes_ZDiffAtXY.YLim = [-sqrt(app.XSpinner.Value^2+app.YSpinner.Value^2)*0.5-10+app.ZValueSpinner.Value,sqrt(app.XSpinner.Value^2+app.YSpinner.Value^2)*0.5+10+app.ZValueSpinner.Value];
        end

        % Value changed function: ZDiffxyCheckBox
        function ZDiffxyCheckBoxValueChanged(app, event)
            fprintf('\nCallback: ZDiffxyCheckBoxValueChanged\n')
            [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
            app.updatePreviewofData(app.Vect_DatToSave,cellArr)
            
        end

        % Button pushed function: RefreshPlotsButton
        function RefreshPlotsButtonPushed(app, event)
            fprintf('\nCallback: RefreshPlotsButtonValueChanged \n')
            %Clear all plots
            cla(app.UIAxesPtcld);                       
            hold (app.UIAxesPtcld,"on");
            app.updatePlots();            
        end

        % Value changed function: FrSpinner
        function FrSpinnerValueChanged(app, event)
            app.FrameSlider.Value = app.FrSpinner.Value;
            app.FrameSliderValueChanged();            
        end

        % Value changed function: StartFrSpinner
        function StartFrSpinnerValueChanged(app, event)
            app.StartFrameSlider.Value = app.StartFrSpinner.Value;
            app.StartFrameSliderValueChanged();            
        end

        % Value changed function: EndFrSpinner
        function EndFrSpinnerValueChanged(app, event)
            app.EndFrameSlider.Value = app.EndFrSpinner.Value;
            app.EndFrameSliderValueChanged();            
        end

        % Value changed function: MinMaxzValsCheckBox
        function MinMaxzValsCheckBoxValueChanged(app, event)
            fprintf('\nCallback: MaxMinCheckBoxValueChanged \n')
            [app.Vect_DatToSave,~,cellArr] = app.DataToSave();
            app.updatePreviewofData(app.Vect_DatToSave,cellArr)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PouringLadleInspectUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {933, 933};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {505, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PouringLadleInspectUIFigure = uifigure('Visible', 'off');
            app.PouringLadleInspectUIFigure.Name = 'PouringLadle-Inspect';
            app.PouringLadleInspectUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'Icon_App.png');
            app.PouringLadleInspectUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PouringLadleInspectUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PouringLadleInspectLabel = uilabel(app.LeftPanel);
            app.PouringLadleInspectLabel.Text = 'PouringLadle-Inspect';
            app.ProgressBar_LPS = ProgressBar_LPS(app.LeftPanel);
            app.ProgressBar_LPS.Visible = 'off';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConvertbagTab = uitab(app.TabGroup);
            app.ConvertbagTab.Title = 'Convert .bag ';
            app.SelectedbagFileLabel = uilabel(app.ConvertbagTab);
            app.SelectedbagFileLabel.Text = 'Selected .bag File';
            app.SelectedbagFileEditField = uieditfield(app.ConvertbagTab, 'text');
            app.SelectedbagFileEditField.Editable = 'off';
            app.SelectedbagFileEditField.Value = 'no file selected!';
            app.SettingsOfTheRecordedBagFileTextArea = uitextarea(app.ConvertbagTab);
            app.SettingsOfTheRecordedBagFileTextArea.Editable = 'off';
            app.UseSettingsOfBagButton = uibutton(app.ConvertbagTab, 'push');
            app.UseSettingsOfBagButton.ButtonPushedFcn = createCallbackFcn(app, @UseSettingsOfBagButtonPushed, true);
            app.UseSettingsOfBagButton.Enable = 'off';
            app.UseSettingsOfBagButton.Text = 'Use settings of .bag';
            app.fpsOfRecordingSpinnerLabel = uilabel(app.ConvertbagTab);
            app.fpsOfRecordingSpinnerLabel.Text = 'FPS';
            app.fpsOfRecordingSpinner = uispinner(app.ConvertbagTab);
            app.fpsOfRecordingSpinner.ValueDisplayFormat = '%.0f [1/s]';
            app.fpsOfRecordingSpinner.ValueChangedFcn = createCallbackFcn(app, @fpsOfRecordingSpinnerValueChanged, true);
            app.fpsOfRecordingSpinner.Value = 30;
            app.PlaybackSpeedSpinnerLabel = uilabel(app.ConvertbagTab);
            app.PlaybackSpeedSpinnerLabel.Text = 'Playbackspeed';
            app.PlaybackSpeedSpinner = uispinner(app.ConvertbagTab);
            app.PlaybackSpeedSpinner.Step = 0.1;
            app.PlaybackSpeedSpinner.Limits = [0 2];
            app.PlaybackSpeedSpinner.ValueDisplayFormat = '%.2f ';
            app.PlaybackSpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedSpinnerValueChanged, true);
            app.PlaybackSpeedSpinner.Value = 1;
            app.timeOfRecordingSpinnerLabel = uilabel(app.ConvertbagTab);
            app.timeOfRecordingSpinnerLabel.Text = 'duration';
            app.timeOfRecordingSpinner = uispinner(app.ConvertbagTab);
            app.timeOfRecordingSpinner.ValueDisplayFormat = '%.0f [s]';
            app.timeOfRecordingSpinner.ValueChangedFcn = createCallbackFcn(app, @timeOfRecordingSpinnerValueChanged, true);
            app.timeOfRecordingSpinner.Value = 10;
            app.ConversionOptionsButton = uibutton(app.ConvertbagTab, 'push');
            app.ConversionOptionsButton.Tooltip = {'.'};
            app.ConversionOptionsButton.Text = 'Conversion Options';
            app.SetshapeofboundryBoxButton = uibutton(app.ConvertbagTab, 'push');
            app.SetshapeofboundryBoxButton.Tooltip = {''};
            app.SetshapeofboundryBoxButton.Text = 'Set shape of boundry Box';
            app.SetallxySpinnerLabel = uilabel(app.ConvertbagTab);
            app.SetallxySpinnerLabel.Text = 'Set all x,y';
            app.SetallxySpinner = uispinner(app.ConvertbagTab);
            app.SetallxySpinner.ValueDisplayFormat = '%.f [mm]';
            app.SetallxySpinner.ValueChangedFcn = createCallbackFcn(app, @SetallxySpinnerValueChanged, true);
            app.SetallxySpinner.Value = 30;
            app.y_minSpinnerLabel = uilabel(app.ConvertbagTab);
            app.y_minSpinnerLabel.Text = 'y_min';
            app.y_minSpinner = uispinner(app.ConvertbagTab);
            app.y_minSpinner.Limits = [-5000 5000];
            app.y_minSpinner.ValueDisplayFormat = '%.f [mm]';
            app.y_minSpinner.ValueChangedFcn = createCallbackFcn(app, @y_minSpinnerValueChanged, true);
            app.y_minSpinner.Value = -30;
            app.y_maxSpinnerLabel = uilabel(app.ConvertbagTab);
            app.y_maxSpinnerLabel.Text = 'y_max';
            app.y_maxSpinner = uispinner(app.ConvertbagTab);
            app.y_maxSpinner.Limits = [-5000 5000];
            app.y_maxSpinner.ValueDisplayFormat = '%.f [mm]';
            app.y_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @y_maxSpinnerValueChanged, true);
            app.y_maxSpinner.Value = 30;
            app.z_minSpinnerLabel = uilabel(app.ConvertbagTab);
            app.z_minSpinnerLabel.Text = 'z_min';
            app.z_minSpinner = uispinner(app.ConvertbagTab);
            app.z_minSpinner.Limits = [-10000 10000];
            app.z_minSpinner.ValueDisplayFormat = '%.f [mm]';
            app.z_minSpinner.ValueChangedFcn = createCallbackFcn(app, @z_minSpinnerValueChanged, true);
            app.z_minSpinner.Value = -240;
            app.z_maxSpinnerLabel = uilabel(app.ConvertbagTab);
            app.z_maxSpinnerLabel.Text = 'z_max';
            app.z_maxSpinner = uispinner(app.ConvertbagTab);
            app.z_maxSpinner.Limits = [-10000 10000];
            app.z_maxSpinner.ValueDisplayFormat = '%.f [mm]';
            app.z_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @z_maxSpinnerValueChanged, true);
            app.z_maxSpinner.Value = -170;
            app.x_minSpinnerLabel = uilabel(app.ConvertbagTab);
            app.x_minSpinnerLabel.Text = 'x_min ';
            app.x_minSpinner = uispinner(app.ConvertbagTab);
            app.x_minSpinner.Limits = [-5000 5000];
            app.x_minSpinner.ValueDisplayFormat = '%.f [mm]';
            app.x_minSpinner.ValueChangedFcn = createCallbackFcn(app, @x_minSpinnerValueChanged, true);
            app.x_minSpinner.Value = -30;
            app.x_maxSpinnerLabel = uilabel(app.ConvertbagTab);
            app.x_maxSpinnerLabel.Text = 'x_max ';
            app.x_maxSpinner = uispinner(app.ConvertbagTab);
            app.x_maxSpinner.Limits = [-5000 5000];
            app.x_maxSpinner.ValueDisplayFormat = '%.f [mm]';
            app.x_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @x_maxSpinnerValueChanged, true);
            app.x_maxSpinner.Value = 30;
            app.ProfilesDropDownLabel = uilabel(app.ConvertbagTab);
            app.ProfilesDropDownLabel.Text = 'Profiles';
            app.ProfilesDropDown = uidropdown(app.ConvertbagTab);
            app.ProfilesDropDown.Items = {'default', ''};
            app.ProfilesDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfilesDropDownValueChanged, true);
            app.ProfilesDropDown.Value = 'default';
            app.SelectCreateconversionProfileButton = uibutton(app.ConvertbagTab, 'push');
            app.SelectCreateconversionProfileButton.Tooltip = {''};
            app.SelectCreateconversionProfileButton.Text = ' Select/ Create conversion Profile';
            app.ContinueButton_Bag = uibutton(app.ConvertbagTab, 'push');
            app.ContinueButton_Bag.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton_BagPushed, true);
            app.ContinueButton_Bag.Icon = fullfile(pathToMLAPP, 'images', 'pngfind.com-green-arrow-png-right.png');
            app.ContinueButton_Bag.Enable = 'off';
            app.ContinueButton_Bag.Tooltip = {'Continue with filtering the data without saving it to a seperate File'};
            app.ContinueButton_Bag.Text = 'Continue ';
            app.Info_AreaBag = uibutton(app.ConvertbagTab, 'push');
            app.Info_AreaBag.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_AreaBag.Tooltip = {'set the shape of the boundry box. points outside this box will not be converted.'};
            app.Info_AreaBag.Text = '';
            app.Info_ConvBag = uibutton(app.ConvertbagTab, 'push');
            app.Info_ConvBag.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_ConvBag.Tooltip = {'select options for converting the bag file'};
            app.Info_ConvBag.Text = '';
            app.Info_ProfileBag = uibutton(app.ConvertbagTab, 'push');
            app.Info_ProfileBag.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_ProfileBag.Tooltip = {'Profiles contain settings to convert .bag into .csv. this settings can be adjusted and saved into a new Profile.'};
            app.Info_ProfileBag.Text = '';
            app.SelectfileandstarttheconverterButton = uibutton(app.ConvertbagTab, 'push');
            app.SelectfileandstarttheconverterButton.Tooltip = {''};
            app.SelectfileandstarttheconverterButton.Text = 'Select file and start the converter';
            app.ImportbagButton = uibutton(app.ConvertbagTab, 'push');
            app.ImportbagButton.ButtonPushedFcn = createCallbackFcn(app, @ImportbagButtonPushed, true);
            app.ImportbagButton.Text = 'Import .bag';
            app.PlotfirstframeButton = uibutton(app.ConvertbagTab, 'push');
            app.PlotfirstframeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfirstframeButtonPushed, true);
            app.PlotfirstframeButton.Enable = 'off';
            app.PlotfirstframeButton.Text = 'Plot first frame';
            app.StartConvertButton = uibutton(app.ConvertbagTab, 'push');
            app.StartConvertButton.ButtonPushedFcn = createCallbackFcn(app, @StartConvertButtonPushed, true);
            app.StartConvertButton.Enable = 'off';
            app.StartConvertButton.Text = 'Start Convert';
            app.SaveSettingsButton = uibutton(app.ConvertbagTab, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.ResetSettingsButton = uibutton(app.ConvertbagTab, 'push');
            app.ResetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSettingsButtonPushed, true);
            app.ResetSettingsButton.Text = 'Reset Settings';
            app.Info_Import_Bag = uibutton(app.ConvertbagTab, 'push');
            app.Info_Import_Bag.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_Import_Bag.Tooltip = {'Profiles contain settings to convert .bag into .csv. this settings can be adjusted and saved into a new Profile.'};
            app.Info_Import_Bag.Text = '';
            app.FilterDataTab = uitab(app.TabGroup);
            app.FilterDataTab.Title = 'Filter Data';
            app.SetLadleShapestlasfilterobjectButton = uibutton(app.FilterDataTab, 'push');
            app.SetLadleShapestlasfilterobjectButton.Tooltip = {''};
            app.SetLadleShapestlasfilterobjectButton.Text = 'Set Ladle Shape (.stl) as filter object';
            app.SelectedstlLabel = uilabel(app.FilterDataTab);
            app.SelectedstlLabel.Text = 'Selected .stl:';
            app.SelectedstlEditField = uieditfield(app.FilterDataTab, 'text');
            app.SelectedstlEditField.Editable = 'off';
            app.SelectedstlEditField.Value = 'no file selected!';
            app.dxSpinnerLabel = uilabel(app.FilterDataTab);
            app.dxSpinnerLabel.Enable = 'off';
            app.dxSpinnerLabel.Text = 'dx';
            app.dxSpinner = uispinner(app.FilterDataTab);
            app.dxSpinner.Limits = [-500 500];
            app.dxSpinner.ValueDisplayFormat = '%.f [mm]';
            app.dxSpinner.ValueChangedFcn = createCallbackFcn(app, @dxSpinnerValueChanged, true);
            app.dxSpinner.Enable = 'off';
            app.dySpinnerLabel = uilabel(app.FilterDataTab);
            app.dySpinnerLabel.Enable = 'off';
            app.dySpinnerLabel.Text = 'dy';
            app.dySpinner = uispinner(app.FilterDataTab);
            app.dySpinner.Limits = [-500 500];
            app.dySpinner.ValueDisplayFormat = '%.f [mm]';
            app.dySpinner.ValueChangedFcn = createCallbackFcn(app, @dySpinnerValueChanged, true);
            app.dySpinner.Enable = 'off';
            app.dzSpinnerLabel = uilabel(app.FilterDataTab);
            app.dzSpinnerLabel.Enable = 'off';
            app.dzSpinnerLabel.Text = 'dz';
            app.dzSpinner = uispinner(app.FilterDataTab);
            app.dzSpinner.Limits = [-1000 1000];
            app.dzSpinner.ValueDisplayFormat = '%.f [mm]';
            app.dzSpinner.ValueChangedFcn = createCallbackFcn(app, @dzSpinnerValueChanged, true);
            app.dzSpinner.Enable = 'off';
            app.dalphaSpinnerLabel = uilabel(app.FilterDataTab);
            app.dalphaSpinnerLabel.Enable = 'off';
            app.dalphaSpinnerLabel.Text = 'dalpha';
            app.dalphaSpinner = uispinner(app.FilterDataTab);
            app.dalphaSpinner.Limits = [-360 360];
            app.dalphaSpinner.ValueDisplayFormat = '%.f [°]';
            app.dalphaSpinner.ValueChangedFcn = createCallbackFcn(app, @dalphaSpinnerValueChanged, true);
            app.dalphaSpinner.Enable = 'off';
            app.dalphaSpinner.Value = 90;
            app.dbetaSpinnerLabel = uilabel(app.FilterDataTab);
            app.dbetaSpinnerLabel.Enable = 'off';
            app.dbetaSpinnerLabel.Text = 'dbeta';
            app.dbetaSpinner = uispinner(app.FilterDataTab);
            app.dbetaSpinner.Limits = [-360 360];
            app.dbetaSpinner.ValueDisplayFormat = '%.f [°]';
            app.dbetaSpinner.ValueChangedFcn = createCallbackFcn(app, @dbetaSpinnerValueChanged, true);
            app.dbetaSpinner.Enable = 'off';
            app.dgammaSpinnerLabel = uilabel(app.FilterDataTab);
            app.dgammaSpinnerLabel.Enable = 'off';
            app.dgammaSpinnerLabel.Text = 'dgamma';
            app.dgammaSpinner = uispinner(app.FilterDataTab);
            app.dgammaSpinner.Limits = [-360 360];
            app.dgammaSpinner.ValueDisplayFormat = '%.f [°]';
            app.dgammaSpinner.ValueChangedFcn = createCallbackFcn(app, @dgammaSpinnerValueChanged, true);
            app.dgammaSpinner.Enable = 'off';
            app.SelectedcsvFileEditFieldLabel = uilabel(app.FilterDataTab);
            app.SelectedcsvFileEditFieldLabel.Text = 'Selected .csv File';
            app.SelectedcsvFileEditField = uieditfield(app.FilterDataTab, 'text');
            app.SelectedcsvFileEditField.Editable = 'off';
            app.SelectedcsvFileEditField.Value = 'no file selected!';
            app.dxSpinner_2Label = uilabel(app.FilterDataTab);
            app.dxSpinner_2Label.Enable = 'off';
            app.dxSpinner_2Label.Text = 'dx';
            app.dxSpinner_Filter = uispinner(app.FilterDataTab);
            app.dxSpinner_Filter.Limits = [-5000 5000];
            app.dxSpinner_Filter.ValueDisplayFormat = '%.f [mm]';
            app.dxSpinner_Filter.ValueChangedFcn = createCallbackFcn(app, @dxSpinner_FilterValueChanged, true);
            app.dxSpinner_Filter.Enable = 'off';
            app.dySpinner_2Label = uilabel(app.FilterDataTab);
            app.dySpinner_2Label.Enable = 'off';
            app.dySpinner_2Label.Text = 'dy';
            app.dySpinner_Filter = uispinner(app.FilterDataTab);
            app.dySpinner_Filter.Limits = [-5000 5000];
            app.dySpinner_Filter.ValueDisplayFormat = '%.f [mm]';
            app.dySpinner_Filter.ValueChangedFcn = createCallbackFcn(app, @dySpinner_FilterValueChanged, true);
            app.dySpinner_Filter.Enable = 'off';
            app.dzSpinner_2Label = uilabel(app.FilterDataTab);
            app.dzSpinner_2Label.Enable = 'off';
            app.dzSpinner_2Label.Text = 'dz';
            app.dzSpinner_Filter = uispinner(app.FilterDataTab);
            app.dzSpinner_Filter.Limits = [-10000 10000];
            app.dzSpinner_Filter.ValueDisplayFormat = '%.f [mm]';
            app.dzSpinner_Filter.ValueChangedFcn = createCallbackFcn(app, @dzSpinner_FilterValueChanged, true);
            app.dzSpinner_Filter.Enable = 'off';
            app.dalphaSpinner_2Label = uilabel(app.FilterDataTab);
            app.dalphaSpinner_2Label.Enable = 'off';
            app.dalphaSpinner_2Label.Text = 'dalpha';
            app.dalphaSpinner_Filter = uispinner(app.FilterDataTab);
            app.dalphaSpinner_Filter.Limits = [-360 360];
            app.dalphaSpinner_Filter.ValueDisplayFormat = '%.f [°]';
            app.dalphaSpinner_Filter.ValueChangedFcn = createCallbackFcn(app, @dalphaSpinner_FilterValueChanged, true);
            app.dalphaSpinner_Filter.Enable = 'off';
            app.dbetaSpinner_2Label = uilabel(app.FilterDataTab);
            app.dbetaSpinner_2Label.Enable = 'off';
            app.dbetaSpinner_2Label.Text = 'dbeta';
            app.dbetaSpinner_Filter = uispinner(app.FilterDataTab);
            app.dbetaSpinner_Filter.Limits = [-360 360];
            app.dbetaSpinner_Filter.ValueDisplayFormat = '%.f [°]';
            app.dbetaSpinner_Filter.ValueChangedFcn = createCallbackFcn(app, @dbetaSpinner_FilterValueChanged, true);
            app.dbetaSpinner_Filter.Enable = 'off';
            app.dgammaSpinner_2Label = uilabel(app.FilterDataTab);
            app.dgammaSpinner_2Label.Enable = 'off';
            app.dgammaSpinner_2Label.Text = 'dgamma';
            app.dgammaSpinner_Filter = uispinner(app.FilterDataTab);
            app.dgammaSpinner_Filter.Limits = [-360 360];
            app.dgammaSpinner_Filter.ValueDisplayFormat = '%.f [°]';
            app.dgammaSpinner_Filter.ValueChangedFcn = createCallbackFcn(app, @dgammaSpinner_FilterValueChanged, true);
            app.dgammaSpinner_Filter.Enable = 'off';
            app.LadleShapeDropDownLabel = uilabel(app.FilterDataTab);
            app.LadleShapeDropDownLabel.Text = 'Ladle Shape';
            app.LadleShapeDropDown = uidropdown(app.FilterDataTab);
            app.LadleShapeDropDown.Items = {'Version 1', ''};
            app.LadleShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @LadleShapeDropDownValueChanged, true);
            app.LadleShapeDropDown.Value = 'Version 1';
            app.StartFrameSliderLabel = uilabel(app.FilterDataTab);
            app.StartFrameSliderLabel.Enable = 'off';
            app.StartFrameSliderLabel.Text = 'Start Frame';
            app.StartFrameSlider = uislider(app.FilterDataTab);
            app.StartFrameSlider.Limits = [1 10];
            app.StartFrameSlider.ValueChangedFcn = createCallbackFcn(app, @StartFrameSliderValueChanged, true);
            app.StartFrameSlider.Enable = 'off';
            app.StartFrameSlider.Value = 1;
            app.EndFrameSliderLabel = uilabel(app.FilterDataTab);
            app.EndFrameSliderLabel.Enable = 'off';
            app.EndFrameSliderLabel.Text = 'End Frame';
            app.EndFrameSlider = uislider(app.FilterDataTab);
            app.EndFrameSlider.Limits = [1 10];
            app.EndFrameSlider.ValueChangedFcn = createCallbackFcn(app, @EndFrameSliderValueChanged, true);
            app.EndFrameSlider.Enable = 'off';
            app.EndFrameSlider.Value = 1;
            app.SetthePositionofthepointcloudButton = uibutton(app.FilterDataTab, 'push');
            app.SetthePositionofthepointcloudButton.Tooltip = {''};
            app.SetthePositionofthepointcloudButton.Text = 'Set the Position of the pointcloud';
            app.SelectthestartandendframetobefilterdButton = uibutton(app.FilterDataTab, 'push');
            app.SelectthestartandendframetobefilterdButton.Tooltip = {''};
            app.SelectthestartandendframetobefilterdButton.Text = 'Select the start and end frame to be filterd';
            app.ContinueButton_Filter = uibutton(app.FilterDataTab, 'push');
            app.ContinueButton_Filter.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton_FilterPushed, true);
            app.ContinueButton_Filter.Icon = fullfile(pathToMLAPP, 'images', 'pngfind.com-green-arrow-png-right.png');
            app.ContinueButton_Filter.Enable = 'off';
            app.ContinueButton_Filter.Tooltip = {'Continue with evaluating the data without saving it to a seperate File'};
            app.ContinueButton_Filter.Text = 'Continue ';
            app.DropDownFramesliderFilter = uidropdown(app.FilterDataTab);
            app.DropDownFramesliderFilter.Items = {'Frame', 'Time', '', ''};
            app.DropDownFramesliderFilter.ValueChangedFcn = createCallbackFcn(app, @DropDownFramesliderFilterValueChanged, true);
            app.DropDownFramesliderFilter.Enable = 'off';
            app.DropDownFramesliderFilter.Value = 'Frame';
            app.Info_LocationCsv = uibutton(app.FilterDataTab, 'push');
            app.Info_LocationCsv.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_LocationCsv.Tooltip = {'set the location of the pointcloud. The rotation is defined as Euler X,Y,Z (Alpha,Beta,Gamma).'};
            app.Info_LocationCsv.Text = '';
            app.Info_SelectLadle = uibutton(app.FilterDataTab, 'push');
            app.Info_SelectLadle.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_SelectLadle.Tooltip = {'Select a new ladle.It is used to filter the recordeded pointclouds. The rotation is defined as Euler X,Y,Z (Alpha,Beta,Gamma).'};
            app.Info_SelectLadle.Text = '';
            app.Info_CSVTimerange = uibutton(app.FilterDataTab, 'push');
            app.Info_CSVTimerange.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_CSVTimerange.Tooltip = {'Select the first and last frame that shoud be a part of the filterd dataset.'};
            app.Info_CSVTimerange.Text = '';
            app.SelectfileandstartthefilterButton = uibutton(app.FilterDataTab, 'push');
            app.SelectfileandstartthefilterButton.Tooltip = {''};
            app.SelectfileandstartthefilterButton.Text = 'Select file and start the filter';
            app.Info_ImportCSV = uibutton(app.FilterDataTab, 'push');
            app.Info_ImportCSV.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_ImportCSV.Tooltip = {'set the location of the pointcloud. The rotation is defined as Euler X,Y,Z (Alpha,Beta,Gamma).'};
            app.Info_ImportCSV.Text = '';
            app.ImportcsvButton = uibutton(app.FilterDataTab, 'push');
            app.ImportcsvButton.ButtonPushedFcn = createCallbackFcn(app, @ImportcsvButtonPushed, true);
            app.ImportcsvButton.Text = 'Import .csv';
            app.StartFilterButton = uibutton(app.FilterDataTab, 'push');
            app.StartFilterButton.ButtonPushedFcn = createCallbackFcn(app, @StartFilterButtonPushed, true);
            app.StartFilterButton.Enable = 'off';
            app.StartFilterButton.Text = 'Start Filter';
            app.SelectnewLadleButton = uibutton(app.FilterDataTab, 'push');
            app.SelectnewLadleButton.ButtonPushedFcn = createCallbackFcn(app, @SelectnewLadleButtonPushed, true);
            app.SelectnewLadleButton.Text = 'Select new Ladle ';
            app.removeLadleButton = uibutton(app.FilterDataTab, 'push');
            app.removeLadleButton.ButtonPushedFcn = createCallbackFcn(app, @removeLadleButtonPushed, true);
            app.removeLadleButton.Enable = 'off';
            app.removeLadleButton.Text = 'remove Ladle';
            app.plotLadleButton = uibutton(app.FilterDataTab, 'push');
            app.plotLadleButton.ButtonPushedFcn = createCallbackFcn(app, @plotLadleButtonPushed, true);
            app.plotLadleButton.Enable = 'off';
            app.plotLadleButton.Text = 'plot Ladle';
            app.StartFrSpinner = uispinner(app.FilterDataTab);
            app.StartFrSpinner.Limits = [0 Inf];
            app.StartFrSpinner.ValueChangedFcn = createCallbackFcn(app, @StartFrSpinnerValueChanged, true);
            app.StartFrSpinner.Enable = 'off';
            app.StartFrSpinner.Value = 1;
            app.EndFrSpinner = uispinner(app.FilterDataTab);
            app.EndFrSpinner.Limits = [0 Inf];
            app.EndFrSpinner.ValueChangedFcn = createCallbackFcn(app, @EndFrSpinnerValueChanged, true);
            app.EndFrSpinner.Enable = 'off';
            app.EvaluatePlaybackTab = uitab(app.TabGroup);
            app.EvaluatePlaybackTab.Title = 'Evaluate & Playback';
            app.FrSpinner = uispinner(app.EvaluatePlaybackTab);
            app.FrSpinner.Limits = [0 Inf];
            app.FrSpinner.ValueChangedFcn = createCallbackFcn(app, @FrSpinnerValueChanged, true);
            app.FrSpinner.Enable = 'off';
            app.SelectedcsvFilesEditFieldLabel = uilabel(app.EvaluatePlaybackTab);
            app.SelectedcsvFilesEditFieldLabel.Text = 'Selected .csv File(s)';
            app.Selected1csvFilesEditField = uieditfield(app.EvaluatePlaybackTab, 'text');
            app.Selected1csvFilesEditField.Editable = 'off';
            app.Selected1csvFilesEditField.Value = 'no 1. file selected!';
            app.Select1FileButton = uibutton(app.EvaluatePlaybackTab, 'push');
            app.Select1FileButton.ButtonPushedFcn = createCallbackFcn(app, @Select1FileButtonPushed, true);
            app.Select1FileButton.Tooltip = {'.bag and .csv files can be evalueated but only .bag files offer the possibility to evaluate the distribution of residuals of the fitted plane!'};
            app.Select1FileButton.Text = 'Select 1. File';
            app.TabGroup2 = uitabgroup(app.EvaluatePlaybackTab);
            app.AlphaBetaTab = uitab(app.TabGroup2);
            app.AlphaBetaTab.Title = 'Alpha & Beta';
            app.UiAxisAlpha = uiaxes(app.AlphaBetaTab);
            xlabel(app.UiAxisAlpha, '\fontsize{12}[s]')
            ylabel(app.UiAxisAlpha, '\fontsize{12}\alpha [deg] rotation around X')
            zlabel(app.UiAxisAlpha, 'Z')
            app.UiAxisAlpha.XLim = [0 10];
            app.UiAxisAlpha.YLim = [-25 25];
            app.UiAxisAlpha.ZLim = [0 1];
            app.UIAxesBeta = uiaxes(app.AlphaBetaTab);
            xlabel(app.UIAxesBeta, '\fontsize{12}[s]')
            ylabel(app.UIAxesBeta, '\fontsize{12}\beta [deg] rotatiom around Y')
            zlabel(app.UIAxesBeta, 'Z')
            app.UIAxesBeta.XLim = [0 10];
            app.UIAxesBeta.YLim = [-25 25];
            app.UIAxesBeta.ZLim = [0 1];
            app.Panel_AlphaBeta = uipanel(app.AlphaBetaTab);
            app.Panel_AlphaBeta.Title = '   ';
            app.Panel_AlphaBeta.Visible = 'off';
            app.PlottingalpabetaButtonGroup = uibuttongroup(app.Panel_AlphaBeta);
            app.PlottingalpabetaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottingalpabetaButtonGroupSelectionChanged, true);
            app.PlottingalpabetaButtonGroup.Title = '  Plotting alpa & beta';
            app.pointsonlyButton = uiradiobutton(app.PlottingalpabetaButtonGroup);
            app.pointsonlyButton.Text = 'points only';
            app.pointsonlyButton.Value = true;
            app.linesonlyButton = uiradiobutton(app.PlottingalpabetaButtonGroup);
            app.linesonlyButton.Text = 'lines only';
            app.pointslinesButton = uiradiobutton(app.PlottingalpabetaButtonGroup);
            app.pointslinesButton.Text = 'points & lines ';
            app.xydistanceto00Label = uilabel(app.Panel_AlphaBeta);
            app.xydistanceto00Label.Text = 'xy distance to (0,0)';
            app.setxyCoordSpinner = uispinner(app.Panel_AlphaBeta);
            app.setxyCoordSpinner.Limits = [-500 500];
            app.setxyCoordSpinner.ValueDisplayFormat = '%.f [mm]';
            app.setxyCoordSpinner.ValueChangedFcn = createCallbackFcn(app, @setxyCoordSpinnerValueChanged, true);
            app.setxyCoordSpinner.Value = 20;
            app.SetLocationtocalculateAlphaBetaButton = uibutton(app.Panel_AlphaBeta, 'push');
            app.SetLocationtocalculateAlphaBetaButton.Tooltip = {''};
            app.SetLocationtocalculateAlphaBetaButton.Text = 'Set Location to calculate Alpha & Beta';
            app.Info_TypeAngleDeriv_2 = uibutton(app.Panel_AlphaBeta, 'push');
            app.Info_TypeAngleDeriv_2.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_TypeAngleDeriv_2.Text = '';
            app.setdelta_xySpinnerLabel = uilabel(app.Panel_AlphaBeta);
            app.setdelta_xySpinnerLabel.Text = 'set delta_xy:';
            app.setdelta_xySpinner = uispinner(app.Panel_AlphaBeta);
            app.setdelta_xySpinner.ValueDisplayFormat = '%.f [mm]';
            app.setdelta_xySpinner.ValueChangedFcn = createCallbackFcn(app, @setdelta_xySpinnerValueChanged, true);
            app.setdelta_xySpinner.Value = 15;
            app.Opt_AlphaBeta = uibutton(app.AlphaBetaTab, 'push');
            app.Opt_AlphaBeta.ButtonPushedFcn = createCallbackFcn(app, @Opt_AlphaBetaButtonPushed, true);
            app.Opt_AlphaBeta.Icon = fullfile(pathToMLAPP, 'images', 'ArrowDown.png');
            app.Opt_AlphaBeta.Text = '';
            app.ContourOptionsDropDownLabel_3 = uilabel(app.AlphaBetaTab);
            app.ContourOptionsDropDownLabel_3.Text = 'Options';
            app.DerivativAngleTab = uitab(app.TabGroup2);
            app.DerivativAngleTab.Title = 'Derivativ/ Angle';
            app.Panel_DistAngle = uipanel(app.DerivativAngleTab);
            app.Panel_DistAngle.Title = '   ';
            app.Panel_DistAngle.Visible = 'off';
            app.DistAnglesTabgroup = uitabgroup(app.DerivativAngleTab);
            app.AnglesTopvewTab = uitab(app.DistAnglesTabgroup);
            app.AnglesTopvewTab.Title = 'Angles  Topview';
            app.UIAxes_Derivativ = uiaxes(app.AnglesTopvewTab);
            title(app.UIAxes_Derivativ, 'gradient/ angle[°]')
            xlabel(app.UIAxes_Derivativ, 'X [mm]')
            ylabel(app.UIAxes_Derivativ, 'Y [mm]')
            zlabel(app.UIAxes_Derivativ, 'Z')
            app.UIAxes_Derivativ.XLim = [0 1];
            app.UIAxes_Derivativ.YLim = [0 1];
            app.UIAxes_Derivativ.ZLim = [-30 30];
            colormap(app.UIAxes_Derivativ, 'turbo')
            app.AngleatxyTab = uitab(app.DistAnglesTabgroup);
            app.AngleatxyTab.Title = 'Angle at x,y';
            app.UiAxisAlpha_xy = uiaxes(app.AngleatxyTab);
            xlabel(app.UiAxisAlpha_xy, '\fontsize{12}[s]')
            ylabel(app.UiAxisAlpha_xy, '\fontsize{12}\alpha [deg] rotation around X')
            zlabel(app.UiAxisAlpha_xy, 'Z')
            app.UiAxisAlpha_xy.XLim = [0 10];
            app.UiAxisAlpha_xy.YLim = [-25 25];
            app.UiAxisAlpha_xy.ZLim = [0 1];
            app.UiAxisBeta_xy = uiaxes(app.AngleatxyTab);
            xlabel(app.UiAxisBeta_xy, '\fontsize{12}[s]')
            ylabel(app.UiAxisBeta_xy, '\fontsize{12}\beta [deg] rotation around Y')
            zlabel(app.UiAxisBeta_xy, 'Z')
            app.UiAxisBeta_xy.XLim = [0 10];
            app.UiAxisBeta_xy.YLim = [-25 25];
            app.UiAxisBeta_xy.ZLim = [0 1];
            app.DistOfAnglesTab = uitab(app.DistAnglesTabgroup);
            app.DistOfAnglesTab.Title = 'Distribution of Angles';
            app.UIAxesDistAngles = uiaxes(app.DistOfAnglesTab);
            title(app.UIAxesDistAngles, 'Distribution of Angles')
            xlabel(app.UIAxesDistAngles, 'grad [°]')
            ylabel(app.UIAxesDistAngles, ' xi []')
            zlabel(app.UIAxesDistAngles, 'Z')
            app.UIAxesDistAngles.XLim = [-35 35];
            app.UIAxesDistAngles.YLim = [0 500];
            app.UIAxesDistAngles.ZLim = [0 1];
            app.UIAxesDistAngles.XLimitMethod = 'tight';
            app.UIAxesDistAngles.YLimitMethod = 'tight';
            app.UIAxesDistAngles.ZLimitMethod = 'tight';
            app.OptAnglePanel = uipanel(app.DerivativAngleTab);
            app.OptAnglePanel.Enable = 'off';
            app.OptAnglePanel.Title = '          .';
            app.OptAnglePanel.Visible = 'off';
            app.NumberofLinesSpinner_2Label = uilabel(app.OptAnglePanel);
            app.NumberofLinesSpinner_2Label.Text = 'Number of Lines';
            app.NumberofLinesSpinner_2 = uispinner(app.OptAnglePanel);
            app.NumberofLinesSpinner_2.Limits = [0 Inf];
            app.NumberofLinesSpinner_2.ValueChangedFcn = createCallbackFcn(app, @NumberofLinesSpinner_2ValueChanged, true);
            app.NumberofLinesSpinner_2.Enable = 'off';
            app.NumberofLinesSpinner_2.Visible = 'off';
            app.NumberofLinesSpinner_2.Value = 10;
            app.FixedValuesEditFieldLabel_2 = uilabel(app.OptAnglePanel);
            app.FixedValuesEditFieldLabel_2.Text = 'Fixed Values';
            app.FixedValuesEditField_2 = uieditfield(app.OptAnglePanel, 'text');
            app.FixedValuesEditField_2.ValueChangedFcn = createCallbackFcn(app, @FixedValuesEditField_2ValueChanged, true);
            app.FixedValuesEditField_2.Value = '[-35,-30,-25,-20,-18,-16,-14,-12,-10,-9,-8,-7,-6,-5,-4,-3,-2,-1,0,1,2,3,4,5,6,7,8,9,10,12,14,16,18,20,25,30,35]';
            app.MinSpinner_2Label = uilabel(app.OptAnglePanel);
            app.MinSpinner_2Label.Text = 'Min:';
            app.MinSpinner_2 = uispinner(app.OptAnglePanel);
            app.MinSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_2ValueChanged, true);
            app.MinSpinner_2.Value = -30;
            app.MaxSpinner_2Label = uilabel(app.OptAnglePanel);
            app.MaxSpinner_2Label.Text = 'Max:';
            app.MaxSpinner_2 = uispinner(app.OptAnglePanel);
            app.MaxSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_2ValueChanged, true);
            app.MaxSpinner_2.Value = 30;
            app.SetMaxMinvaluestobeColorizedButton_2 = uibutton(app.OptAnglePanel, 'push');
            app.SetMaxMinvaluestobeColorizedButton_2.Tooltip = {''};
            app.SetMaxMinvaluestobeColorizedButton_2.Text = 'Set Max & Min values to be Colorized';
            app.ChangetheWayhowLinesareplottedButton_2 = uibutton(app.OptAnglePanel, 'push');
            app.ChangetheWayhowLinesareplottedButton_2.Tooltip = {''};
            app.ChangetheWayhowLinesareplottedButton_2.Text = 'Change the Way how Lines are plotted';
            app.MethodofcreatingButtonGroup_2 = uibuttongroup(app.OptAnglePanel);
            app.MethodofcreatingButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @MethodofcreatingButtonGroup_2SelectionChanged, true);
            app.MethodofcreatingButtonGroup_2.Title = 'Method of creating levels to display contour lines';
            app.UseNumberofLinesButton_2 = uiradiobutton(app.MethodofcreatingButtonGroup_2);
            app.UseNumberofLinesButton_2.Text = 'Use Number of Lines';
            app.UseFixdValuesButton_2 = uiradiobutton(app.MethodofcreatingButtonGroup_2);
            app.UseFixdValuesButton_2.Text = 'Use Fixd Values ';
            app.UseFixdValuesButton_2.Value = true;
            app.SetOptionsfortypeofDerivativsButton = uibutton(app.OptAnglePanel, 'push');
            app.SetOptionsfortypeofDerivativsButton.Tooltip = {''};
            app.SetOptionsfortypeofDerivativsButton.Text = 'Set Options for type of Derivativs';
            app.Info_Color_colorbar_Ang = uibutton(app.OptAnglePanel, 'push');
            app.Info_Color_colorbar_Ang.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_Color_colorbar_Ang.Text = '';
            app.Info_TypeAngleDeriv_3 = uibutton(app.OptAnglePanel, 'push');
            app.Info_TypeAngleDeriv_3.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_TypeAngleDeriv_3.Text = '';
            app.Info_Anglelevels = uibutton(app.OptAnglePanel, 'push');
            app.Info_Anglelevels.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_Anglelevels.Text = '';
            app.AngleDerivButtonGroup = uibuttongroup(app.OptAnglePanel);
            app.AngleDerivButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AngleDerivButtonGroupSelectionChanged, true);
            app.AngleDerivButtonGroup.Title = 'Select which parts of the Derivativs shoud be visualized';
            app.AlphaXDirectionButton = uiradiobutton(app.AngleDerivButtonGroup);
            app.AlphaXDirectionButton.Text = 'Alpha/X-Direction';
            app.BetaYDirectionButton = uiradiobutton(app.AngleDerivButtonGroup);
            app.BetaYDirectionButton.Text = 'Beta/Y-Direction';
            app.absolutevalueXYButton = uiradiobutton(app.AngleDerivButtonGroup);
            app.absolutevalueXYButton.Text = 'absolute value (XY)';
            app.absolutevalueXYButton.Value = true;
            app.PlottingAnglesButtonGroup = uibuttongroup(app.OptAnglePanel);
            app.PlottingAnglesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottingAnglesButtonGroupSelectionChanged, true);
            app.PlottingAnglesButtonGroup.Title = 'Plotting Angles';
            app.AlphaonlyxAxisButton = uiradiobutton(app.PlottingAnglesButtonGroup);
            app.AlphaonlyxAxisButton.Text = 'Alpha only (x-Axis)';
            app.AlphaonlyxAxisButton.Value = true;
            app.Betaonlyy_AxisButton = uiradiobutton(app.PlottingAnglesButtonGroup);
            app.Betaonlyy_AxisButton.Text = 'Beta only (y_Axis)';
            app.AlphaBetaButton = uiradiobutton(app.PlottingAnglesButtonGroup);
            app.AlphaBetaButton.Text = 'Alpha & Beta';
            app.SetOptionsforHistogramButton = uibutton(app.OptAnglePanel, 'push');
            app.SetOptionsforHistogramButton.Tooltip = {''};
            app.SetOptionsforHistogramButton.Text = 'Set Options for Histogram';
            app.Info_TypeAngleDeriv_4 = uibutton(app.OptAnglePanel, 'push');
            app.Info_TypeAngleDeriv_4.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_TypeAngleDeriv_4.Text = '';
            app.Image5 = uiimage(app.OptAnglePanel);
            app.Image5.ImageSource = 'LineUi.png';
            app.SetOptionsfortheContourPlotLabel = uilabel(app.OptAnglePanel);
            app.SetOptionsfortheContourPlotLabel.Text = '  Set Options for the Contour Plot';
            app.SetOptionsfortheHistogrammLabel = uilabel(app.OptAnglePanel);
            app.SetOptionsfortheHistogrammLabel.Text = '  Set Options for the Histogramm';
            app.Image5_2 = uiimage(app.OptAnglePanel);
            app.Image5_2.ImageSource = 'LineUi.png';
            app.Image5_3 = uiimage(app.OptAnglePanel);
            app.Image5_3.ImageSource = 'LineUi.png';
            app.SetOptionsforPlottingAnglesoverTimeLabel = uilabel(app.OptAnglePanel);
            app.SetOptionsforPlottingAnglesoverTimeLabel.Text = '  Set Options for Plotting Angles over Time';
            app.DisplayzDiffatxyovertimeButton_2 = uibutton(app.OptAnglePanel, 'push');
            app.DisplayzDiffatxyovertimeButton_2.Tooltip = {''};
            app.DisplayzDiffatxyovertimeButton_2.Text = 'Display zDiff at x,y over time';
            app.Info_locatonEvaluate_2 = uibutton(app.OptAnglePanel, 'push');
            app.Info_locatonEvaluate_2.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_locatonEvaluate_2.Tooltip = {'Set the visualization style of the z difference. The defult option displays the Z difference as contour plot. An other option is that the z-difference at user specifiyd x,y values is plotted against the time'};
            app.Info_locatonEvaluate_2.Text = '';
            app.XYcoordinatestoevaluateatLabel_2 = uilabel(app.OptAnglePanel);
            app.XYcoordinatestoevaluateatLabel_2.Text = 'X,Y coordinates to evaluate at';
            app.XSpinner_2Label = uilabel(app.OptAnglePanel);
            app.XSpinner_2Label.Text = 'X';
            app.XSpinner_2 = uispinner(app.OptAnglePanel);
            app.YSpinner_2Label = uilabel(app.OptAnglePanel);
            app.YSpinner_2Label.Text = 'Y';
            app.YSpinner_2 = uispinner(app.OptAnglePanel);
            app.Warning_NoFit_DistAngle = uibutton(app.DerivativAngleTab, 'push');
            app.Warning_NoFit_DistAngle.ButtonPushedFcn = createCallbackFcn(app, @Warning_NoFit_2ButtonPushed, true);
            app.Warning_NoFit_DistAngle.Icon = fullfile(pathToMLAPP, 'images', 'Warning.png');
            app.Warning_NoFit_DistAngle.Tooltip = {'This Tab only Displays Data if an Fitplane is Selected to be calculated !'};
            app.Warning_NoFit_DistAngle.Text = '';
            app.Opt_DistAngle = uibutton(app.DerivativAngleTab, 'push');
            app.Opt_DistAngle.ButtonPushedFcn = createCallbackFcn(app, @Opt_DistAngleButtonPushed, true);
            app.Opt_DistAngle.Icon = fullfile(pathToMLAPP, 'images', 'ArrowDown.png');
            app.Opt_DistAngle.Text = '';
            app.DistAngleOptionsDropDownLabel = uilabel(app.DerivativAngleTab);
            app.DistAngleOptionsDropDownLabel.Text = 'Options';
            app.ZDifferenceTab = uitab(app.TabGroup2);
            app.ZDifferenceTab.Title = 'Z Difference ';
            app.ZDiffVisualizVariants = uitabgroup(app.ZDifferenceTab);
            app.ContourplotTab = uitab(app.ZDiffVisualizVariants);
            app.ContourplotTab.Title = 'Contour plot';
            app.UIAxes_z_diff = uiaxes(app.ContourplotTab);
            title(app.UIAxes_z_diff, 'Z-Difference')
            xlabel(app.UIAxes_z_diff, 'X [mm]')
            ylabel(app.UIAxes_z_diff, 'Y [mm]')
            zlabel(app.UIAxes_z_diff, 'Z')
            app.UIAxes_z_diff.XLim = [0 1];
            app.UIAxes_z_diff.YLim = [0 1];
            app.UIAxes_z_diff.ZLim = [-35 35];
            app.UIAxes_z_diff.CLim = [-30 30];
            colormap(app.UIAxes_z_diff, 'turbo')
            app.ZDiffovertimeTab_2 = uitab(app.ZDiffVisualizVariants);
            app.ZDiffovertimeTab_2.Title = 'ZDiff over time';
            app.UiAxes_ZDiffAtXY = uiaxes(app.ZDiffovertimeTab_2);
            xlabel(app.UiAxes_ZDiffAtXY, '\fontsize{12}[s]')
            ylabel(app.UiAxes_ZDiffAtXY, '\fontsize{12}\alpha [deg] z-Difference [mm]')
            zlabel(app.UiAxes_ZDiffAtXY, 'Z')
            app.UiAxes_ZDiffAtXY.XLim = [0 10];
            app.UiAxes_ZDiffAtXY.ZLim = [0 1];
            app.UiAxes_ZDiffAtXY.Visible = 'off';
            app.distZdiff = uitab(app.ZDiffVisualizVariants);
            app.distZdiff.Title = 'Distribution of zDiff';
            app.UIAxesResiduals_ZDiff = uiaxes(app.distZdiff);
            title(app.UIAxesResiduals_ZDiff, 'Distribution of Residuals')
            xlabel(app.UIAxesResiduals_ZDiff, 'd [mm]')
            ylabel(app.UIAxesResiduals_ZDiff, 'xi []')
            zlabel(app.UIAxesResiduals_ZDiff, 'Z')
            app.UIAxesResiduals_ZDiff.XLimitMethod = 'tight';
            app.UIAxesResiduals_ZDiff.YLimitMethod = 'tight';
            app.UIAxesResiduals_ZDiff.ZLimitMethod = 'tight';
            app.TimeDifferenceEditFieldLabel = uilabel(app.ZDifferenceTab);
            app.TimeDifferenceEditFieldLabel.Text = 'Time Difference';
            app.OptionsZdiff = uipanel(app.ZDifferenceTab);
            app.OptionsZdiff.Enable = 'off';
            app.OptionsZdiff.Title = '          .';
            app.OptionsZdiff.Visible = 'off';
            app.MethodofcreatingButtonGroup = uibuttongroup(app.OptionsZdiff);
            app.MethodofcreatingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodofcreatingButtonGroupSelectionChanged, true);
            app.MethodofcreatingButtonGroup.Title = 'Method of creating levels to display contour lines';
            app.UseNumberofLinesButton = uiradiobutton(app.MethodofcreatingButtonGroup);
            app.UseNumberofLinesButton.Text = 'Use Number of Lines';
            app.UseFixdValuesButton = uiradiobutton(app.MethodofcreatingButtonGroup);
            app.UseFixdValuesButton.Text = 'Use Fixd Values ';
            app.UseFixdValuesButton.Value = true;
            app.NumberofLinesSpinnerLabel = uilabel(app.OptionsZdiff);
            app.NumberofLinesSpinnerLabel.Text = 'Number of Lines';
            app.NumberofLinesSpinner = uispinner(app.OptionsZdiff);
            app.NumberofLinesSpinner.Limits = [0 Inf];
            app.NumberofLinesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofLinesSpinnerValueChanged, true);
            app.NumberofLinesSpinner.Enable = 'off';
            app.NumberofLinesSpinner.Visible = 'off';
            app.NumberofLinesSpinner.Value = 10;
            app.FixedValuesEditFieldLabel = uilabel(app.OptionsZdiff);
            app.FixedValuesEditFieldLabel.Text = 'Fixed Values';
            app.FixedValuesEditField = uieditfield(app.OptionsZdiff, 'text');
            app.FixedValuesEditField.ValueChangedFcn = createCallbackFcn(app, @FixedValuesEditFieldValueChanged, true);
            app.FixedValuesEditField.Value = '[-35,-30,-25,-20,-18,-16,-14,-12,-10,-9,-8,-7,-6,-5,-4,-3,-2,-1,0,1,2,3,4,5,6,7,8,9,10,12,14,16,18,20,25,30,35]';
            app.MinLabel = uilabel(app.OptionsZdiff);
            app.MinLabel.Text = 'Min:';
            app.MinSpinner = uispinner(app.OptionsZdiff);
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MinSpinner.Value = -35;
            app.MaxLabel = uilabel(app.OptionsZdiff);
            app.MaxLabel.Text = 'Max:';
            app.MaxSpinner = uispinner(app.OptionsZdiff);
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MaxSpinner.Value = 35;
            app.SetMaxMinvaluestobeColorizedButton = uibutton(app.OptionsZdiff, 'push');
            app.SetMaxMinvaluestobeColorizedButton.Tooltip = {''};
            app.SetMaxMinvaluestobeColorizedButton.Text = 'Set Max & Min values to be Colorized';
            app.ChangetheWayhowLinesareplottedButton = uibutton(app.OptionsZdiff, 'push');
            app.ChangetheWayhowLinesareplottedButton.Tooltip = {''};
            app.ChangetheWayhowLinesareplottedButton.Text = 'Change the Way how Lines are plotted';
            app.Info_LevelsZDiff = uibutton(app.OptionsZdiff, 'push');
            app.Info_LevelsZDiff.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_LevelsZDiff.Text = '';
            app.Info_colorspaceZdiff = uibutton(app.OptionsZdiff, 'push');
            app.Info_colorspaceZdiff.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_colorspaceZdiff.Text = '';
            app.DisplayzDiffatxyovertimeButton = uibutton(app.OptionsZdiff, 'push');
            app.DisplayzDiffatxyovertimeButton.Tooltip = {''};
            app.DisplayzDiffatxyovertimeButton.Text = 'Display zDiff at x,y over time';
            app.Info_locatonEvaluate = uibutton(app.OptionsZdiff, 'push');
            app.Info_locatonEvaluate.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_locatonEvaluate.Tooltip = {'Set the visualization style of the z difference. The defult option displays the Z difference as contour plot. An other option is that the z-difference at user specifiyd x,y values is plotted against the time'};
            app.Info_locatonEvaluate.Text = '';
            app.XYcoordinatestoevaluateatLabel = uilabel(app.OptionsZdiff);
            app.XYcoordinatestoevaluateatLabel.Text = 'X,Y coordinates to evaluate at';
            app.XSpinnerLabel = uilabel(app.OptionsZdiff);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.OptionsZdiff);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.OptionsZdiff);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.OptionsZdiff);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.AdjustreferenceplaneButton = uibutton(app.OptionsZdiff, 'push');
            app.AdjustreferenceplaneButton.Tooltip = {''};
            app.AdjustreferenceplaneButton.Text = 'Adjust reference plane ';
            app.Info_Zoffset = uibutton(app.OptionsZdiff, 'push');
            app.Info_Zoffset.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_Zoffset.Tooltip = {'This option sets the Z-location of the reference plane if only one File is selected. It is recomendet to set this value to the location of the liquid surface'};
            app.Info_Zoffset.Text = '';
            app.ZValueSpinnerLabel = uilabel(app.OptionsZdiff);
            app.ZValueSpinnerLabel.Text = 'Z-Value';
            app.ZValueSpinner = uispinner(app.OptionsZdiff);
            app.ZValueSpinner.Tooltip = {'-18 is the defult value (at least simulations of the Fluid beavior use this Value)'};
            app.ZValueSpinner.Value = -18;
            app.TimeDifferenceEditField = uieditfield(app.ZDifferenceTab, 'text');
            app.TimeDifferenceEditField.Editable = 'off';
            app.Warning_NoFit_ZDiff = uibutton(app.ZDifferenceTab, 'push');
            app.Warning_NoFit_ZDiff.ButtonPushedFcn = createCallbackFcn(app, @Warning_NoFit_2ButtonPushed, true);
            app.Warning_NoFit_ZDiff.Icon = fullfile(pathToMLAPP, 'images', 'Warning.png');
            app.Warning_NoFit_ZDiff.Tooltip = {'This Tab only Displays Data if an Fitplane is Selected to be calculated !'};
            app.Warning_NoFit_ZDiff.Text = '';
            app.ContourOptionsDropDownLabel = uilabel(app.ZDifferenceTab);
            app.ContourOptionsDropDownLabel.Text = 'Options';
            app.Opt_Contoursurf = uibutton(app.ZDifferenceTab, 'push');
            app.Opt_Contoursurf.ButtonPushedFcn = createCallbackFcn(app, @Opt_ContoursurfPushed, true);
            app.Opt_Contoursurf.Icon = fullfile(pathToMLAPP, 'images', 'ArrowDown.png');
            app.Opt_Contoursurf.Text = '';
            app.QualityofFitTab = uitab(app.TabGroup2);
            app.QualityofFitTab.Title = 'Quality of Fit';
            app.ZDiffVisualizVariants_2 = uitabgroup(app.QualityofFitTab);
            app.ResidualsFitplaneTab = uitab(app.ZDiffVisualizVariants_2);
            app.ResidualsFitplaneTab.Title = 'Residuals Fitplane';
            app.UIAxesResiduals_Fit = uiaxes(app.ResidualsFitplaneTab);
            title(app.UIAxesResiduals_Fit, 'Distribution of Residuals')
            xlabel(app.UIAxesResiduals_Fit, 'd [mm]')
            ylabel(app.UIAxesResiduals_Fit, 'xi []')
            zlabel(app.UIAxesResiduals_Fit, 'Z')
            app.UIAxesResiduals_Fit.XLim = [-4 4];
            app.UIAxesResiduals_Fit.YLim = [0 1200];
            app.UIAxesResiduals_Fit.ZLim = [0 1];
            app.UIAxesResiduals_Fit.XLimitMethod = 'tight';
            app.UIAxesResiduals_Fit.YLimitMethod = 'tight';
            app.UIAxesResiduals_Fit.ZLimitMethod = 'tight';
            app.gofvariablesoverTimeTab = uitab(app.ZDiffVisualizVariants_2);
            app.gofvariablesoverTimeTab.Title = 'gof variables over Time';
            app.UIAxesRMSE = uiaxes(app.gofvariablesoverTimeTab);
            xlabel(app.UIAxesRMSE, 'time [s]')
            ylabel(app.UIAxesRMSE, 'R-Sqared')
            zlabel(app.UIAxesRMSE, 'Z')
            app.UIAxesSSE = uiaxes(app.gofvariablesoverTimeTab);
            xlabel(app.UIAxesSSE, 'time [s]')
            ylabel(app.UIAxesSSE, 'SSE')
            zlabel(app.UIAxesSSE, 'Z')
            app.UIAxesRSquared = uiaxes(app.gofvariablesoverTimeTab);
            xlabel(app.UIAxesRSquared, 'time [s]')
            ylabel(app.UIAxesRSquared, 'RMSE')
            zlabel(app.UIAxesRSquared, 'Z')
            app.RMSEEditFieldLabel = uilabel(app.QualityofFitTab);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.QualityofFitTab, 'numeric');
            app.RMSEEditField.Editable = 'off';
            app.RsquaredEditFieldLabel = uilabel(app.QualityofFitTab);
            app.RsquaredEditFieldLabel.Text = 'R-squared';
            app.RsquaredEditField = uieditfield(app.QualityofFitTab, 'numeric');
            app.RsquaredEditField.Editable = 'off';
            app.SSEEditFieldLabel = uilabel(app.QualityofFitTab);
            app.SSEEditFieldLabel.Text = 'SSE';
            app.SSEEditField = uieditfield(app.QualityofFitTab, 'numeric');
            app.SSEEditField.Editable = 'off';
            app.Panel_DistResd = uipanel(app.QualityofFitTab);
            app.Panel_DistResd.Title = '   ';
            app.Panel_DistResd.Visible = 'off';
            app.SelecthistogramstoplotButtonGroup = uibuttongroup(app.Panel_DistResd);
            app.SelecthistogramstoplotButtonGroup.Title = 'Select histograms to plot';
            app.FirstOriginButton = uiradiobutton(app.SelecthistogramstoplotButtonGroup);
            app.FirstOriginButton.Text = 'First Origin';
            app.FirstOriginButton.Value = true;
            app.SecondOriginButton = uiradiobutton(app.SelecthistogramstoplotButtonGroup);
            app.SecondOriginButton.Text = 'Second Origin';
            app.BothButton = uiradiobutton(app.SelecthistogramstoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.Opt_DistResd = uibutton(app.QualityofFitTab, 'push');
            app.Opt_DistResd.ButtonPushedFcn = createCallbackFcn(app, @Opt_DistResdButtonPushed, true);
            app.Opt_DistResd.Icon = fullfile(pathToMLAPP, 'images', 'ArrowDown.png');
            app.Opt_DistResd.Text = '';
            app.DistRedOptionsDropDownLabel = uilabel(app.QualityofFitTab);
            app.DistRedOptionsDropDownLabel.Text = 'Options';
            app.Warning_NoFit = uibutton(app.QualityofFitTab, 'push');
            app.Warning_NoFit.ButtonPushedFcn = createCallbackFcn(app, @Warning_NoFit_2ButtonPushed, true);
            app.Warning_NoFit.Icon = fullfile(pathToMLAPP, 'images', 'Warning.png');
            app.Warning_NoFit.Tooltip = {'This Tab only Displays Data if an Fitplane is Selected to be calculated !'};
            app.Warning_NoFit.Text = '';
            app.MaxMinTab = uitab(app.TabGroup2);
            app.MaxMinTab.Title = 'Max/ Min';
            app.UiAxesMaxMin = uiaxes(app.MaxMinTab);
            xlabel(app.UiAxesMaxMin, '\fontsize{12}[s]')
            ylabel(app.UiAxesMaxMin, '\fontsize{12}Max and Min Values [mm]')
            zlabel(app.UiAxesMaxMin, 'Z')
            app.UiAxesMaxMin.XLim = [0 10];
            app.UiAxesMaxMin.YLim = [-60 -10];
            app.UiAxesMaxMin.ZLim = [0 1];
            app.StartPlaybackButton = uibutton(app.EvaluatePlaybackTab, 'push');
            app.StartPlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlaybackButtonPushed, true);
            app.StartPlaybackButton.Text = 'Start Playback';
            app.Select2FileButton = uibutton(app.EvaluatePlaybackTab, 'push');
            app.Select2FileButton.ButtonPushedFcn = createCallbackFcn(app, @Select2FileButtonPushed, true);
            app.Select2FileButton.Tooltip = {'.bag and .csv files can be evalueated but only .bag files offer the possibility to evaluate the distribution of residuals of the fitted plane!'};
            app.Select2FileButton.Text = 'Select 2. File';
            app.Selected2csvFilesEditField = uieditfield(app.EvaluatePlaybackTab, 'text');
            app.Selected2csvFilesEditField.Value = 'no 2. file selected!';
            app.FrameSliderLabel = uilabel(app.EvaluatePlaybackTab);
            app.FrameSliderLabel.Enable = 'off';
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.EvaluatePlaybackTab);
            app.FrameSlider.Limits = [1 10];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Enable = 'off';
            app.FrameSlider.Value = 1;
            app.SelectaframeortimetobeEvaluatedButton = uibutton(app.EvaluatePlaybackTab, 'push');
            app.SelectaframeortimetobeEvaluatedButton.Tooltip = {''};
            app.SelectaframeortimetobeEvaluatedButton.Text = 'Select a frame or time to be Evaluated';
            app.FrameOtTimeDropDown = uidropdown(app.EvaluatePlaybackTab);
            app.FrameOtTimeDropDown.Items = {'Frame', 'Time', '', ''};
            app.FrameOtTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @FrameOtTimeDropDownValueChanged, true);
            app.FrameOtTimeDropDown.Enable = 'off';
            app.FrameOtTimeDropDown.Value = 'Frame';
            app.Info_selectfrEvaluate = uibutton(app.EvaluatePlaybackTab, 'push');
            app.Info_selectfrEvaluate.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_selectfrEvaluate.Tooltip = {''};
            app.Info_selectfrEvaluate.Text = '';
            app.OptEvaluationPanel = uipanel(app.EvaluatePlaybackTab);
            app.OptEvaluationPanel.Visible = 'off';
            app.FitSurfaceButton = uibutton(app.OptEvaluationPanel, 'push');
            app.FitSurfaceButton.Tooltip = {''};
            app.FitSurfaceButton.Text = '';
            app.distOfResidualsButton = uibutton(app.OptEvaluationPanel, 'push');
            app.distOfResidualsButton.Tooltip = {''};
            app.distOfResidualsButton.Text = '';
            app.calculateGradientButton = uibutton(app.OptEvaluationPanel, 'push');
            app.calculateGradientButton.Tooltip = {''};
            app.calculateGradientButton.Text = '';
            app.distOfAnglesButton = uibutton(app.OptEvaluationPanel, 'push');
            app.distOfAnglesButton.Tooltip = {''};
            app.distOfAnglesButton.Text = '';
            app.FitSurfaceDropDownLabel = uilabel(app.OptEvaluationPanel);
            app.FitSurfaceDropDownLabel.Text = 'Fit  Surface ';
            app.FitSurfaceDropDown = uidropdown(app.OptEvaluationPanel);
            app.FitSurfaceDropDown.Items = {'none', 'poly11', 'poly22', 'poly33', 'poly44', 'poly55', 'linearinterp'};
            app.FitSurfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @FitSurfaceDropDownValueChanged, true);
            app.FitSurfaceDropDown.Tooltip = {'Selecting none deactivates many evaluation options. low grade poly fits like poly11 might induce unexpected beavior/ results!'};
            app.FitSurfaceDropDown.Value = 'poly11';
            app.DistofResidualsSwitchLabel = uilabel(app.OptEvaluationPanel);
            app.DistofResidualsSwitchLabel.Text = 'Dist. of Residuals';
            app.DistofResidualsSwitch = uiswitch(app.OptEvaluationPanel, 'slider');
            app.DistofResidualsSwitch.ValueChangedFcn = createCallbackFcn(app, @DistofResidualsSwitchValueChanged, true);
            app.DistofAngleSwitchLabel = uilabel(app.OptEvaluationPanel);
            app.DistofAngleSwitchLabel.Text = 'Dist. of Angle';
            app.DistofAngleSwitch = uiswitch(app.OptEvaluationPanel, 'slider');
            app.DistofAngleSwitch.ValueChangedFcn = createCallbackFcn(app, @DistofAngleSwitchValueChanged, true);
            app.CalculateDerivativSwitchLabel = uilabel(app.OptEvaluationPanel);
            app.CalculateDerivativSwitchLabel.Text = 'Calculate Derivativ';
            app.CalculateDerivativSwitch = uiswitch(app.OptEvaluationPanel, 'slider');
            app.CalculateDerivativSwitch.ValueChangedFcn = createCallbackFcn(app, @CalculateDerivativSwitchValueChanged, true);
            app.calculateZDiffButton = uibutton(app.OptEvaluationPanel, 'push');
            app.calculateZDiffButton.Tooltip = {''};
            app.calculateZDiffButton.Text = '';
            app.CalculateZDiffLabel = uilabel(app.OptEvaluationPanel);
            app.CalculateZDiffLabel.Text = 'Calculate Z Diff.';
            app.CalculateZDiff = uiswitch(app.OptEvaluationPanel, 'slider');
            app.CalculateZDiff.ValueChangedFcn = createCallbackFcn(app, @CalculateZDiffValueChanged, true);
            app.calcAlpaBetaButton = uibutton(app.OptEvaluationPanel, 'push');
            app.calcAlpaBetaButton.Tooltip = {''};
            app.calcAlpaBetaButton.Text = '';
            app.CalAlphaBetaSwitchLabel = uilabel(app.OptEvaluationPanel);
            app.CalAlphaBetaSwitchLabel.Text = 'Cal. Alpha & Beta';
            app.CalAlphaBetaSwitch = uiswitch(app.OptEvaluationPanel, 'slider');
            app.CalAlphaBetaSwitch.ValueChangedFcn = createCallbackFcn(app, @CalAlphaBetaSwitchValueChanged, true);
            app.SelectfilesandstarttheevaluationButton = uibutton(app.EvaluatePlaybackTab, 'push');
            app.SelectfilesandstarttheevaluationButton.Tooltip = {''};
            app.SelectfilesandstarttheevaluationButton.Text = 'Select file(s) and start the evaluation';
            app.Opt_Evaluate = uibutton(app.EvaluatePlaybackTab, 'push');
            app.Opt_Evaluate.ButtonPushedFcn = createCallbackFcn(app, @Opt_EvaluateButtonPushed, true);
            app.Opt_Evaluate.Icon = fullfile(pathToMLAPP, 'images', 'ArrowDown.png');
            app.Opt_Evaluate.Text = '';
            app.OptEvaluateLable = uilabel(app.EvaluatePlaybackTab);
            app.OptEvaluateLable.Text = 'Options';
            app.Info_FileselectEvaluate = uibutton(app.EvaluatePlaybackTab, 'push');
            app.Info_FileselectEvaluate.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_FileselectEvaluate.Tooltip = {'Import Data to evaluate and use the Options Dropdown to select what to Calculate.'};
            app.Info_FileselectEvaluate.Text = '';
            app.ExportEvaluationDataTab = uitab(app.TabGroup);
            app.ExportEvaluationDataTab.Title = 'Export Evaluation Data';
            app.SavetocsvButton = uibutton(app.ExportEvaluationDataTab, 'state');
            app.SavetocsvButton.ValueChangedFcn = createCallbackFcn(app, @SavetocsvButtonValueChanged, true);
            app.SavetocsvButton.Text = 'Save to .csv';
            app.SavetoxlsButton = uibutton(app.ExportEvaluationDataTab, 'state');
            app.SavetoxlsButton.ValueChangedFcn = createCallbackFcn(app, @SavetoxlsButtonValueChanged, true);
            app.SavetoxlsButton.Enable = 'off';
            app.SavetoxlsButton.Text = 'Save to .xls';
            app.AlphaBetaCheckBox = uicheckbox(app.ExportEvaluationDataTab);
            app.AlphaBetaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlphaBetaCheckBoxValueChanged, true);
            app.AlphaBetaCheckBox.Text = ' Alpha & Beta ';
            app.AlphaBetaCheckBox.Value = true;
            app.ScalarEvaluationValuestoExportButton = uibutton(app.ExportEvaluationDataTab, 'push');
            app.ScalarEvaluationValuestoExportButton.Tooltip = {''};
            app.ScalarEvaluationValuestoExportButton.Text = 'Scalar Evaluation Values to Export ';
            app.ParametersforcsvExportButton = uibutton(app.ExportEvaluationDataTab, 'push');
            app.ParametersforcsvExportButton.Tooltip = {'Select parameters for the export to .csv'};
            app.ParametersforcsvExportButton.Text = 'Parameters for .csv Export ';
            app.SelectdataformatandExportButton = uibutton(app.ExportEvaluationDataTab, 'push');
            app.SelectdataformatandExportButton.Tooltip = {'select filetype to export data'};
            app.SelectdataformatandExportButton.Text = 'Select data format and Export';
            app.UITableDataToSave = uitable(app.ExportEvaluationDataTab);
            app.UITableDataToSave.ColumnName = {''; ''; ''};
            app.UITableDataToSave.RowName = {};
            app.PreviewofexportedDataButton = uibutton(app.ExportEvaluationDataTab, 'push');
            app.PreviewofexportedDataButton.Tooltip = {''};
            app.PreviewofexportedDataButton.Text = 'Preview of exported Data ';
            app.SeperatorDropDownLabel = uilabel(app.ExportEvaluationDataTab);
            app.SeperatorDropDownLabel.Text = 'Seperator';
            app.SeperatorDropDown = uidropdown(app.ExportEvaluationDataTab);
            app.SeperatorDropDown.Items = {',', ';', '_', '/', 'space'};
            app.SeperatorDropDown.ValueChangedFcn = createCallbackFcn(app, @SeperatorDropDownValueChanged, true);
            app.SeperatorDropDown.Value = ';';
            app.IncludeCollumtitelsSwitchLabel = uilabel(app.ExportEvaluationDataTab);
            app.IncludeCollumtitelsSwitchLabel.Text = 'Include Collumtitels';
            app.IncludeCollumtitelsSwitch = uiswitch(app.ExportEvaluationDataTab, 'slider');
            app.IncludeCollumtitelsSwitch.Value = 'On';
            app.gofofFitplaneCheckBox = uicheckbox(app.ExportEvaluationDataTab);
            app.gofofFitplaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @gofofFitplaneCheckBoxValueChanged, true);
            app.gofofFitplaneCheckBox.Enable = 'off';
            app.gofofFitplaneCheckBox.Tooltip = {'Inclueds RMSE, SSE R-Squared'};
            app.gofofFitplaneCheckBox.Text = 'gof of Fitplane';
            app.OriginofDataCheckBox = uicheckbox(app.ExportEvaluationDataTab);
            app.OriginofDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @OriginofDataCheckBoxValueChanged, true);
            app.OriginofDataCheckBox.Text = 'Origin of Data';
            app.OriginofDataCheckBox.Value = true;
            app.TimestampsCheckBox = uicheckbox(app.ExportEvaluationDataTab);
            app.TimestampsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimestampsCheckBoxValueChanged, true);
            app.TimestampsCheckBox.Text = 'Timestamps';
            app.TimestampsCheckBox.Value = true;
            app.Info_ValuesToExport = uibutton(app.ExportEvaluationDataTab, 'push');
            app.Info_ValuesToExport.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_ValuesToExport.Tooltip = {'Select the data that shoud be exported. '};
            app.Info_ValuesToExport.Text = '';
            app.Info_ParamToExport = uibutton(app.ExportEvaluationDataTab, 'push');
            app.Info_ParamToExport.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_ParamToExport.Tooltip = {'Options for exporting data. ";" is the recomended Seperator!'};
            app.Info_ParamToExport.Text = '';
            app.Info_PrevExport = uibutton(app.ExportEvaluationDataTab, 'push');
            app.Info_PrevExport.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_PrevExport.Tooltip = {'Sorting data effects the order of the exportet data. This Preview can be used to copy data with ctrl.+c.'};
            app.Info_PrevExport.Text = '';
            app.ExportdependingontheOriginButtonGroup = uibuttongroup(app.ExportEvaluationDataTab);
            app.ExportdependingontheOriginButtonGroup.Title = 'Export depending on the Origin';
            app.ExportbothButton = uiradiobutton(app.ExportdependingontheOriginButtonGroup);
            app.ExportbothButton.Text = 'Export both';
            app.ExportbothButton.Value = true;
            app.OnlyfromFile1Button = uiradiobutton(app.ExportdependingontheOriginButtonGroup);
            app.OnlyfromFile1Button.Text = 'Only from File 1';
            app.OnlyfromFile2Button = uiradiobutton(app.ExportdependingontheOriginButtonGroup);
            app.OnlyfromFile2Button.Text = 'Only from File 2';
            app.IndexCheckBox = uicheckbox(app.ExportEvaluationDataTab);
            app.IndexCheckBox.ValueChangedFcn = createCallbackFcn(app, @IndexCheckBoxValueChanged, true);
            app.IndexCheckBox.Visible = 'off';
            app.IndexCheckBox.Text = 'Index';
            app.IndexCheckBox.Value = true;
            app.ZDiffxyCheckBox = uicheckbox(app.ExportEvaluationDataTab);
            app.ZDiffxyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZDiffxyCheckBoxValueChanged, true);
            app.ZDiffxyCheckBox.Enable = 'off';
            app.ZDiffxyCheckBox.Text = 'ZDiff @ x,y';
            app.PointcloudEvaluationValuestoExportButton = uibutton(app.ExportEvaluationDataTab, 'push');
            app.PointcloudEvaluationValuestoExportButton.Tooltip = {'select filetype to export data'};
            app.PointcloudEvaluationValuestoExportButton.Text = 'Pointcloud Evaluation Values to Export';
            app.ExportZDiffButton = uibutton(app.ExportEvaluationDataTab, 'push');
            app.ExportZDiffButton.ButtonPushedFcn = createCallbackFcn(app, @ExportZDiffButtonPushed, true);
            app.ExportZDiffButton.Enable = 'off';
            app.ExportZDiffButton.Text = 'Export Z-Diff';
            app.ExportAnglesButton = uibutton(app.ExportEvaluationDataTab, 'push');
            app.ExportAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAnglesButtonPushed, true);
            app.ExportAnglesButton.Enable = 'off';
            app.ExportAnglesButton.Text = 'Export Angles';
            app.ExportFitplaneButton = uibutton(app.ExportEvaluationDataTab, 'push');
            app.ExportFitplaneButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFitplaneButtonPushed, true);
            app.ExportFitplaneButton.Enable = 'off';
            app.ExportFitplaneButton.Text = 'Export Fitplane';
            app.Info_PointcloudsToExp = uibutton(app.ExportEvaluationDataTab, 'push');
            app.Info_PointcloudsToExp.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_PointcloudsToExp.Tooltip = {'Export Arrays with Pointclouds  '};
            app.Info_PointcloudsToExp.Text = '';
            app.Info_dataformatExp = uibutton(app.ExportEvaluationDataTab, 'push');
            app.Info_dataformatExp.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_dataformatExp.Tooltip = {'Select data format. Currently only csv is supported!'};
            app.Info_dataformatExp.Text = '';
            app.MinMaxzValsCheckBox = uicheckbox(app.ExportEvaluationDataTab);
            app.MinMaxzValsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinMaxzValsCheckBoxValueChanged, true);
            app.MinMaxzValsCheckBox.Text = 'Min & Max z-Vals';
            app.MinMaxzValsCheckBox.Value = true;
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'images', 'Repeat.png');
            app.StopButton.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxesCoordSystem = uiaxes(app.GridLayout2);
            app.UIAxesCoordSystem.XLim = [-0.2 1];
            app.UIAxesCoordSystem.YLim = [-0.2 1];
            app.UIAxesCoordSystem.ZLim = [-0.2 1];
            app.UIAxesCoordSystem.XLimitMethod = 'padded';
            app.UIAxesCoordSystem.YLimitMethod = 'padded';
            app.UIAxesCoordSystem.ZLimitMethod = 'padded';
            app.UIAxesPtcld = uiaxes(app.GridLayout2);
            xlabel(app.UIAxesPtcld, '\fontsize{14} X[mm]')
            ylabel(app.UIAxesPtcld, '\fontsize{14}Y[mm]')
            zlabel(app.UIAxesPtcld, '\fontsize{14}Z[mm]')
            app.UIAxesPtcld.XLim = [-30 30];
            app.UIAxesPtcld.YLim = [-30 30];
            app.UIAxesPtcld.ZLim = [-240 -140];
            app.UIAxesPtcld.XLimitMethod = 'tight';
            app.UIAxesPtcld.YLimitMethod = 'tight';
            app.UIAxesPtcld.ZLimitMethod = 'tight';
            app.GraphicsOptionsareaffectallplotsPanel = uipanel(app.GridLayout2);
            app.GraphicsOptionsareaffectallplotsPanel.Title = 'Graphics Options are affect all plots ';
            app.GraphicsOptionsareaffectallplotsPanel.Visible = 'off';
            app.MaxMinLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.MaxMinLabel.Text = 'Max, Min:';
            app.MaxMinSwitch = uiswitch(app.GraphicsOptionsareaffectallplotsPanel, 'slider');
            app.MaxMinSwitch.ValueChangedFcn = createCallbackFcn(app, @MaxMinSwitchValueChanged, true);
            app.MaxMinSwitch.Value = 'On';
            app.delta_xyLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.delta_xyLabel.Text = 'delta_xy:';
            app.delta_xySwitch = uiswitch(app.GraphicsOptionsareaffectallplotsPanel, 'slider');
            app.delta_xySwitch.ValueChangedFcn = createCallbackFcn(app, @delta_xySwitchValueChanged, true);
            app.delta_xySwitch.Value = 'On';
            app.FitplaneLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.FitplaneLabel.Text = 'Fitted Ladle:';
            app.FittedLadleSwitch = uiswitch(app.GraphicsOptionsareaffectallplotsPanel, 'slider');
            app.FittedLadleSwitch.ValueChangedFcn = createCallbackFcn(app, @FittedLadleSwitchValueChanged, true);
            app.CoordSystemLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.CoordSystemLabel.Text = 'Coord-Sys:';
            app.CoordSysSwitch = uiswitch(app.GraphicsOptionsareaffectallplotsPanel, 'slider');
            app.CoordSysSwitch.ValueChangedFcn = createCallbackFcn(app, @CoordSysSwitchValueChanged, true);
            app.CoordSysSwitch.Value = 'On';
            app.SwitchObjectsonoroffButton = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.SwitchObjectsonoroffButton.Tooltip = {''};
            app.SwitchObjectsonoroffButton.Text = 'Switch Objects on or off';
            app.ChangeColorTransparencyButton = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.ChangeColorTransparencyButton.Tooltip = {''};
            app.ChangeColorTransparencyButton.Text = 'Change Color & Transparency  ';
            app.TransparencySliderLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.TransparencySliderLabel.Text = 'Transparency';
            app.TransparencySlider = uislider(app.GraphicsOptionsareaffectallplotsPanel);
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.Value = 50;
            app.FPSCounterSwitchLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.FPSCounterSwitchLabel.Text = 'FPS Counter:';
            app.FPSCounterSwitch = uiswitch(app.GraphicsOptionsareaffectallplotsPanel, 'slider');
            app.FPSCounterSwitch.ValueChangedFcn = createCallbackFcn(app, @FPSCounterSwitchValueChanged, true);
            app.FPSCounterSwitch.Value = 'On';
            app.SkippedFrSwitchLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.SkippedFrSwitchLabel.Text = 'Skipped Fr.:';
            app.SkippedFrSwitch = uiswitch(app.GraphicsOptionsareaffectallplotsPanel, 'slider');
            app.SkippedFrSwitch.ValueChangedFcn = createCallbackFcn(app, @SkippedFrSwitchValueChanged, true);
            app.SkippedFrSwitch.Value = 'On';
            app.ColorLadleDropDownLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.ColorLadleDropDownLabel.Text = 'Color Ladle';
            app.ColorLadleDropDown = uidropdown(app.GraphicsOptionsareaffectallplotsPanel);
            app.ColorLadleDropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Custom'};
            app.ColorLadleDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorLadleDropDownValueChanged, true);
            app.ColorLadleDropDown.Value = 'Orange';
            app.ColorMaxMinDropDownLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.ColorMaxMinDropDownLabel.Text = 'Color Max, Min';
            app.ColorMaxMinDropDown = uidropdown(app.GraphicsOptionsareaffectallplotsPanel);
            app.ColorMaxMinDropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Custom'};
            app.ColorMaxMinDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMaxMinDropDownValueChanged, true);
            app.ColorMaxMinDropDown.Value = 'Green';
            app.Pointcloud1DropDownLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.Pointcloud1DropDownLabel.Text = 'Pointcloud 1';
            app.Pointcloud1DropDown = uidropdown(app.GraphicsOptionsareaffectallplotsPanel);
            app.Pointcloud1DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Custom'};
            app.Pointcloud1DropDown.ValueChangedFcn = createCallbackFcn(app, @Pointcloud1DropDownValueChanged, true);
            app.Pointcloud1DropDown.Value = 'Blue';
            app.Pointcloud2DropDownLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.Pointcloud2DropDownLabel.Text = 'Pointcloud 2';
            app.Pointcloud2DropDown = uidropdown(app.GraphicsOptionsareaffectallplotsPanel);
            app.Pointcloud2DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'custom'};
            app.Pointcloud2DropDown.ValueChangedFcn = createCallbackFcn(app, @Pointcloud2DropDownValueChanged, true);
            app.Pointcloud2DropDown.Value = 'Red';
            app.delta_xyDropDownLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.delta_xyDropDownLabel.Text = 'delta_xy';
            app.delta_xyDropDown = uidropdown(app.GraphicsOptionsareaffectallplotsPanel);
            app.delta_xyDropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Custom'};
            app.delta_xyDropDown.ValueChangedFcn = createCallbackFcn(app, @delta_xyDropDownValueChanged, true);
            app.delta_xyDropDown.Value = 'Red';
            app.MeshsizeSpinnerLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.MeshsizeSpinnerLabel.Text = 'Meshsize';
            app.MeshsizeSpinner = uispinner(app.GraphicsOptionsareaffectallplotsPanel);
            app.MeshsizeSpinner.Step = 0.5;
            app.MeshsizeSpinner.Limits = [0 100];
            app.MeshsizeSpinner.ValueDisplayFormat = '%.2f [mm]';
            app.MeshsizeSpinner.Value = 2.5;
            app.ChangeReselutionsUpdateFrequancysButton = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.ChangeReselutionsUpdateFrequancysButton.Tooltip = {''};
            app.ChangeReselutionsUpdateFrequancysButton.Text = 'Change Reselutions & Update Frequancys ';
            app.FitplaneSwitchLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.FitplaneSwitchLabel.Text = 'Fitplane ';
            app.FitplaneSwitch = uiswitch(app.GraphicsOptionsareaffectallplotsPanel, 'slider');
            app.FitplaneSwitch.Items = {'Normal', 'Simple'};
            app.FitplaneSwitch.Value = 'Normal';
            app.Info_ThingsToPlot = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.Info_ThingsToPlot.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_ThingsToPlot.Text = '';
            app.Info_ColorTransp = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.Info_ColorTransp.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_ColorTransp.Tooltip = {'Change the Color of diffrent Objects based on Predefined Coloroptions.'};
            app.Info_ColorTransp.Text = '';
            app.Info_ResUpdateFreq = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.Info_ResUpdateFreq.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_ResUpdateFreq.Tooltip = {'The meshsize setting effects the Calculation/ Visualisation of the Fitplane, Dist of Angles, Angle Deriv. and the Shape of the Mask. Small meshsizes will slow down the Programm!'; 'The update frequancy only affects how often a Plot is updated. Calcualtions arent effected. high values might speed up the Programm.'};
            app.Info_ResUpdateFreq.Text = '';
            app.CalculateTopviewFluid = uicheckbox(app.GraphicsOptionsareaffectallplotsPanel);
            app.CalculateTopviewFluid.ValueChangedFcn = createCallbackFcn(app, @CalculateTopviewFluidValueChanged, true);
            app.CalculateTopviewFluid.Text = 'Mask Areas that are not coverd by the Fluid (x,y-plane)';
            app.SetOptionsforAreatoDisplayOnlyonTopviewButton = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.SetOptionsforAreatoDisplayOnlyonTopviewButton.Tooltip = {''};
            app.SetOptionsforAreatoDisplayOnlyonTopviewButton.Text = 'Set Options for Area to Display (Only on Topview)';
            app.Info_AreaToDisplay = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.Info_AreaToDisplay.Icon = fullfile(pathToMLAPP, 'images', 'Info-mod.png');
            app.Info_AreaToDisplay.Tooltip = {'Only Schow Parts of the Topview that are a part of at least one Pointcloud. This Option has a large effect on compute time!'};
            app.Info_AreaToDisplay.Text = '';
            app.Opt_AdvgraphicsOpt = uibutton(app.GraphicsOptionsareaffectallplotsPanel, 'push');
            app.Opt_AdvgraphicsOpt.ButtonPushedFcn = createCallbackFcn(app, @Opt_AdvgraphicsOptButtonPushed, true);
            app.Opt_AdvgraphicsOpt.Icon = fullfile(pathToMLAPP, 'images', 'ArrowDown.png');
            app.Opt_AdvgraphicsOpt.Text = '';
            app.AdvancedColorTranspOptionsLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.AdvancedColorTranspOptionsLabel.Text = 'Advanced Color & Transp. Options   ';
            app.UpdateFreqSpinnerLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.UpdateFreqSpinnerLabel.Text = 'Update Freq.';
            app.UpdateFreqSpinner = uispinner(app.GraphicsOptionsareaffectallplotsPanel);
            app.UpdateFreqSpinner.Step = 0.5;
            app.UpdateFreqSpinner.Limits = [0 100];
            app.UpdateFreqSpinner.ValueDisplayFormat = '%.0f ';
            app.UpdateFreqSpinner.Value = 1;
            app.Panel_3 = uipanel(app.GraphicsOptionsareaffectallplotsPanel);
            app.Panel_3.Visible = 'off';
            app.ColorSelectorFace = wt.ColorSelector(app.Panel_3);
            app.ColorSelectorFace.Value = [0.059 1 1];
            app.ColorSelectorFace.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorFaceValueChanged, true);
            app.ColorSelectorEdge = wt.ColorSelector(app.Panel_3);
            app.ColorSelectorEdge.Value = [0 0.45 0.74];
            app.ColorSelectorEdge.Tooltip = "ONLY used by Pointcloud 1&2!";
            app.FacecolorLabel = uilabel(app.Panel_3);
            app.FacecolorLabel.Text = 'Facecolor';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '';
            app.EdgecolorLabel = uilabel(app.Panel_3);
            app.EdgecolorLabel.Tooltip = {'ONLY used by Pointcloud 1&2!'};
            app.EdgecolorLabel.Text = 'Edgecolor';
            app.TransparencyMultiplyerButton = uibutton(app.Panel_3, 'push');
            app.TransparencyMultiplyerButton.Tooltip = {''};
            app.TransparencyMultiplyerButton.Text = 'Transparency Multiplyer';
            app.LadleSpinnerLabel = uilabel(app.Panel_3);
            app.LadleSpinnerLabel.Text = 'Ladle';
            app.LadleSpinner = uispinner(app.Panel_3);
            app.LadleSpinner.Step = 5;
            app.LadleSpinner.Limits = [0 100];
            app.LadleSpinner.ValueDisplayFormat = '%.f [%%]';
            app.LadleSpinner.Value = 40;
            app.MaxMinSpinnerLabel = uilabel(app.Panel_3);
            app.MaxMinSpinnerLabel.Text = 'Max, Min';
            app.MaxMinSpinner = uispinner(app.Panel_3);
            app.MaxMinSpinner.Step = 5;
            app.MaxMinSpinner.Limits = [0 100];
            app.MaxMinSpinner.ValueDisplayFormat = '%.f [%%]';
            app.MaxMinSpinner.Value = 35;
            app.delta_xySpinnerLabel = uilabel(app.Panel_3);
            app.delta_xySpinnerLabel.Text = 'delta_xy';
            app.delta_xySpinner = uispinner(app.Panel_3);
            app.delta_xySpinner.Step = 5;
            app.delta_xySpinner.Limits = [0 100];
            app.delta_xySpinner.ValueDisplayFormat = '%.f [%%]';
            app.delta_xySpinner.Value = 65;
            app.FitobjectSpinnerLabel = uilabel(app.Panel_3);
            app.FitobjectSpinnerLabel.Text = 'Fitobject';
            app.FitobjectSpinner = uispinner(app.Panel_3);
            app.FitobjectSpinner.Step = 5;
            app.FitobjectSpinner.Limits = [0 100];
            app.FitobjectSpinner.ValueDisplayFormat = '%.f [%%]';
            app.FitobjectSpinner.Value = 100;
            app.EditCustomColorProfileButton = uibutton(app.Panel_3, 'push');
            app.EditCustomColorProfileButton.Tooltip = {''};
            app.EditCustomColorProfileButton.Text = 'Edit Custom Color Profile';
            app.ColorSelectorStartbtn = wt.ColorSelector(app.Panel_3);
            app.ColorSelectorStartbtn.Value = [0.42 0.81 0.32];
            app.ColorSelectorRemoveBtn = wt.ColorSelector(app.Panel_3);
            app.ColorSelectorRemoveBtn.Value = [0.99 0.071 0.071];
            app.ColorSelectorLoadBtn = wt.ColorSelector(app.Panel_3);
            app.ColorSelectorLoadBtn.Value = [0.11 0.61 0.95];
            app.ColorSelectorContBtn = wt.ColorSelector(app.Panel_3);
            app.ColorSelectorContBtn.Value = [0 0.16 1];
            app.LoadBtnLabel = uilabel(app.Panel_3);
            app.LoadBtnLabel.Tooltip = {'ONLY used by Pointcloud 1&2!'};
            app.LoadBtnLabel.Text = 'Load Btn.';
            app.StartBtnLabel = uilabel(app.Panel_3);
            app.StartBtnLabel.Tooltip = {'ONLY used by Pointcloud 1&2!'};
            app.StartBtnLabel.Text = 'Start Btn.';
            app.RemoveBtnLabel = uilabel(app.Panel_3);
            app.RemoveBtnLabel.Tooltip = {'ONLY used by Pointcloud 1&2!'};
            app.RemoveBtnLabel.Text = 'Remove Btn.';
            app.ContinueBtnLabel = uilabel(app.Panel_3);
            app.ContinueBtnLabel.Tooltip = {'ONLY used by Pointcloud 1&2!'};
            app.ContinueBtnLabel.Text = 'Continue Btn.';
            app.ApplyChangesButton = uibutton(app.Panel_3, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.LocationxyLabel = uilabel(app.GraphicsOptionsareaffectallplotsPanel);
            app.LocationxyLabel.Text = 'Location xy';
            app.LocationSwitch = uiswitch(app.GraphicsOptionsareaffectallplotsPanel, 'slider');
            app.LocationSwitch.ValueChangedFcn = createCallbackFcn(app, @LocationSwitchValueChanged, true);
            app.LocationSwitch.Value = 'On';
            app.Image4 = uiimage(app.GraphicsOptionsareaffectallplotsPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'LineUi.png');
            app.ProcessedfpsLabel = uilabel(app.GridLayout2);
            app.ProcessedfpsLabel.Text = 'Processed fps:';
            app.ProcessedfpsGauge = uigauge(app.GridLayout2, 'linear');
            app.ProcessedfpsGauge.Limits = [30 67];
            app.ProcessedfpsGauge.Tooltip = {''};
            app.ProcessedfpsGauge.Value = 0.1;
            app.skippedFramesLabel = uilabel(app.GridLayout2);
            app.skippedFramesLabel.Text = 'skipped :';
            app.skippedEditField = uieditfield(app.GridLayout2, 'numeric');
            app.skippedEditField.Editable = 'off';
            app.skippedEditField.Value = 1;
            app.GraphicsOptionsLabel = uilabel(app.GridLayout2);
            app.GraphicsOptionsLabel.Text = 'Graphics Options   ';
            app.Opt_Contoursurf_Graphics = uibutton(app.GridLayout2, 'push');
            app.Opt_Contoursurf_Graphics.ButtonPushedFcn = createCallbackFcn(app, @Opt_Contoursurf_GraphicsButtonPushed, true);
            app.Opt_Contoursurf_Graphics.Icon = fullfile(pathToMLAPP, 'images', 'ArrowDown.png');
            app.Opt_Contoursurf_Graphics.Text = '';
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'LogoLPS.png');
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'HiRocastlogo.PNG');
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'Icon_App_V2.png');
            app.PouringLadleInspectV13102022Label = uilabel(app.GridLayout2);
            app.PouringLadleInspectV13102022Label.Text = 'PouringLadle-Inspect V1.3 10.2022 ';
            app.RefreshPlotsButton = uibutton(app.GridLayout2, 'push');
            app.RefreshPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPlotsButtonPushed, true);
            app.RefreshPlotsButton.Text = 'Refresh Plots';
            app.MaxLabel_2 = uilabel(app.GridLayout2);
            app.MaxLabel_2.Text = 'Max:';
            app.MaxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxEditField.Editable = 'off';
            app.MinLabel_2 = uilabel(app.GridLayout2);
            app.MinLabel_2.Text = 'Min:';
            app.MinEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinEditField.Editable = 'off';
            % Show the figure after all components are created
            app.PouringLadleInspectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PouringLadle_Inspect_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PouringLadleInspectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PouringLadleInspectUIFigure)
        end
    end
end