classdef ProgressBar_LPS < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ProgressLabel  matlab.ui.control.Label
        Percentage     matlab.ui.control.Label
        Backround      matlab.ui.control.Label
    end

    properties (Access = public)
        Progress (1,1) double {mustBeLessThanOrEqual(Progress, 100), mustBeGreaterThanOrEqual(Progress, 0)} = 0; % Set Percentage as value from 1-100%
    end
    
    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            compSize = comp.Position;
            comp.Backround.Position = [3,3,compSize(1,3)-4,compSize(1,4)-4];
            prog = (comp.Progress+0.001)/100;
            comp.Percentage.Position = [3,3,round(prog*(compSize(1,3)-4)),compSize(1,4)-4];
            comp.ProgressLabel.Position = comp.Backround.Position;
            comp.ProgressLabel.Text = " Progress " + num2str(round(comp.Progress)) + "%";
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 450 40];
            comp.BackgroundColor = [0.3608 0.3608 0.3608];
            comp.Backround = uilabel(comp);
            comp.Backround.Text = '';
            comp.Percentage = uilabel(comp);
            comp.Percentage.Text = '';
            comp.ProgressLabel = uilabel(comp);
            comp.ProgressLabel.Text = 'Progress 0%';
        end
    end
end